classdef uifig_gseapreranked < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        scGEAppGSEAPrerankedUIFigure    matlab.ui.Figure
        SelectRankVaribleDropDownLabel  matlab.ui.control.Label
        SelectRankVaribleDropDown       matlab.ui.control.DropDown
        RunGSEAButton                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PrerankedGenesTab               matlab.ui.container.Tab
        UITableGList                    matlab.ui.control.Table
        SaveTableAsButton               matlab.ui.control.Button
        AssignTableIntoWorkspaceButton_3  matlab.ui.control.Button
        GeneSetsSettingsTab             matlab.ui.container.Tab
        ParameterSettingsPanel          matlab.ui.container.Panel
        NumberofPermutationsSliderLabel  matlab.ui.control.Label
        NumberofPermutationsSlider      matlab.ui.control.Slider
        SortingDirectionofRankVaribleButtonGroup  matlab.ui.container.ButtonGroup
        ascendButton                    matlab.ui.control.RadioButton
        descendButton                   matlab.ui.control.RadioButton
        GeneSetsPanel                   matlab.ui.container.Panel
        MSigDBv62updatedJuly2018Label   matlab.ui.control.Label
        MSigDBv62updatedJuly2018ListBox  matlab.ui.control.ListBox
        AboutMSigDBButton               matlab.ui.control.Button
        PositivelyCorrSetsTab           matlab.ui.container.Tab
        UITablePRes                     matlab.ui.control.Table
        SaveTableAsButton_2             matlab.ui.control.Button
        AssignTableIntoWorkspaceButton  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ClickongenesettoplotresultCheckBox  matlab.ui.control.CheckBox
        GuidetointerpretresultsButton   matlab.ui.control.Button
        NegativelyCorrSetsTab           matlab.ui.container.Tab
        UITableNRes                     matlab.ui.control.Table
        SaveTableAsButton_3             matlab.ui.control.Button
        AssignTableIntoWorkspaceButton_2  matlab.ui.control.Button
        ClickongenesettoplotresultCheckBox_2  matlab.ui.control.CheckBox
        GuidetointerpretresultsButton_2  matlab.ui.control.Button
        PlotResultsCheckBox             matlab.ui.control.CheckBox
        ReadyLampLabel                  matlab.ui.control.Label
        ReadyLamp                       matlab.ui.control.Lamp
        LoadPrerankedGenesButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        Tgenelist
        Tpos % Positive Result Table
        Tneg % Negative Result Table
        Fig  % uifigure for plot display
    end
    
    methods (Access = public)
        
        function load_datatable(app,T)
            app.Tgenelist=T;
            app.UITableGList.Data=app.Tgenelist;
            app.UITableGList.ColumnName=app.Tgenelist.Properties.VariableNames;
            if length(app.Tgenelist.Properties.VariableNames)>1
                app.SelectRankVaribleDropDown.Items=app.Tgenelist.Properties.VariableNames(2:end);
            else
                app.SelectRankVaribleDropDown.Items=app.Tgenelist.Properties.VariableNames;
            end
            app.RunGSEAButton.Enable=true;
        end
        
        function load_genelist(app,genes)
            grank=(1:length(genes))';
            app.Tgenelist=table(genes,grank);            
            app.UITableGList.Data=app.Tgenelist;
            app.UITableGList.ColumnName=app.Tgenelist.Properties.VariableNames;
            if length(app.Tgenelist.Properties.VariableNames)>1
                app.SelectRankVaribleDropDown.Items=app.Tgenelist.Properties.VariableNames(2:end);
            else
                app.SelectRankVaribleDropDown.Items=app.Tgenelist.Properties.VariableNames;
            end
            app.RunGSEAButton.Enable=true;
        end
    end
    
    methods (Access = private)
        
        
        function show_image(app,id)
            imfilename=sprintf('GSEA_plots/GS_%d.png',id);
            if ~exist(imfilename,'file'), return; end
            try 
                im=imread(imfilename);
                if ~isempty(app.Fig), delete(app.Fig); end
%                 if isempty(app.Fig) || ~isvalid(app.Fig)
%                     app.Fig=uifigure;
%                 end
                app.Fig=uifigure;
                a=uiaxes(app.Fig,'Position',[0 0 app.Fig.Position(3:4)]);
                imshow(im,'parent',a);
            catch
                errordlg(sprintf('Error when showing %s',imfilename));          
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cdgea;
            addpath('+run/thirdparty/GSEA');
            app.UITablePRes.Tooltip={};
            app.UITableNRes.Tooltip={};
            dblist=strtrim(string(ls('+run/thirdparty/GSEA/msigdb*.mat')));            
            app.MSigDBv62updatedJuly2018ListBox.Items=dblist;
            app.MSigDBv62updatedJuly2018ListBox.ItemsData=dblist;
            app.MSigDBv62updatedJuly2018ListBox.Value=app.MSigDBv62updatedJuly2018ListBox.Items(5);
            % movegui(app.scGEAppGSEAPrerankedUIFigure,'center');
            % app.scGEAppGSEAPrerankedUIFigure.Position
        end

        % Button pushed function: RunGSEAButton
        function RunGSEAButtonPushed(app, event)
            if strcmp(app.ReadyLampLabel.Text,'Done.')
                txt='Rerun the analysis?';
            else
                txt='This analysis will take some time. Would you like to continue?';
            end
            answer = uiconfirm(app.scGEAppGSEAPrerankedUIFigure,txt,'Long running time',...
                            'Icon','warning');
            switch answer
                case 'Cancel'
                    return;
                case 'OK'                
                    app.ReadyLamp.Color='r';
                    app.ReadyLamp.Enable=true;
                    app.ReadyLampLabel.Visible=true;
                    app.ReadyLampLabel.Text='Busy';
                    pause(1)
    
                    % rmdir GSEA_plots s
                    tic;
                    t=app.UITableGList.Data;
                    selvar=app.SelectRankVaribleDropDown.Value;
                    [~,idx]=ismember(selvar,...
                        app.SelectRankVaribleDropDown.Items);
                    showplot=false;
                    if app.PlotResultsCheckBox.Value, showplot=true; end
                    pause(.5);
                    glist=table2array(t(:,1));
                    if size(t,2)>1
                        grank=table2array(t(:,1+idx));
                    else
                         grank=1:length(glist);
                    end
                    d=uiprogressdlg(app.scGEAppGSEAPrerankedUIFigure,...
                        'Title','Running GSEA (Preranked)',...
                        'Indeterminate','on');
                     dbfile=app.MSigDBv62updatedJuly2018ListBox.Value;
                     n_perm=round(app.NumberofPermutationsSlider.Value);
                     if app.ascendButton.Value
                        sort_type='ascend';
                     elseif app.descendButton.Value
                         sort_type='descend';
                     end
                         
                     fprintf('Gene Set: %s\nNum of permutations: %d\nSorting direction: %s\nSelected variable: %s\n',...
                         dbfile,n_perm,sort_type,selvar);
                        [Tp,Tn]=run.GSEA(glist,grank,showplot,n_perm,dbfile,sort_type);
                     close(d);
                     app.UITablePRes.Data=Tp;
                     app.UITablePRes.ColumnName=Tp.Properties.VariableNames;
                     app.UITableNRes.Data=Tn;
                     app.UITableNRes.ColumnName=Tn.Properties.VariableNames;                 
                     % waitbar(1,fw,'Finishing'); pause(1); close(fw);
                     app.Tpos=Tp;
                     app.Tneg=Tn;
                        app.ReadyLamp.Color='g';
                        app.ReadyLampLabel.Text='Done';
                        app.TabGroup.SelectedTab=app.PositivelyCorrSetsTab;
                    toc;
            end
        end

        % Cell selection callback: UITablePRes
        function UITablePResCellSelection(app, event)
            indices = event.Indices;
            if app.ClickongenesettoplotresultCheckBox.Value
                id=app.Tpos{indices(1),2};
                app.show_image(id);
            end
        end

        % Cell selection callback: UITableNRes
        function UITableNResCellSelection(app, event)
            indices = event.Indices;
            if app.ClickongenesettoplotresultCheckBox_2.Value            
                id=app.Tneg{indices(1),2};
                app.show_image(id);
            end
        end

        % Button pushed function: SaveTableAsButton_2
        function SaveTableAsButton_2Pushed(app, event)
            if ~isempty(app.Tpos)
            [file,path]=uiputfile('res_pos.txt');
            figure(app.scGEAppGSEAPrerankedUIFigure);
            if file~=0
                writetable(app.Tpos,fullfile(path,file),'Delimiter','\t');
            end
            end           
            
        end

        % Button pushed function: SaveTableAsButton_3
        function SaveTableAsButton_3Pushed(app, event)
            if ~isempty(app.Tneg)
            [file,path] = uiputfile('res_neg.txt');
            figure(app.scGEAppGSEAPrerankedUIFigure);
            if file~=0
                writetable(app.Tneg,fullfile(path,file),'Delimiter','\t');
            end
            end            
        end

        % Button pushed function: SaveTableAsButton
        function SaveTableAsButtonPushed(app, event)
            t=app.UITableGList.Data;
            if ~isempty(t)
                [file,path] = uiputfile('ranked_genelist.txt');
                figure(app.scGEAppGSEAPrerankedUIFigure);
                if file~=0
                    writetable(t,fullfile(path,file),'Delimiter','\t');
                end
            end  
        end

        % Button pushed function: AssignTableIntoWorkspaceButton_3
        function AssignTableIntoWorkspaceButton_3Pushed(app, event)
            t=app.UITableGList.Data;
            labels = {'Save Ranked Genelist to variable named:'}; 
                vars = {'T'}; 
                values = {t};
                export2wsdlg(labels,vars,values);
        end

        % Button pushed function: AssignTableIntoWorkspaceButton
        function AssignTableIntoWorkspaceButtonPushed(app, event)
            labels = {'Save Positive GSEA Result (Tpos) to variable named:'}; 
                vars = {'Tpos'}; 
                values = {app.Tpos};
                export2wsdlg(labels,vars,values);            
        end

        % Button pushed function: AssignTableIntoWorkspaceButton_2
        function AssignTableIntoWorkspaceButton_2Pushed(app, event)
            labels = {'Save Negative GSEA Result (Tneg) to variable named:'}; 
                vars = {'Tneg'};
                values = {app.Tneg};
                export2wsdlg(labels,vars,values);              
        end

        % Button pushed function: LoadPrerankedGenesButton
        function LoadPrerankedGenesButtonPushed(app, event)
            % commandwindow;
            [file, path] = uigetfile( ...
               {'*.csv;*.tab;*.txt', 'Preranked Gene List Files (*.csv, *.tab, *.txt)';
                '*.*',  'All Files (*.*)'}, ...
                'Pick a Gene List file');
                figure(app.scGEAppGSEAPrerankedUIFigure);
        	if isequal(file,0), return; end
%                 d=uiprogressdlg(app.scGEAppGSEAPrerankedUIFigure,...
%                     'Title','Loading Preranked Genes',...
%                     'Indeterminate','on');
%               drawnow; pause(0.5); % https://www.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
        	filename=fullfile(path,file);         
                T=readtable(filename);
                app.load_datatable(T);
%               close(d);
        end

        % Button pushed function: AboutMSigDBButton
        function AboutMSigDBButtonPushed(app, event)
            web('http://software.broadinstitute.org/gsea/msigdb/collections.jsp');
        end

        % Value changed function: NumberofPermutationsSlider
        function NumberofPermutationsSliderValueChanged(app, event)
            value = app.NumberofPermutationsSlider.Value;
            allowedsel=app.NumberofPermutationsSlider.MajorTicks;            
            [~,idx]=min(abs(value-allowedsel));
            app.NumberofPermutationsSlider.Value=allowedsel(idx);            
        end

        % Button pushed function: GuidetointerpretresultsButton, 
        % GuidetointerpretresultsButton_2
        function GuidetointerpretresultsButtonPushed(app, event)
            web('http://www.gsea-msigdb.org/gsea/doc/GSEAUserGuideFrame.html?_Interpreting_GSEA_Results');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.scGEAppGSEAPrerankedUIFigure = uifigure('Visible', 'off');
            app.scGEAppGSEAPrerankedUIFigure.Name = 'scGEApp - GSEA (Preranked)';
            app.SelectRankVaribleDropDownLabel = uilabel(app.scGEAppGSEAPrerankedUIFigure);
            app.SelectRankVaribleDropDownLabel.Text = 'Select Rank Varible';
            app.SelectRankVaribleDropDown = uidropdown(app.scGEAppGSEAPrerankedUIFigure);
            app.RunGSEAButton = uibutton(app.scGEAppGSEAPrerankedUIFigure, 'push');
            app.RunGSEAButton.ButtonPushedFcn = createCallbackFcn(app, @RunGSEAButtonPushed, true);
            app.RunGSEAButton.Enable = 'off';
            app.RunGSEAButton.Text = 'Run GSEA...';
            app.TabGroup = uitabgroup(app.scGEAppGSEAPrerankedUIFigure);
            app.PrerankedGenesTab = uitab(app.TabGroup);
            app.PrerankedGenesTab.Title = 'Preranked Genes';
            app.UITableGList = uitable(app.PrerankedGenesTab);
            app.UITableGList.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableGList.RowName = {};
            app.SaveTableAsButton = uibutton(app.PrerankedGenesTab, 'push');
            app.SaveTableAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableAsButtonPushed, true);
            app.SaveTableAsButton.Text = 'Save Table As...';
            app.AssignTableIntoWorkspaceButton_3 = uibutton(app.PrerankedGenesTab, 'push');
            app.AssignTableIntoWorkspaceButton_3.ButtonPushedFcn = createCallbackFcn(app, @AssignTableIntoWorkspaceButton_3Pushed, true);
            app.AssignTableIntoWorkspaceButton_3.Text = 'Assign Table Into Workspace...';
            app.GeneSetsSettingsTab = uitab(app.TabGroup);
            app.GeneSetsSettingsTab.Title = 'Gene Sets & Settings';
            app.ParameterSettingsPanel = uipanel(app.GeneSetsSettingsTab);
            app.ParameterSettingsPanel.Title = 'Parameter Settings';
            app.NumberofPermutationsSliderLabel = uilabel(app.ParameterSettingsPanel);
            app.NumberofPermutationsSliderLabel.Text = 'Number of Permutations:';
            app.NumberofPermutationsSlider = uislider(app.ParameterSettingsPanel);
            app.NumberofPermutationsSlider.Limits = [100 2000];
            app.NumberofPermutationsSlider.MajorTicks = [100 500 1000 1500 2000];
            app.NumberofPermutationsSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofPermutationsSliderValueChanged, true);
            app.NumberofPermutationsSlider.MinorTicks = [100 500 1000 1500 2000];
            app.NumberofPermutationsSlider.Value = 1000;
            app.SortingDirectionofRankVaribleButtonGroup = uibuttongroup(app.ParameterSettingsPanel);
            app.SortingDirectionofRankVaribleButtonGroup.Title = 'Sorting Direction of Rank Varible';
            app.ascendButton = uiradiobutton(app.SortingDirectionofRankVaribleButtonGroup);
            app.ascendButton.Text = 'ascend';
            app.descendButton = uiradiobutton(app.SortingDirectionofRankVaribleButtonGroup);
            app.descendButton.Text = 'descend';
            app.descendButton.Value = true;
            app.GeneSetsPanel = uipanel(app.GeneSetsSettingsTab);
            app.GeneSetsPanel.Title = 'Gene Sets';
            app.MSigDBv62updatedJuly2018Label = uilabel(app.GeneSetsPanel);
            app.MSigDBv62updatedJuly2018Label.Text = 'MSigDB v6.2 (updated July 2018)';
            app.MSigDBv62updatedJuly2018ListBox = uilistbox(app.GeneSetsPanel);
            app.MSigDBv62updatedJuly2018ListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7'};
            app.AboutMSigDBButton = uibutton(app.GeneSetsPanel, 'push');
            app.AboutMSigDBButton.ButtonPushedFcn = createCallbackFcn(app, @AboutMSigDBButtonPushed, true);
            app.AboutMSigDBButton.Text = 'About MSigDB...';
            app.PositivelyCorrSetsTab = uitab(app.TabGroup);
            app.PositivelyCorrSetsTab.Title = 'Positively Corr Sets';
            app.UITablePRes = uitable(app.PositivelyCorrSetsTab);
            app.UITablePRes.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITablePRes.RowName = {};
            app.UITablePRes.CellSelectionCallback = createCallbackFcn(app, @UITablePResCellSelection, true);
            app.UITablePRes.Tooltip = {'.'};
            app.SaveTableAsButton_2 = uibutton(app.PositivelyCorrSetsTab, 'push');
            app.SaveTableAsButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveTableAsButton_2Pushed, true);
            app.SaveTableAsButton_2.Text = 'Save Table As... ';
            app.AssignTableIntoWorkspaceButton = uibutton(app.PositivelyCorrSetsTab, 'push');
            app.AssignTableIntoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @AssignTableIntoWorkspaceButtonPushed, true);
            app.AssignTableIntoWorkspaceButton.Text = 'Assign Table Into Workspace...';
            app.Label = uilabel(app.PositivelyCorrSetsTab);
            app.Label.Text = '';
            app.ClickongenesettoplotresultCheckBox = uicheckbox(app.PositivelyCorrSetsTab);
            app.ClickongenesettoplotresultCheckBox.Text = {'Click on gene set'; 'to plot result'};
            app.ClickongenesettoplotresultCheckBox.Value = true;
            app.GuidetointerpretresultsButton = uibutton(app.PositivelyCorrSetsTab, 'push');
            app.GuidetointerpretresultsButton.ButtonPushedFcn = createCallbackFcn(app, @GuidetointerpretresultsButtonPushed, true);
            app.GuidetointerpretresultsButton.Text = 'Guide to interpret results...';
            app.NegativelyCorrSetsTab = uitab(app.TabGroup);
            app.NegativelyCorrSetsTab.Title = 'Negatively Corr Sets';
            app.UITableNRes = uitable(app.NegativelyCorrSetsTab);
            app.UITableNRes.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableNRes.RowName = {};
            app.UITableNRes.CellSelectionCallback = createCallbackFcn(app, @UITableNResCellSelection, true);
            app.UITableNRes.Tooltip = {'.'};
            app.SaveTableAsButton_3 = uibutton(app.NegativelyCorrSetsTab, 'push');
            app.SaveTableAsButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveTableAsButton_3Pushed, true);
            app.SaveTableAsButton_3.Text = 'Save Table As...';
            app.AssignTableIntoWorkspaceButton_2 = uibutton(app.NegativelyCorrSetsTab, 'push');
            app.AssignTableIntoWorkspaceButton_2.ButtonPushedFcn = createCallbackFcn(app, @AssignTableIntoWorkspaceButton_2Pushed, true);
            app.AssignTableIntoWorkspaceButton_2.Text = 'Assign Table Into Workspace...';
            app.ClickongenesettoplotresultCheckBox_2 = uicheckbox(app.NegativelyCorrSetsTab);
            app.ClickongenesettoplotresultCheckBox_2.Text = {'Click on gene set'; 'to plot result'};
            app.ClickongenesettoplotresultCheckBox_2.Value = true;
            app.GuidetointerpretresultsButton_2 = uibutton(app.NegativelyCorrSetsTab, 'push');
            app.GuidetointerpretresultsButton_2.ButtonPushedFcn = createCallbackFcn(app, @GuidetointerpretresultsButtonPushed, true);
            app.GuidetointerpretresultsButton_2.Text = 'Guide to interpret results...';
            app.PlotResultsCheckBox = uicheckbox(app.scGEAppGSEAPrerankedUIFigure);
            app.PlotResultsCheckBox.Text = 'Plot Results';
            app.ReadyLampLabel = uilabel(app.scGEAppGSEAPrerankedUIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.scGEAppGSEAPrerankedUIFigure);
            app.ReadyLamp.Enable = 'off';
            app.LoadPrerankedGenesButton = uibutton(app.scGEAppGSEAPrerankedUIFigure, 'push');
            app.LoadPrerankedGenesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPrerankedGenesButtonPushed, true);
            app.LoadPrerankedGenesButton.Text = 'Load Preranked Genes...';
            % Show the figure after all components are created
            app.scGEAppGSEAPrerankedUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uifig_gseapreranked

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.scGEAppGSEAPrerankedUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.scGEAppGSEAPrerankedUIFigure)
        end
    end
end