classdef scGEApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        scGEAppUIFigure                 matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadDataTab                     matlab.ui.container.Tab
        Data1Panel                      matlab.ui.container.Panel
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        SelectFileButton                matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        PreviewButton                   matlab.ui.control.Button
        Data2Panel                      matlab.ui.container.Panel
        FileNameEditField_2Label        matlab.ui.control.Label
        FileNameEditField_2             matlab.ui.control.EditField
        SelectFileButton_2              matlab.ui.control.Button
        LoadButton_2                    matlab.ui.control.Button
        PreviewButton_2                 matlab.ui.control.Button
        NextButton_LoadData             matlab.ui.control.Button
        DataSourceButtonGroup           matlab.ui.container.ButtonGroup
        YourOwnButton                   matlab.ui.control.RadioButton
        Example1DataButton              matlab.ui.control.RadioButton
        Example2DataButton              matlab.ui.control.RadioButton
        FilterTab                       matlab.ui.container.Tab
        NextButton_Filter               matlab.ui.control.Button
        BackButton_Filter               matlab.ui.control.Button
        Filter1Panel                    matlab.ui.container.Panel
        FilterGenesButton               matlab.ui.control.Button
        FilterCellsButton               matlab.ui.control.Button
        MinimalofnonzerospergeneSpinnerLabel  matlab.ui.control.Label
        MinimalofnonzerospergeneSpinner  matlab.ui.control.Spinner
        MinimalofnonzerospercellSpinnerLabel  matlab.ui.control.Label
        MinimalofnonzerospercellSpinner  matlab.ui.control.Spinner
        Filter2Panel                    matlab.ui.container.Panel
        SelectGenesButton               matlab.ui.control.Button
        cellshavingSpinnerLabel         matlab.ui.control.Label
        cellshavingSpinner              matlab.ui.control.Spinner
        readspercellLabel               matlab.ui.control.Label
        readspercellSpinner             matlab.ui.control.Spinner
        withatleastLabel                matlab.ui.control.Label
        RemoveMTgenesButton             matlab.ui.control.Button
        NormalizationTab                matlab.ui.container.Tab
        NextButton_3                    matlab.ui.control.Button
        BackButton_2                    matlab.ui.control.Button
        Method1Panel                    matlab.ui.container.Panel
        logx1Button                     matlab.ui.control.StateButton
        LibrarySizeNormalizationButton  matlab.ui.control.StateButton
        Method2Panel                    matlab.ui.container.Panel
        DESeqNormalizationButton        matlab.ui.control.StateButton
        sqrtxButton                     matlab.ui.control.StateButton
        BatchCorrectionTab              matlab.ui.container.Tab
        GeneIntersectionofData12Button  matlab.ui.control.Button
        BackButton_3                    matlab.ui.control.Button
        NextButton_4                    matlab.ui.control.Button
        DataSelectionButtonGroup_Bat    matlab.ui.container.ButtonGroup
        Data1Button_Bat                 matlab.ui.control.RadioButton
        Data2Button_Bat                 matlab.ui.control.RadioButton
        Data12Button_Bat                matlab.ui.control.RadioButton
        Method1Panel_2                  matlab.ui.container.Panel
        ComBatJohnsonetal2007Button     matlab.ui.control.StateButton
        Method2Panel_2                  matlab.ui.container.Panel
        HCPMostafavietal2013Button      matlab.ui.control.StateButton
        ImputationTab                   matlab.ui.container.Tab
        BackButton_4                    matlab.ui.control.Button
        NextButton_5                    matlab.ui.control.Button
        DataSelectionButtonGroup_Imp    matlab.ui.container.ButtonGroup
        Data1Button_Imp                 matlab.ui.control.RadioButton
        Data2Button_Imp                 matlab.ui.control.RadioButton
        Data12Button_Imp                matlab.ui.control.RadioButton
        Method1Panel_3                  matlab.ui.container.Panel
        MAGICvanDijketal2018Button      matlab.ui.control.StateButton
        Method2Panel_3                  matlab.ui.container.Panel
        mcImputeMongiaetal2019Button    matlab.ui.control.StateButton
        FeatureSelectionTab             matlab.ui.container.Tab
        DataSelectionButtonGroup_Fea    matlab.ui.container.ButtonGroup
        Data1Button_Fea                 matlab.ui.control.RadioButton
        Data2Button_Fea                 matlab.ui.control.RadioButton
        Data12Button_Fea                matlab.ui.control.RadioButton
        BackButton_5                    matlab.ui.control.Button
        NextButton_6                    matlab.ui.control.Button
        SplineFitbasedFeatureSelectionPanel  matlab.ui.container.Panel
        MeanCVDropout3DSplineFitButton  matlab.ui.control.Button
        ShowFitCurveCheckBox            matlab.ui.control.CheckBox
        HighlyVariableGeneHVGSelectionPanel  matlab.ui.container.Panel
        HVGBrenneckeetal2013Button      matlab.ui.control.Button
        ShowFitCurveCheckBox_2          matlab.ui.control.CheckBox
        VEGChenetal2016Button           matlab.ui.control.Button
        VisualizationTab                matlab.ui.container.Tab
        DataSelectionButtonGroup_Vis    matlab.ui.container.ButtonGroup
        Data1Button_Vis                 matlab.ui.control.RadioButton
        Data2Button_Vis                 matlab.ui.control.RadioButton
        Data12Button_Vis                matlab.ui.control.RadioButton
        AllCellsPanel                   matlab.ui.container.Panel
        PCAButton                       matlab.ui.control.Button
        DiffusionMapButton              matlab.ui.control.Button
        tSNEButton                      matlab.ui.control.Button
        PHATE3DButton                   matlab.ui.control.Button
        AllGenesPanel                   matlab.ui.container.Panel
        MeanCVDropout3DScatterButton    matlab.ui.control.Button
        MeanDropout2DScatterButton      matlab.ui.control.Button
        MeanCV2DScatterButton           matlab.ui.control.Button
        MeanlogVarlog2DScatterButton    matlab.ui.control.Button
        CombinedEmbeddingButton         matlab.ui.control.Button
        FeaturedGenesPanel              matlab.ui.container.Panel
        ExpressionLandscape3DStemButton  matlab.ui.control.Button
        BackButton_6                    matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        UITableData1                    matlab.ui.control.Table
        UITableData2                    matlab.ui.control.Table
        AssignData1IntoWorkspaceButton  matlab.ui.control.Button
        SaveData1AsButton               matlab.ui.control.Button
        AssignData2IntoWorkspaceButton  matlab.ui.control.Button
        SaveData2AsButton               matlab.ui.control.Button
        Data1NullLabel                  matlab.ui.control.Label
        Data2NullLabel                  matlab.ui.control.Label
        ResultTab                       matlab.ui.container.Tab
        UITableRes                      matlab.ui.control.Table
        SaveTableAsButton               matlab.ui.control.Button
        AssignTableIntoWorkspaceButton  matlab.ui.control.Button
        RunGSEAPrerankedButton          matlab.ui.control.Button
        RunGOrillaButton                matlab.ui.control.Button
        AboutTab                        matlab.ui.container.Tab
        ContributorsTextAreaLabel       matlab.ui.control.Label
        ContributorsTextArea            matlab.ui.control.TextArea
        AuthorLabel                     matlab.ui.control.Label
        AuthorTextArea                  matlab.ui.control.TextArea
        scGEApp2019SinglecellGeneExpressionAnalysisLabel  matlab.ui.control.Label
        CommandlineFunctionsPanel       matlab.ui.container.Panel
        Demo1Button                     matlab.ui.control.Button
        Demo2Button                     matlab.ui.control.Button
        Demo3Button                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        X % Data 1
        Y % Data 2
        genelistx %
        genelisty %
        T           % Result Table
        FigPreview  % Data previewer
    end
    
    methods (Access = private)
        function plot_3dscatter(app,id)
            figure;
            switch id
                case 1
                    sc_scatter3(app.X,app.genelistx,true,true);
                    title('Data 1');
                case 2
                    sc_scatter3(app.Y,app.genelisty,true,true);
                    title('Data 2');
                case 3
                    %sc_scatter3(app.X,app.genelistx,true,true);
                    %hold on
                    %sc_scatter3(app.Y,app.genelisty,true,true);
                    %title('Data 1 & 2');
                    %legend({'Data 1','Spline 1','Data 2','Spline 2'},'location','northeast');
                    sc_scatter3x(app.X,app.Y,app.genelistx,true,true);
                    title('Data 1 & 2')
                    legend({'Data 1','Spline 1','Data 2','Spline 2'},'location','northeast');
            end
        end
    
        function reset_state_buttons(app)
            app.ComBatJohnsonetal2007Button.Value=false;
            app.HCPMostafavietal2013Button.Value=false;
            app.logx1Button.Value=false;
            app.sqrtxButton.Value=false;
            app.LibrarySizeNormalizationButton.Value=false;
            app.DESeqNormalizationButton.Value=false;
        end
    
        function preview_data(app,fn)
                opts=detectImportOptions(fn,'filetype','text');
                tpre=preview(fn,opts);
                if isempty(app.FigPreview) || ~isvalid(app.FigPreview)  
                    app.FigPreview=uifigure('Name','Data Preview');
                    app.FigPreview.Position=[app.FigPreview.Position(1:2),400,300];
                end
                    t=uitable(app.FigPreview,...
                        'Position',[10, 10, app.FigPreview.Position(3:4)-20]);
                    t.Data=tpre(:,1:50);
        end
    
        function update_expr_tables(app,tabid)
            if nargin<2, tabid=12; end
            if tabid==1 || tabid==12
            if ~isempty(app.X) && ~isempty(app.genelistx)                
                t=table();
                t.genes=string(app.genelistx(1:50));
                t=[t,array2table(app.X(1:50,1:20))];
                %t.genes=string(app.genelistx);
                %t=[t,array2table(app.X)];                
                app.UITableData1.Data=t;
                app.AssignData1IntoWorkspaceButton.Enable=true;
                app.SaveData1AsButton.Enable=true;
                app.Data1NullLabel.Text=sprintf('Data 1: [%d genes x %d cells]',size(app.X,1),size(app.X,2));
            end
            end
            if tabid==2 || tabid==12
            if ~isempty(app.Y) && ~isempty(app.genelisty)                
                t2=table();
                t2.genes=string(app.genelisty(1:50));
                t2=[t2,array2table(app.Y(1:50,1:20))];
                app.UITableData2.Data=t2;
                app.AssignData2IntoWorkspaceButton.Enable=true;
                app.SaveData2AsButton.Enable=true;
                app.Data2NullLabel.Text=sprintf('Data 2: [%d genes x %d cells]',size(app.Y,1),size(app.Y,2));
            end
            end
            if ~isempty(app.T)
                    app.UITableRes.Data=app.T;
                    app.UITableRes.ColumnName=app.T.Properties.VariableNames;
                    % app.TabGroup2.SelectedTab=app.ResultTab;
            end
        end
    
        function cell_scatter(app,score,id)
            a=uifig_embeddingview;
            switch id
                case 1
                    % app.T=sc_splinefit(app.X,app.genelistx,true);
                    gl50=app.T.genes;
                    [~,idx]=ismember(gl50,app.genelistx);
                    a.X=app.X(idx,:);
                    a.genelistx=app.genelistx(idx);        
                case 2
                    % app.T=sc_splinefit(app.Y,app.genelisty,true);
                    gl50=app.T.genes;
                    [~,idx]=ismember(gl50,app.genelisty);
                    a.X=app.Y(idx,:);
                    a.genelistx=app.genelisty(idx);
                case 12
                    % app.T=sc_splinefit2(app.X,app.Y,app.genelistx,app.genelisty,true);
                    gl50=app.T.genes;
                    [~,idx]=ismember(gl50,app.genelisty);
                    TMP=[app.X app.Y];
                    a.X=TMP(idx,:);
                    a.genelistx=app.genelistx(idx);
            end
            a.geneDropDown.Items=app.T.genes;
            a.score=score;
            a.update_scatter;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           cdgea;
           addpath('example_data');
           addpath(genpath('thirdparty'));
           % [app.X,app.genelistx]=sc_readfile('example_data/GSM3044891_GeneExp.UMIs.10X1.txt');
           % [app.X,app.genelistx]=sc_readfile('example_data/GSM3204304_P_P_Expr_999cells.csv');
           % [app.Y,app.genelisty]=sc_readfile('example_data/GSM3204305_P_N_Expr_999cells.csv');
           movegui(app.scGEAppUIFigure,'center');
           % app.scGEAppUIFigure.Position
        end

        % Button pushed function: NextButton_LoadData
        function NextButton_LoadDataPushed(app, event)
            app.TabGroup.SelectedTab=app.FilterTab;
            TabGroupSelectionChanged(app,event);                 
        end

        % Button pushed function: NextButton_Filter
        function NextButton_FilterPushed(app, event)
            app.TabGroup.SelectedTab=app.NormalizationTab;
            TabGroupSelectionChanged(app,event);             
        end

        % Button pushed function: BackButton_Filter
        function BackButton_FilterPushed(app, event)
            app.TabGroup.SelectedTab=app.LoadDataTab;
            TabGroupSelectionChanged(app,event);             
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.FilterTab;
            TabGroupSelectionChanged(app,event);             
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            
            app.TabGroup.SelectedTab=app.BatchCorrectionTab;
            TabGroupSelectionChanged(app,event);             
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            % commandwindow;
            [file, path] = uigetfile( ...
               {'*.csv;*.tab;*.txt', 'Expression Matrix Files (*.csv, *.tab, *.txt)';
                '*.*',  'All Files (*.*)'}, ...
                'Pick a Exprssion Matrix file');
                % https://www.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
                figure(app.scGEAppUIFigure);
                if isequal(file,0), return; end
                % drawnow; pause(0.5);
        	filename=[path,file];
                % fullfilename=fullfile(path,file);
                app.FileNameEditField.Value=filename;
        end

        % Button pushed function: SelectFileButton_2
        function SelectFileButton_2Pushed(app, event)
            if ~isempty(app.X)
                     [filename, pathname] = uigetfile( ...
               {'*.csv;*.tab;*.txt', 'Expression Matrix Files (*.csv, *.tab, *.txt)';
                '*.*',  'All Files (*.*)'}, ...
                'Pick a Exprssion Matrix file');
                figure(app.scGEAppUIFigure);
        	if ~(filename), return; end
                % drawnow; pause(0.5);
        	filename=[pathname,filename];
                %filename=fullfile(path,file);
                app.FileNameEditField_2.Value=filename;
            else                
                uialert(app.scGEAppUIFigure,'Data 1 needs to be loaded first!','Load Data');
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if ~isempty(app.FileNameEditField.Value)
                d = uiprogressdlg(app.scGEAppUIFigure,'Title','Please Wait',...
                    'Message','Opening Data 1 File'); pause(1)
                d.Value = .33;  d.Message = 'Loading Data 1'; pause(.5)                
                [app.X,app.genelistx]=sc_readfile(app.FileNameEditField.Value);
                update_expr_tables(app,1);
                d.Value = .67;  d.Message = 'Loading Data 1'; pause(1)
                d.Value = 1; d.Message = 'Finishing'; pause(1); close(d);                
                app.SelectFileButton_2.Enable=true;
                app.PreviewButton_2.Enable=true;
                app.LoadButton_2.Enable=true;
                update_expr_tables(app,1);
                reset_state_buttons(app);
                % dbk=dbstack; helpdlg([dbk(1).name '......done.']);                
            end
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            if strcmp(app.FileNameEditField.Value, app.FileNameEditField_2.Value)
                % uialert(app.scGEAppUIFigure,'Trying to load the same data file','Duplicate Data');
            end
            if ~isempty(app.FileNameEditField_2.Value)
                
                d = uiprogressdlg(app.scGEAppUIFigure,'Title','Please Wait',...
                    'Message','Opening Data 2 File'); pause(1)
                d.Value = .33;  d.Message = 'Loading Data 2'; pause(.5)                
                [app.Y,app.genelisty]=sc_readfile(app.FileNameEditField_2.Value);
                update_expr_tables(app,2);
                d.Value = .67;  d.Message = 'Loading Data 2'; pause(1)
                d.Value = 1; d.Message = 'Finishing'; pause(1); close(d);                
                reset_state_buttons(app);
                % dbk=dbstack; helpdlg([dbk(1).name '......done.']);
            end
        end

        % Callback function
        function logx1transformationButtonValueChanged(app, event)
            update_expr_tables(app);            
            value = app.logx1Button.Value;
            if value
                if ~isempty(app.X)
                    app.X=log(app.X+1);
                    update_expr_tables(app,1);
                end
                if ~isempty(app.Y)
                    app.Y=log(app.Y+1);
                    update_expr_tables(app,2);
                end
            else
                app.logx1Button.Value=true;
            end
        end

        % Button pushed function: FilterGenesButton
        function FilterGenesButtonPushed(app, event)
                if ~isempty(app.X) && ~isempty(app.genelistx)
                    [app.X,app.genelistx]=sc_filterg(app.X,app.genelistx,...
                     app.MinimalofnonzerospergeneSpinner.Value);
                     update_expr_tables(app,1);
                end
                if ~isempty(app.Y) && ~isempty(app.genelisty)    
                    [app.Y,app.genelisty]=sc_filterg(app.Y,app.genelisty,...
                               app.MinimalofnonzerospergeneSpinner.Value);
                     update_expr_tables(app,2);
                end                
                % dbk=dbstack; helpdlg([dbk(1).name '......done.']);
        end

        % Button pushed function: FilterCellsButton
        function FilterCellsButtonPushed(app, event)
                if ~isempty(app.X) && ~isempty(app.genelistx)                
                     [app.X]=sc_filterc(app.X,app.MinimalofnonzerospercellSpinner.Value);
                     update_expr_tables(app,1);
                end                            
                if ~isempty(app.Y) && ~isempty(app.genelisty)                
                     [app.Y]=sc_filterc(app.Y,app.MinimalofnonzerospercellSpinner.Value);
                     update_expr_tables(app,2);
                end
                % dbk=dbstack; helpdlg([dbk(1).name '......done.']);
        end

        % Value changed function: logx1Button
        function logx1ButtonValueChanged2(app, event)
            value = app.logx1Button.Value;
            app.sqrtxButton.Enable=false;
            if value
                if ~isempty(app.X)
                    app.X=log(app.X+1);
                    update_expr_tables(app,1);
                end
                if ~isempty(app.Y)
                    app.Y=log(app.Y+1);
                    update_expr_tables(app,2);
                end
%               app.Method2Panel.Visible=false;
                app.DESeqNormalizationButton.Enable=false;
                
            else
                app.logx1Button.Value=true;                
            end
        end

        % Value changed function: LibrarySizeNormalizationButton
        function LibrarySizeNormalizationButtonValueChanged(app, event)
            value = app.LibrarySizeNormalizationButton.Value;
            if value
                if ~isempty(app.X)
                  [app.X]=sc_norm(app.X,'type','libsize');
                 update_expr_tables(app,1);
                 end
                 if ~isempty(app.Y)
                 [app.Y]=sc_norm(app.Y,'type','libsize');
                 update_expr_tables(app,2);
                 end
                app.DESeqNormalizationButton.Enable=false;
            else
                app.LibrarySizeNormalizationButton.Value=true;
            end
        end

        % Value changed function: DESeqNormalizationButton
        function DESeqNormalizationButtonValueChanged(app, event)
            value = app.DESeqNormalizationButton.Value;
            if value
                if ~isempty(app.X)
                 [app.X]=sc_norm(app.X,'type','deseq');
                 update_expr_tables(app,1);
                 end
                 if ~isempty(app.Y)
                     [app.Y]=sc_norm(app.Y,'type','deseq');
                     update_expr_tables(app,2);
                 end
                app.LibrarySizeNormalizationButton.Enable=false;
                app.logx1Button.Enable=false;
                app.sqrtxButton.Enable=false;

            else
                app.DESeqNormalizationButton.Value=true;
            end            
            
        end

        % Button pushed function: SelectGenesButton
        function SelectGenesButtonPushed(app, event)
            if ~isempty(app.X)
                [app.X,app.genelistx]=sc_selectg(app.X,app.genelistx,...
                    app.readspercellSpinner.Value,...
                    app.cellshavingSpinner.Value);
                update_expr_tables(app,1);
            end
            if ~isempty(app.Y)
                [app.Y,app.genelisty]=sc_selectg(app.Y,app.genelisty,...
                    app.readspercellSpinner.Value,...
                    app.cellshavingSpinner.Value);
                update_expr_tables(app,2);
            end            
        end

        % Button pushed function: AssignData1IntoWorkspaceButton
        function AssignData1IntoWorkspaceButtonPushed(app, event)
                labels = {'Save app.X to variable named:' ...
                      'Save app.GENELISTX to variable named:'}; 
                vars = {'X','genelistx'}; 
                values = {app.X,app.genelistx};
                export2wsdlg(labels,vars,values);
        end

        % Button pushed function: AssignData2IntoWorkspaceButton
        function AssignData2IntoWorkspaceButtonPushed(app, event)
                labels = {'Save app.Y to variable named:' ...
                      'Save app.GENELISTY to variable named:'}; 
                vars = {'Y','genelisty'}; 
                values = {app.Y,app.genelisty};
                export2wsdlg(labels,vars,values);            
        end

        % Button pushed function: SaveData1AsButton
        function SaveData1AsButtonPushed(app, event)
            if ~isempty(app.X)
            t=table();
                t.genes=string(app.genelistx);
                t=[t,array2table(app.X)];            
                [file,path] = uiputfile('data_1.txt');
                figure(app.scGEAppUIFigure);                
                if file~=0
                    writetable(t,fullfile(path,file),'Delimiter','\t');
                end
            end            
        end

        % Value changed function: MAGICvanDijketal2018Button
        function MAGICvanDijketal2018ButtonValueChanged(app, event)
            value = app.MAGICvanDijketal2018Button.Value;
            if value
                if ~isempty(app.X) && (app.Data1Button_Imp.Value || app.Data12Button_Imp.Value)
                    if ~isnormalized(app.X)                    
                        answer = uiconfirm(app.scGEAppUIFigure,...
                               'It is recommended to normalize data first. Do you want to continue with raw data?',...
                               'Normalized Data','Icon','warning');
                        switch answer
                            case 'Cancel'
                                app.MAGICvanDijketal2018Button.Value=false;
                                return;
                            case 'OK'                           
                                % continue 
                        end
                    end            
                    d = uiprogressdlg(app.scGEAppUIFigure,'Title','Please Wait',...
                        'Message','Loading Data 1'); pause(.5)
                    d.Value = .33;  d.Message = 'Processing Data 1'; pause(.5)
                    [app.X]=run_magic(app.X,false);
                    d.Value = .67;  d.Message = 'Processing Data 1'; pause(1)
                    d.Value = 1; d.Message = 'Finishing'; pause(1); close(d);
                    update_expr_tables(app,1);
                 end
                 if ~isempty(app.Y) && (app.Data2Button_Imp.Value || app.Data12Button_Imp.Value)  
                    if ~isnormalized(app.Y)
                        answer = uiconfirm(app.scGEAppUIFigure,...
                               'It is recommended to normalize data first. Do you want to continue with raw data?',...
                               'Normalized Data','Icon','warning');
                        switch answer
                            case 'Cancel'
                                app.MAGICvanDijketal2018Button.Value=false;
                                return;
                            case 'OK'                           
                                
                        end
                    end
                    d = uiprogressdlg(app.scGEAppUIFigure,'Title','Please Wait',...
                        'Message','Loading Data 2'); pause(.5)
                    d.Value = .33;  d.Message = 'Processing Data 2'; pause(.5)
                    [app.Y]=run_magic(app.Y,false);
                    d.Value = .67;  d.Message = 'Processing Data 2'; pause(1)
                    d.Value = 1; d.Message = 'Finishing'; pause(1)
                    close(d);
                    update_expr_tables(app,2);
                 end
            else
                app.MAGICvanDijketal2018Button.Value=true;
            end            
        end

        % Button pushed function: GeneIntersectionofData12Button
        function GeneIntersectionofData12ButtonPushed(app, event)
            if ~isempty(app.X) && ~isempty(app.Y)
            [genelist,i,j]=intersect(app.genelistx,app.genelisty,'stable');
            app.genelistx=genelist;
            app.genelisty=genelist;
            app.X=app.X(i,:);
            app.Y=app.Y(j,:);
            update_expr_tables(app);
            % dbk=dbstack; helpdlg([dbk(1).name '......done.']);
            end
        end

        % Selection changed function: DataSourceButtonGroup
        function DataSourceButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataSourceButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Your Own'
                    app.LoadButton_2.Enable=false;
                    app.SelectFileButton_2.Enable=false;
                    app.PreviewButton_2.Enable=false;
                    app.FileNameEditField.Value='';
                    app.FileNameEditField_2.Value='';
                case 'Example 1 Data'
                    app.LoadButton_2.Enable=false;
                    app.SelectFileButton_2.Enable=false;
                    app.PreviewButton_2.Enable=false;                    
                    app.FileNameEditField.Value='example_data/GSM3044891_GeneExp.UMIs.10X1.txt';
                    app.FileNameEditField_2.Value='';                    
                case 'Example 2 Data'
                    app.LoadButton_2.Enable=true;
                    app.SelectFileButton_2.Enable=true;
                    app.PreviewButton_2.Enable=true;
                    app.FileNameEditField.Value='example_data/GSM3204305_P_N_Expr_999cells.csv';
                    app.FileNameEditField_2.Value='example_data/GSM3204304_P_P_Expr_999cells.csv';
            end
        end

        % Button pushed function: tSNEButton
        function tSNEButtonPushed(app, event)
            if app.Data1Button_Vis.Value
                d = uiprogressdlg(app.scGEAppUIFigure,'Title','Please Wait',...
                    'Message','Loading Data 1'); pause(.5)
                d.Value = .33;  d.Message = 'Processing Data 1'; pause(.5)
                s=tsne(app.X');
                d.Value = .67;  d.Message = 'Processing Data 1'; pause(1)
                d.Value = 1; d.Message = 'Finishing'; pause(1)
                close(d);
                figure; scatter(s(:,1),s(:,2)); xlabel('tSNE\_1'); ylabel('tSNE\_2');
                title('tSNE (Perplexity = 30)'); 
                cell_scatter_id=1;

            
            elseif app.Data2Button_Vis.Value
                d = uiprogressdlg(app.scGEAppUIFigure,'Title','Please Wait',...
                    'Message','Loading Data 2'); pause(.5)
                d.Value = .33;  d.Message = 'Processing Data 2'; pause(.5)
                s=tsne(app.Y');
                d.Value = .67;  d.Message = 'Processing Data 2'; pause(1)
                d.Value = 1; d.Message = 'Finishing'; pause(1)
                close(d);
                figure; scatter(s(:,1),s(:,2)); xlabel('tSNE\_1'); ylabel('tSNE\_2');
                title('tSNE (Perplexity = 30)');
                cell_scatter_id=2;
            elseif app.Data12Button_Vis.Value
                if any(app.genelistx~=app.genelisty), return; end                
                d = uiprogressdlg(app.scGEAppUIFigure,'Title','Please Wait',...
                    'Message','Loading Data 1 & 2'); pause(.5)
                d.Value = .33;  d.Message = 'Processing Data 1 & 2'; pause(.5)                
                data=[app.X app.Y];
                indx=[zeros(size(app.X,2),1);ones(size(app.Y,2),1)];                
                s=tsne(data');
                d.Value = .67;  d.Message = 'Processing Data 1 & 2'; pause(1)
                d.Value = 1; d.Message = 'Finishing'; pause(1)
                close(d);
                figure; 
                scatter(s(indx==0,1),s(indx==0,2));
                hold on
                scatter(s(indx==1,1),s(indx==1,2));                
                xlabel('tSNE\_1'); ylabel('tSNE\_2');
                title('tSNE (Perplexity = 30)');
                legend({'Data 1','Data 2'});
                cell_scatter_id=12;
            end
            
            if ~isempty(app.T)
            answer = uiconfirm(app.scGEAppUIFigure,...
                   'Color data points (cells) based on expression values of genes in the Feature Table?',...
                   'Color Plot','Icon','warning');
            switch answer
                case 'Cancel'
                    return;
                case 'OK'                           
                    cell_scatter(app,s,cell_scatter_id);
            end
            end
                        
        end

        % Button pushed function: PCAButton
        function PCAButtonPushed(app, event)
            if app.Data1Button_Vis.Value
                [~,s]=pca(app.X');
                figure; scatter(s(:,1),s(:,2)); xlabel('PC\_1'); ylabel('PC\_2');
            elseif app.Data2Button_Vis.Value
                [~,s]=pca(app.Y');
                figure; scatter(s(:,1),s(:,2)); xlabel('PC\_1'); ylabel('PC\_2');                
            elseif app.Data12Button_Vis.Value
                if any(app.genelistx~=app.genelisty)
                    
                else
                    data=[app.X app.Y];
                    indx=[zeros(size(app.X,2),1);ones(size(app.Y,2),1)];
                    [~,s]=pca(data');
                    figure; 
                    % gscatter(s(:,1),s(:,2),indx); 
                    scatter(s(indx==0,1),s(indx==0,2));
                    hold on
                    scatter(s(indx==1,1),s(indx==1,2));                
                    xlabel('PC\_1'); ylabel('PC\_2');
                    legend({'Data 1','Data 2'});
                end
            end
            
        end

        % Button pushed function: MeanCVDropout3DScatterButton
        function MeanCVDropout3DScatterButtonPushed(app, event)
             id=find([app.Data1Button_Vis.Value app.Data2Button_Vis.Value app.Data12Button_Vis.Value]);
             plot_3dscatter(app,id);
        end

        % Button pushed function: MeanDropout2DScatterButton
        function MeanDropout2DScatterButtonPushed(app, event)
             if app.Data1Button_Vis.Value
                figure;
                sc_scatter(app.X,app.genelistx,'mean_dropr');
                title('Data 1')
             elseif app.Data2Button_Vis.Value
                figure;
                sc_scatter(app.Y,app.genelisty,'mean_dropr');   
                title('Data 2')
             elseif app.Data12Button_Vis.Value
                figure;
                sc_scatter(app.X,app.genelistx,'mean_dropr');
                hold on
                sc_scatter(app.Y,app.genelisty,'mean_dropr');
                legend({'Data 1','Data 2'})
                title('Data 1 & 2');
             end
        end

        % Button pushed function: MeanCV2DScatterButton
        function MeanCV2DScatterButtonPushed(app, event)
             if app.Data1Button_Vis.Value
                figure;
                sc_scatter(app.X,app.genelistx,'mean_cv');
                title('Data 1')
             elseif app.Data2Button_Vis.Value
                figure;
                sc_scatter(app.Y,app.genelisty,'mean_cv');   
                title('Data 2')
             elseif app.Data12Button_Vis.Value
                figure;
                sc_scatter(app.X,app.genelistx,'mean_cv');
                hold on
                sc_scatter(app.Y,app.genelisty,'mean_cv');
                legend({'Data 1','Data 2'})
                title('Data 1 & 2');
             end
        end

        % Button pushed function: MeanlogVarlog2DScatterButton
        function MeanlogVarlog2DScatterButtonPushed(app, event)
             if app.Data1Button_Vis.Value
                figure;
                sc_scatter(app.X,app.genelistx,'meanlg_varlg');
                title('Data 1')
             elseif app.Data2Button_Vis.Value
                figure;
                sc_scatter(app.Y,app.genelisty,'meanlg_varlg');   
                title('Data 2')
             elseif app.Data12Button_Vis.Value
                figure;
                sc_scatter(app.X,app.genelistx,'meanlg_varlg');
                hold on
                sc_scatter(app.Y,app.genelisty,'meanlg_varlg');
                legend({'Data 1','Data 2'})
                title('Data 1 & 2');
             end
        end

        % Button pushed function: ExpressionLandscape3DStemButton
        function ExpressionLandscape3DStemButtonPushed(app, event)
            if isempty(app.T)
                uialert(app.scGEAppUIFigure,'Feature Table is empty. Run FEATURE SELECTION first.','Empty Feature Table');
            else               
                answer = uiconfirm(app.scGEAppUIFigure,'First 50 genes in the Feature Table will be shown.',...
                    'Confirm Data Source','Icon','info');            
                switch answer
                    case 'OK'
                        % app.T=sortrows(app.T,size(app.T,2),'descend');                
                        if app.Data1Button_Vis.Value
                            [t1,idx1]=ismember(table2array(app.T(:,1)),app.genelistx);
                            figure;
                            sc_stem3(app.X(idx1,:),[],app.genelistx(idx1),50);
                        elseif app.Data2Button_Vis.Value
                            [t2,idx2]=ismember(table2array(app.T(:,1)),app.genelisty);
                            figure;
                            sc_stem3(app.Y(idx2,:),[],app.genelisty(idx2),50);
                        elseif app.Data12Button_Vis.Value
                            [t1,idx1]=ismember(table2array(app.T(:,1)),app.genelistx);
                            [t2,idx2]=ismember(table2array(app.T(:,1)),app.genelisty);
                            figure;
                            sc_stem3(app.X(idx1,:),app.Y(idx2,:),app.genelistx(idx1),50);
                        end     
                    case 'Cancel'
                        return;
                end                
            end
        end

        % Button pushed function: MeanCVDropout3DSplineFitButton
        function MeanCVDropout3DSplineFitButtonPushed(app, event)
            % app.DataSelectionButtonGroup_Fea
            if app.Data1Button_Fea.Value
                if ~isempty(app.X)
                    app.T=sc_splinefit(app.X,app.genelistx);
                    app.UITableRes.Data=app.T;
                    app.UITableRes.ColumnName=app.T.Properties.VariableNames;                    
                    app.TabGroup2.SelectedTab=app.ResultTab;
                    
                    if app.ShowFitCurveCheckBox.Value                            
                            plot_3dscatter(app,1);
                    end
                end
            elseif app.Data2Button_Fea.Value                
                if ~isempty(app.Y)
                    app.T=sc_splinefit(app.Y,app.genelisty);
                    app.UITableRes.Data=app.T;
                    app.UITableRes.ColumnName=app.T.Properties.VariableNames;                    
                    app.TabGroup2.SelectedTab=app.ResultTab;
                    if app.ShowFitCurveCheckBox.Value
                            plot_3dscatter(app,2);
                    end
                end
            elseif app.Data12Button_Fea.Value
                if ~isempty(app.X) && ~isempty(app.Y)
                    if any(app.genelistx~=app.genelisty)
                        uialert(app.scGEAppUIFigure,'Numbers of genes of Data 1 & 2 are expected to be the same.','Unequal Numbers of Genes');
                        return;                    
                    end
                    app.T=sc_splinefit2(app.X,app.Y,app.genelistx,app.genelisty);
                    app.UITableRes.Data=app.T;
                    app.UITableRes.ColumnName=app.T.Properties.VariableNames;                    
                    app.TabGroup2.SelectedTab=app.ResultTab;
                    if app.ShowFitCurveCheckBox.Value
                        plot_3dscatter(app,3);
                    end                    
                end
            end

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            update_expr_tables(app);
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab.Title
                case 'Feature Selection'
                    if ~isempty(app.T)
                        app.TabGroup2.SelectedTab=app.ResultTab;
                    end
                    if ~isempty(app.X)
                        app.Data1Button_Fea.Enable=true;
                    else
                         app.Data1Button_Fea.Enable=false;
                    end
                    if ~isempty(app.Y)
                        app.Data2Button_Fea.Enable=true;
                    else
                         app.Data2Button_Fea.Enable=false;
                    end
                    if ~isempty(app.X) && ~isempty(app.Y)
                        app.Data12Button_Fea.Enable=true;
                    else
                         app.Data12Button_Fea.Enable=false;
                    end
                    if ~isempty(app.X) || ~isempty(app.Y)
                        app.MeanCVDropout3DSplineFitButton.Enable=true;                        
                        app.HVGBrenneckeetal2013Button.Enable=true;
                        app.VEGChenetal2016Button.Enable=true;
                        app.ShowFitCurveCheckBox.Enable=true;
                        app.ShowFitCurveCheckBox_2.Enable=true;
                    else
                        app.MeanCVDropout3DSplineFitButton.Enable=false;                        
                        app.HVGBrenneckeetal2013Button.Enable=false;
                        app.VEGChenetal2016Button.Enable=false;
                        app.ShowFitCurveCheckBox.Enable=false;
                        app.ShowFitCurveCheckBox_2.Enable=false;
                    end                    
                   
                case 'Visualization'
                    if ~isempty(app.T)
                        app.TabGroup2.SelectedTab=app.ResultTab;
                    end
                    if ~isempty(app.X)
                        app.Data1Button_Vis.Enable=true;
                    else
                         app.Data1Button_Vis.Enable=false;
                    end
                    if ~isempty(app.Y)
                        app.Data2Button_Vis.Enable=true;
                    else
                         app.Data2Button_Vis.Enable=false;
                    end
                    if ~isempty(app.X) && ~isempty(app.Y)
                        app.Data12Button_Vis.Enable=true;
                    else
                         app.Data12Button_Vis.Enable=false;
                    end
                    if ~isempty(app.X) || ~isempty(app.Y)
                        app.MeanCV2DScatterButton.Enable=true;
                        app.MeanCVDropout3DScatterButton.Enable=true;
                        app.PHATE3DButton.Enable=true;
                        app.CombinedEmbeddingButton.Enable=true;
                        app.MeanDropout2DScatterButton.Enable=true;
                        app.MeanlogVarlog2DScatterButton.Enable=true;
                        app.ExpressionLandscape3DStemButton.Enable=true;
                        app.PCAButton.Enable=true;
                        app.tSNEButton.Enable=true;
                        app.DiffusionMapButton.Enable=true;
                    else
                        app.MeanCV2DScatterButton.Enable=false;
                        app.MeanCVDropout3DScatterButton.Enable=false;
                        app.PHATE3DButton.Enable=false;
                        app.CombinedEmbeddingButton.Enable=false;                      
                        app.MeanDropout2DScatterButton.Enable=false;
                        app.MeanlogVarlog2DScatterButton.Enable=false;
                        app.ExpressionLandscape3DStemButton.Enable=false;
                        app.PCAButton.Enable=false;
                        app.tSNEButton.Enable=false;
                        app.DiffusionMapButton.Enable=false;                        
                    end                        
                case 'Imputation'
                    app.TabGroup2.SelectedTab=app.DataTab;
                    if ~isempty(app.X)
                        app.Data1Button_Imp.Enable=true;
                    else
                         app.Data1Button_Imp.Enable=false;
                    end
                    if ~isempty(app.Y)
                        app.Data2Button_Imp.Enable=true;
                    else
                         app.Data2Button_Imp.Enable=false;
                    end
                    if ~isempty(app.X) && ~isempty(app.Y)
                        app.Data12Button_Imp.Enable=true;
                    else
                         app.Data12Button_Imp.Enable=false;
                    end
                    if ~isempty(app.X) || ~isempty(app.Y)
                        app.MAGICvanDijketal2018Button.Enable=true;
                        app.mcImputeMongiaetal2019Button.Enable=true;
                    else
                        app.MAGICvanDijketal2018Button.Enable=false;
                        app.mcImputeMongiaetal2019Button.Enable=false;
                    end                    
                case 'Batch Correction'
                    if ~isempty(app.X) && ~isempty(app.Y)
                        app.Data12Button_Bat.Enable=true;
                        app.GeneIntersectionofData12Button.Enable=true;
                        app.ComBatJohnsonetal2007Button.Enable=true;
                        app.HCPMostafavietal2013Button.Enable=true;
                    else
                        app.Data12Button_Bat.Enable=false;
                        app.GeneIntersectionofData12Button.Enable=false;
                        app.ComBatJohnsonetal2007Button.Enable=false;
                        app.HCPMostafavietal2013Button.Enable=false;
                    end
                otherwise
                    app.TabGroup2.SelectedTab=app.DataTab;
                    if ~isempty(app.X) || ~isempty(app.Y)
                        app.FilterCellsButton.Enable=true;
                        app.FilterGenesButton.Enable=true;
                        app.RemoveMTgenesButton.Enable=true;
                        app.SelectGenesButton.Enable=true;
                        app.LibrarySizeNormalizationButton.Enable=true;
                        app.logx1Button.Enable=true;
                        app.sqrtxButton.Enable=true;
                        app.DESeqNormalizationButton.Enable=true;
                    else
                        app.FilterCellsButton.Enable=false;
                        app.FilterGenesButton.Enable=false;
                        app.RemoveMTgenesButton.Enable=false;
                        app.SelectGenesButton.Enable=false;
                        app.LibrarySizeNormalizationButton.Enable=false;
                        app.logx1Button.Enable=false;
                        app.sqrtxButton.Enable=false;
                        app.DESeqNormalizationButton.Enable=false;                        
                    end
            end
        end

        % Button pushed function: RunGSEAPrerankedButton
        function RunGSEAPrerankedButtonPushed(app, event)
            addpath('thirdparty\GSEA\');
            a=uifig_gseapreranked;
            if ~isempty(app.UITableRes.Data)
                a.load_datatable(app.UITableRes.Data);
            end
        end

        % Button pushed function: AssignTableIntoWorkspaceButton
        function AssignTableIntoWorkspaceButtonPushed(app, event)
                if ~isempty(app.T)
                labels = {'Save app.T to variable named:'}; 
                vars = {'T'}; 
                values = {app.T};
                export2wsdlg(labels,vars,values);
                end
        end

        % Value changed function: ComBatJohnsonetal2007Button
        function ComBatJohnsonetal2007ButtonValueChanged(app, event)
            if ~isempty(app.X) && ~isempty(app.Y)
            value = app.ComBatJohnsonetal2007Button.Value;
            if value
                    if size(app.X,1)~=size(app.Y,1)
                        uialert(app.scGEAppUIFigure,'Numbers of genes of Data 1 & 2 are expected to be the same.','Unequal Numbers of Genes');
                        app.ComBatJohnsonetal2007Button.Value=false;
                    else

                        [app.X,app.Y]=run_combat2(app.X,app.Y);
                        
%                         app.X=round(app.X);
%                         app.Y=round(app.Y);
%                         app.X(app.X<0)=0;
%                         app.Y(app.Y<0)=0;
                        
                        update_expr_tables(app);
                        % dbk=dbstack; helpdlg([dbk(1).name '......done.']);
                        % helpdlg('........Done.');
                        uialert(app.scGEAppUIFigure,'ComBat is done.','Confirm Batch Correction','Icon','success');
                    end
            else
                app.ComBatJohnsonetal2007Button.Value=true;
            end
            end
        end

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
               app.TabGroup.SelectedTab=app.NormalizationTab;
                    TabGroupSelectionChanged(app,event);                
        end

        % Button pushed function: NextButton_4
        function NextButton_4Pushed(app, event)
                    app.TabGroup.SelectedTab=app.ImputationTab;
                    TabGroupSelectionChanged(app,event);                   
        end

        % Button pushed function: BackButton_4
        function BackButton_4Pushed(app, event)
                  app.TabGroup.SelectedTab=app.BatchCorrectionTab;
                    TabGroupSelectionChanged(app,event);                   
        end

        % Button pushed function: NextButton_5
        function NextButton_5Pushed(app, event)
                    app.TabGroup.SelectedTab=app.FeatureSelectionTab;
                    TabGroupSelectionChanged(app,event);                        
        end

        % Button pushed function: BackButton_5
        function BackButton_5Pushed(app, event)
                    app.TabGroup.SelectedTab=app.ImputationTab;
                    TabGroupSelectionChanged(app,event);                   
        end

        % Button pushed function: NextButton_6
        function NextButton_6Pushed(app, event)
                    app.TabGroup.SelectedTab=app.VisualizationTab;
                    TabGroupSelectionChanged(app,event);                                      
        end

        % Button pushed function: BackButton_6
        function BackButton_6Pushed(app, event)
                 app.TabGroup.SelectedTab=app.FeatureSelectionTab;
                 TabGroupSelectionChanged(app,event);                  
        end

        % Button pushed function: SaveData2AsButton
        function SaveData2AsButtonPushed(app, event)
            if ~isempty(app.Y)
            t=table();
                t.genes=string(app.genelisty);
                t=[t,array2table(app.Y)];
                [file,path] = uiputfile('data_2.txt');
                figure(app.scGEAppUIFigure);                
                if file~=0
                    writetable(t,fullfile(path,file),'Delimiter','\t');
                end
            end                    
        end

        % Button pushed function: SaveTableAsButton
        function SaveTableAsButtonPushed(app, event)
            if ~isempty(app.T)
            [file,path] = uiputfile('gene_feature_table.txt');
            figure(app.scGEAppUIFigure);
            if file~=0
                writetable(app.T,fullfile(path,file),'Delimiter','\t');
            end
            end
        end

        % Value changed function: HCPMostafavietal2013Button
        function HCPMostafavietal2013ButtonValueChanged(app, event)
            if ~isempty(app.X) && ~isempty(app.Y)
            value = app.HCPMostafavietal2013Button.Value;
            if value
                    if size(app.X,1)~=size(app.Y,1)
                        uialert(app.scGEAppUIFigure,...
                            'Numbers of genes of Data 1 & 2 are expected to be the same.','Unequal Numbers of Genes');
                        app.HCPMostafavietal2013Button.Value=false;
                    else
                        [app.X,app.Y]=run_hcp(app.X,app.Y);
                        update_expr_tables(app);
                    end
                    % dbk=dbstack; helpdlg([dbk(1).name '......done.']);                
            else
                app.HCPMostafavietal2013Button.Value=true;
            end
            end
        end

        % Button pushed function: HVGBrenneckeetal2013Button
        function HVGBrenneckeetal2013ButtonPushed(app, event)
            plotit=false;
            if app.Data1Button_Fea.Value
                if ~isempty(app.X)
                    if app.ShowFitCurveCheckBox_2.Value
                        plotit=true;
                        figure;
                    end
                    [app.T]=sc_hvg(app.X,app.genelistx,true,plotit);
                    app.UITableRes.Data=app.T;
                    app.UITableRes.ColumnName=app.T.Properties.VariableNames;                    
                    app.TabGroup2.SelectedTab=app.ResultTab;
                    
                end                
            elseif app.Data2Button_Fea.Value                
                if ~isempty(app.Y)
                    if app.ShowFitCurveCheckBox_2.Value
                        plotit=true;
                        figure;
                    end                    
                    [app.T]=sc_hvg(app.Y,app.genelisty,true,plotit);
                    app.UITableRes.Data=app.T;
                    app.UITableRes.ColumnName=app.T.Properties.VariableNames;
                    app.TabGroup2.SelectedTab=app.ResultTab;
                    
                end
            elseif app.Data12Button_Fea.Value
                uialert(app.scGEAppUIFigure,'This function is for single data only. Select Data 1 or 2.','Function for single data');
            end            
        end

        % Button pushed function: DiffusionMapButton
        function DiffusionMapButtonPushed(app, event)
            if app.Data1Button_Vis.Value
                [s]=sc_diffuse(app.X);
                figure; scatter(s(:,1),s(:,2)); xlabel('C\_1'); ylabel('C\_2');
                title('Data 1, embedding with diffusion coordinates');
            elseif app.Data2Button_Vis.Value
                [s]=sc_diffuse(app.Y);              
                figure; scatter(s(:,1),s(:,2)); xlabel('C\_1'); ylabel('C\_2');
                title('Data 2, embedding with diffusion coordinates');               
            elseif app.Data12Button_Vis.Value
                if any(app.genelistx~=app.genelisty), return; end
                data=[app.X app.Y];
                indx=[zeros(size(app.X,2),1); ones(size(app.Y,2),1)];
                [s]=sc_diffuse(data);
                figure; 
                % gscatter(s(:,1),s(:,2),indx);
                scatter(s(indx==0,1),s(indx==0,2));
                hold on
                scatter(s(indx==1,1),s(indx==1,2));                
                xlabel('C\_1'); ylabel('C\_2');
                legend({'Data 1','Data 2'});
                title('Data 1 & 2, embedding with diffusion coordinates');
            end            
        end

        % Button pushed function: RemoveMTgenesButton
        function RemoveMTgenesButtonPushed(app, event)
                if ~isempty(app.X) && ~isempty(app.genelistx)
                     i=startsWith(app.genelistx,'MT-');
                     app.genelistx(i)=[];
                     app.X(i,:)=[];
                     update_expr_tables(app,1);
                end
                if ~isempty(app.Y) && ~isempty(app.genelisty)    
                     i=startsWith(app.genelisty,'MT-');
                     app.genelisty(i)=[];
                     app.Y(i,:)=[];
                     update_expr_tables(app,2);
                end                            
        end

        % Button pushed function: Demo1Button
        function Demo1ButtonPushed(app, event)
            web('demo_script1.html');
        end

        % Button pushed function: Demo2Button
        function Demo2ButtonPushed(app, event)
            web('demo_script2.html');
        end

        % Button pushed function: Demo3Button
        function Demo3ButtonPushed(app, event)
            web('demo_script3.html');
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            if ~isempty(app.FileNameEditField.Value)
                fn=app.FileNameEditField.Value;
                preview_data(app,fn);
            end

        end

        % Button pushed function: PreviewButton_2
        function PreviewButton_2Pushed(app, event)
            if ~isempty(app.FileNameEditField_2.Value)
                fn=app.FileNameEditField_2.Value;
                preview_data(app,fn);
            end
        end

        % Button pushed function: RunGOrillaButton
        function RunGOrillaButtonPushed(app, event)
            if ~isempty(app.UITableRes.Data) && ~isempty(app.T)
                run_gorilla(app.T.genes);
            else
                run_gorilla;
            end
        end

        % Button pushed function: PHATE3DButton
        function PHATE3DButtonPushed(app, event)
            answer = uiconfirm(app.scGEAppUIFigure,'This function takes unnormalized input data. Click OK to continue.',...
                'Confirm Input Data','Icon','info');
            switch answer
                case 'OK'
                case 'Cancel'
                    return;
            end            
            if app.Data1Button_Vis.Value
                d = uiprogressdlg(app.scGEAppUIFigure,'Title','Please Wait',...
                    'Message','Loading Data 1'); pause(.5)
                d.Value = .33;  d.Message = 'Processing Data 1'; pause(.5)                
                f=figure('visible','off');
                run_phate(app.X,3,false,true);
                d.Value = .67;  d.Message = 'Processing Data 1'; pause(1)
                d.Value = 1; d.Message = 'Finishing'; pause(1); close(d);
                set(f,'visible','on')
            elseif app.Data2Button_Vis.Value
                d = uiprogressdlg(app.scGEAppUIFigure,'Title','Please Wait',...
                    'Message','Loading Data 2'); pause(.5)
                d.Value = .33;  d.Message = 'Processing Data 2'; pause(.5)                
                f=figure('visible','off');
                run_phate(app.Y,3,false,true);
                d.Value = .67;  d.Message = 'Processing Data 2'; pause(1)
                d.Value = 1; d.Message = 'Finishing'; pause(1); close(d);
                set(f,'visible','on')
            elseif app.Data12Button_Vis.Value
                d = uiprogressdlg(app.scGEAppUIFigure,'Title','Please Wait',...
                    'Message','Loading Data'); pause(.5)
                d.Value = .33;  d.Message = 'Processing Data 1'; pause(.5)                
                f1=figure('visible','off');
                run_phate(app.X,3,false,true);
                d.Value = .67;  d.Message = 'Processing Data 2'; pause(1)
                f2=figure('visible','off');
                run_phate(app.Y,3,false,true);
                d.Value = 1; d.Message = 'Finishing'; pause(1); close(d);                
                set(f1,'visible','on')
                set(f2,'visible','on')                
            end             
        end

        % Button pushed function: CombinedEmbeddingButton
        function CombinedEmbeddingButtonPushed(app, event)
            answer = uiconfirm(app.scGEAppUIFigure,'This function takes unnormalized input data. Click OK to continue...',...
                'Confirm Input Data','Icon','info');
            switch answer
                case 'OK'
                case 'Cancel'
                    return;
            end            
            if app.Data1Button_Vis.Value
                d = uiprogressdlg(app.scGEAppUIFigure,'Title','Please Wait',...
                    'Message','Processing Data 1','Indeterminate','on'); pause(.5)
                f=figure('visible','off');
                sc_embed4w(app.X,true,true,app.genelistx,'mean');
                close(d);
                set(f,'visible','on')
            elseif app.Data2Button_Vis.Value
                d = uiprogressdlg(app.scGEAppUIFigure,'Title','Please Wait',...
                    'Message','Processing Data 2','Indeterminate','on'); pause(.5)
                f=figure('visible','off');
                sc_embed4w(app.Y,true,true,app.genelisty,'mean');
                close(d);
                set(f,'visible','on')
            elseif app.Data12Button_Vis.Value
                uialert(app.scGEAppUIFigure,'This function is for single data only. Select Data 1 or 2.','Function for single data');
            end
        end

        % Value changed function: sqrtxButton
        function sqrtxButtonValueChanged(app, event)
            value = app.sqrtxButton.Value;
            app.logx1Button.Enable=false;
            if value
                if ~isempty(app.X)
                    app.X=sqrt(app.X);
                    update_expr_tables(app,1);
                end
                if ~isempty(app.Y)
                    app.Y=sqrt(app.Y);
                    update_expr_tables(app,2);
                end
                app.DESeqNormalizationButton.Enable=false;                
            else
                app.sqrtxButton.Value=true;                               
            end            
        end

        % Button pushed function: VEGChenetal2016Button
        function VEGChenetal2016ButtonPushed(app, event)
            plotit=false;
            if app.Data1Button_Fea.Value
                if ~isempty(app.X)
                    if app.ShowFitCurveCheckBox_2.Value
                        plotit=true;
                        figure;
                    end
                    [app.T,mdl]=sc_veg(app.X,app.genelistx,true,plotit);
                    app.UITableRes.Data=app.T;
                    app.UITableRes.ColumnName=app.T.Properties.VariableNames;                    
                    app.TabGroup2.SelectedTab=app.ResultTab;
                    mdl
                end                
            elseif app.Data2Button_Fea.Value                
                if ~isempty(app.Y)
                    if app.ShowFitCurveCheckBox_2.Value
                        plotit=true;
                        figure;
                    end                    
                    [app.T,mdl]=sc_veg(app.Y,app.genelisty,true,plotit);
                    app.UITableRes.Data=app.T;
                    app.UITableRes.ColumnName=app.T.Properties.VariableNames;
                    app.TabGroup2.SelectedTab=app.ResultTab;
                    mdl
                end
            elseif app.Data12Button_Fea.Value
                uialert(app.scGEAppUIFigure,'This function is for single data only. Select Data 1 or 2.','Function for single data');
            end            
            
        end

        % Value changed function: mcImputeMongiaetal2019Button
        function mcImputeMongiaetal2019ButtonValueChanged(app, event)
            value = app.mcImputeMongiaetal2019Button.Value;
            if value
                if ~isempty(app.X)
                    if ~isnormalized(app.X)                    
                        answer = uiconfirm(app.scGEAppUIFigure,...
                               'It is recommended to normalize data first. Do you want to continue with raw data?',...
                               'Normalized Data','Icon','warning');
                        switch answer
                            case 'Cancel'
                                app.mcImputeMongiaetal2019Button.Value=false;
                                return;
                            case 'OK'                           
                                % continue 
                        end
                    end                    
                    d = uiprogressdlg(app.scGEAppUIFigure,'Title','Please Wait',...
                        'Message','Loading Data 1'); pause(.5)
                    d.Value = .33;  d.Message = 'Processing Data 1'; pause(.5)
                    [app.X]=run_mcimpute(app.X,false);
                    d.Value = .67;  d.Message = 'Processing Data 1'; pause(1)
                    d.Value = 1; d.Message = 'Finishing'; pause(1); close(d);
                    update_expr_tables(app,1);
                 end
                 if ~isempty(app.Y)
                    if ~isnormalized(app.Y)
                        answer = uiconfirm(app.scGEAppUIFigure,...
                               'It is recommended to normalize data first. Do you want to continue with raw data?',...
                               'Normalized Data','Icon','warning');
                        switch answer
                            case 'Cancel'
                                app.mcImputeMongiaetal2019Button.Value=false;
                                return;
                            case 'OK'                      
                                % continue 
                        end
                    end
                    d = uiprogressdlg(app.scGEAppUIFigure,'Title','Please Wait',...
                        'Message','Loading Data 2'); pause(.5)
                    d.Value = .33;  d.Message = 'Processing Data 2'; pause(.5)
                    [app.Y]=run_mcimpute(app.Y,false);
                    d.Value = .67;  d.Message = 'Processing Data 2'; pause(1)
                    d.Value = 1; d.Message = 'Finishing'; pause(1)
                    close(d);
                    update_expr_tables(app,2);
                 end
            else
                app.mcImputeMongiaetal2019Button.Value=true;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.scGEAppUIFigure = uifigure('Visible', 'off');
            app.scGEAppUIFigure.Name = 'scGEApp';
            app.TabGroup = uitabgroup(app.scGEAppUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.Data1Panel = uipanel(app.LoadDataTab);
            app.Data1Panel.Title = 'Data 1';
            app.FileNameEditFieldLabel = uilabel(app.Data1Panel);
            app.FileNameEditFieldLabel.Text = 'File Name:';
            app.FileNameEditField = uieditfield(app.Data1Panel, 'text');
            app.SelectFileButton = uibutton(app.Data1Panel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File...';
            app.LoadButton = uibutton(app.Data1Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PreviewButton = uibutton(app.Data1Panel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview...';
            app.Data2Panel = uipanel(app.LoadDataTab);
            app.Data2Panel.Title = 'Data 2';
            app.FileNameEditField_2Label = uilabel(app.Data2Panel);
            app.FileNameEditField_2Label.Text = 'File Name:';
            app.FileNameEditField_2 = uieditfield(app.Data2Panel, 'text');
            app.SelectFileButton_2 = uibutton(app.Data2Panel, 'push');
            app.SelectFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButton_2Pushed, true);
            app.SelectFileButton_2.Enable = 'off';
            app.SelectFileButton_2.Text = 'Select File...';
            app.LoadButton_2 = uibutton(app.Data2Panel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Enable = 'off';
            app.LoadButton_2.Text = 'Load';
            app.PreviewButton_2 = uibutton(app.Data2Panel, 'push');
            app.PreviewButton_2.ButtonPushedFcn = createCallbackFcn(app, @PreviewButton_2Pushed, true);
            app.PreviewButton_2.Enable = 'off';
            app.PreviewButton_2.Text = 'Preview...';
            app.NextButton_LoadData = uibutton(app.LoadDataTab, 'push');
            app.NextButton_LoadData.ButtonPushedFcn = createCallbackFcn(app, @NextButton_LoadDataPushed, true);
            app.NextButton_LoadData.Text = 'Next >>';
            app.DataSourceButtonGroup = uibuttongroup(app.LoadDataTab);
            app.DataSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataSourceButtonGroupSelectionChanged, true);
            app.DataSourceButtonGroup.Title = 'Data Source:';
            app.YourOwnButton = uiradiobutton(app.DataSourceButtonGroup);
            app.YourOwnButton.Text = 'Your Own';
            app.YourOwnButton.Value = true;
            app.Example1DataButton = uiradiobutton(app.DataSourceButtonGroup);
            app.Example1DataButton.Text = 'Example 1 Data';
            app.Example2DataButton = uiradiobutton(app.DataSourceButtonGroup);
            app.Example2DataButton.Text = 'Example 2 Data';
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.NextButton_Filter = uibutton(app.FilterTab, 'push');
            app.NextButton_Filter.ButtonPushedFcn = createCallbackFcn(app, @NextButton_FilterPushed, true);
            app.NextButton_Filter.Text = 'Next >>';
            app.BackButton_Filter = uibutton(app.FilterTab, 'push');
            app.BackButton_Filter.ButtonPushedFcn = createCallbackFcn(app, @BackButton_FilterPushed, true);
            app.BackButton_Filter.Text = '<< Back';
            app.Filter1Panel = uipanel(app.FilterTab);
            app.Filter1Panel.Title = 'Filter 1';
            app.FilterGenesButton = uibutton(app.Filter1Panel, 'push');
            app.FilterGenesButton.ButtonPushedFcn = createCallbackFcn(app, @FilterGenesButtonPushed, true);
            app.FilterGenesButton.Text = 'Filter Genes';
            app.FilterCellsButton = uibutton(app.Filter1Panel, 'push');
            app.FilterCellsButton.ButtonPushedFcn = createCallbackFcn(app, @FilterCellsButtonPushed, true);
            app.FilterCellsButton.Text = 'Filter Cells';
            app.MinimalofnonzerospergeneSpinnerLabel = uilabel(app.Filter1Panel);
            app.MinimalofnonzerospergeneSpinnerLabel.Text = 'Minimal # of nonzeros (per gene)';
            app.MinimalofnonzerospergeneSpinner = uispinner(app.Filter1Panel);
            app.MinimalofnonzerospergeneSpinner.Limits = [0 Inf];
            app.MinimalofnonzerospergeneSpinner.Value = 1;
            app.MinimalofnonzerospercellSpinnerLabel = uilabel(app.Filter1Panel);
            app.MinimalofnonzerospercellSpinnerLabel.Text = 'Minimal # of nonzeros (per cell)';
            app.MinimalofnonzerospercellSpinner = uispinner(app.Filter1Panel);
            app.MinimalofnonzerospercellSpinner.Limits = [0 Inf];
            app.MinimalofnonzerospercellSpinner.Value = 10;
            app.Filter2Panel = uipanel(app.FilterTab);
            app.Filter2Panel.Title = 'Filter 2';
            app.SelectGenesButton = uibutton(app.Filter2Panel, 'push');
            app.SelectGenesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGenesButtonPushed, true);
            app.SelectGenesButton.Text = 'Select Genes';
            app.cellshavingSpinnerLabel = uilabel(app.Filter2Panel);
            app.cellshavingSpinnerLabel.Text = 'cells having >=';
            app.cellshavingSpinner = uispinner(app.Filter2Panel);
            app.cellshavingSpinner.Limits = [0 Inf];
            app.cellshavingSpinner.Value = 2;
            app.readspercellLabel = uilabel(app.Filter2Panel);
            app.readspercellLabel.Text = 'read(s) per cell.';
            app.readspercellSpinner = uispinner(app.Filter2Panel);
            app.readspercellSpinner.Limits = [0 Inf];
            app.readspercellSpinner.Value = 1;
            app.withatleastLabel = uilabel(app.Filter2Panel);
            app.withatleastLabel.Text = 'with at least';
            app.RemoveMTgenesButton = uibutton(app.FilterTab, 'push');
            app.RemoveMTgenesButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveMTgenesButtonPushed, true);
            app.RemoveMTgenesButton.Tooltip = {'Remove genes encoded in the mitochondrial genome'};
            app.RemoveMTgenesButton.Text = 'Remove MT-genes';
            app.NormalizationTab = uitab(app.TabGroup);
            app.NormalizationTab.Title = 'Normalization';
            app.NextButton_3 = uibutton(app.NormalizationTab, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next >>';
            app.BackButton_2 = uibutton(app.NormalizationTab, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = '<< Back';
            app.Method1Panel = uipanel(app.NormalizationTab);
            app.Method1Panel.Title = 'Method 1';
            app.logx1Button = uibutton(app.Method1Panel, 'state');
            app.logx1Button.ValueChangedFcn = createCallbackFcn(app, @logx1ButtonValueChanged2, true);
            app.logx1Button.Text = 'log(x+1) ';
            app.LibrarySizeNormalizationButton = uibutton(app.Method1Panel, 'state');
            app.LibrarySizeNormalizationButton.ValueChangedFcn = createCallbackFcn(app, @LibrarySizeNormalizationButtonValueChanged, true);
            app.LibrarySizeNormalizationButton.Text = 'Library Size Normalization';
            app.Method2Panel = uipanel(app.NormalizationTab);
            app.Method2Panel.Title = 'Method 2';
            app.DESeqNormalizationButton = uibutton(app.Method2Panel, 'state');
            app.DESeqNormalizationButton.ValueChangedFcn = createCallbackFcn(app, @DESeqNormalizationButtonValueChanged, true);
            app.DESeqNormalizationButton.Text = 'DESeq Normalization';
            app.sqrtxButton = uibutton(app.NormalizationTab, 'state');
            app.sqrtxButton.ValueChangedFcn = createCallbackFcn(app, @sqrtxButtonValueChanged, true);
            app.sqrtxButton.Text = 'sqrt(x)';
            app.BatchCorrectionTab = uitab(app.TabGroup);
            app.BatchCorrectionTab.Title = 'Batch Correction';
            app.GeneIntersectionofData12Button = uibutton(app.BatchCorrectionTab, 'push');
            app.GeneIntersectionofData12Button.ButtonPushedFcn = createCallbackFcn(app, @GeneIntersectionofData12ButtonPushed, true);
            app.GeneIntersectionofData12Button.Text = 'Gene Intersection of Data 1 & 2';
            app.BackButton_3 = uibutton(app.BatchCorrectionTab, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = '<< Back';
            app.NextButton_4 = uibutton(app.BatchCorrectionTab, 'push');
            app.NextButton_4.ButtonPushedFcn = createCallbackFcn(app, @NextButton_4Pushed, true);
            app.NextButton_4.Text = 'Next >>';
            app.DataSelectionButtonGroup_Bat = uibuttongroup(app.BatchCorrectionTab);
            app.DataSelectionButtonGroup_Bat.Title = 'Data Selection';
            app.Data1Button_Bat = uiradiobutton(app.DataSelectionButtonGroup_Bat);
            app.Data1Button_Bat.Enable = 'off';
            app.Data1Button_Bat.Text = 'Data 1';
            app.Data2Button_Bat = uiradiobutton(app.DataSelectionButtonGroup_Bat);
            app.Data2Button_Bat.Enable = 'off';
            app.Data2Button_Bat.Text = 'Data 2';
            app.Data12Button_Bat = uiradiobutton(app.DataSelectionButtonGroup_Bat);
            app.Data12Button_Bat.Text = 'Data 1 & 2';
            app.Data12Button_Bat.Value = true;
            app.Method1Panel_2 = uipanel(app.BatchCorrectionTab);
            app.Method1Panel_2.Title = 'Method 1';
            app.ComBatJohnsonetal2007Button = uibutton(app.Method1Panel_2, 'state');
            app.ComBatJohnsonetal2007Button.ValueChangedFcn = createCallbackFcn(app, @ComBatJohnsonetal2007ButtonValueChanged, true);
            app.ComBatJohnsonetal2007Button.Text = 'ComBat (Johnson et al. 2007)';
            app.Method2Panel_2 = uipanel(app.BatchCorrectionTab);
            app.Method2Panel_2.Title = 'Method 2';
            app.HCPMostafavietal2013Button = uibutton(app.Method2Panel_2, 'state');
            app.HCPMostafavietal2013Button.ValueChangedFcn = createCallbackFcn(app, @HCPMostafavietal2013ButtonValueChanged, true);
            app.HCPMostafavietal2013Button.Text = 'HCP (Mostafavi et al. 2013)';
            app.ImputationTab = uitab(app.TabGroup);
            app.ImputationTab.Title = 'Imputation';
            app.BackButton_4 = uibutton(app.ImputationTab, 'push');
            app.BackButton_4.ButtonPushedFcn = createCallbackFcn(app, @BackButton_4Pushed, true);
            app.BackButton_4.Text = '<< Back';
            app.NextButton_5 = uibutton(app.ImputationTab, 'push');
            app.NextButton_5.ButtonPushedFcn = createCallbackFcn(app, @NextButton_5Pushed, true);
            app.NextButton_5.Text = 'Next >>';
            app.DataSelectionButtonGroup_Imp = uibuttongroup(app.ImputationTab);
            app.DataSelectionButtonGroup_Imp.Title = 'Data Selection';
            app.Data1Button_Imp = uiradiobutton(app.DataSelectionButtonGroup_Imp);
            app.Data1Button_Imp.Text = 'Data 1';
            app.Data1Button_Imp.Value = true;
            app.Data2Button_Imp = uiradiobutton(app.DataSelectionButtonGroup_Imp);
            app.Data2Button_Imp.Text = 'Data 2';
            app.Data12Button_Imp = uiradiobutton(app.DataSelectionButtonGroup_Imp);
            app.Data12Button_Imp.Text = 'Data 1 & 2 ';
            app.Method1Panel_3 = uipanel(app.ImputationTab);
            app.Method1Panel_3.Title = 'Method 1';
            app.MAGICvanDijketal2018Button = uibutton(app.Method1Panel_3, 'state');
            app.MAGICvanDijketal2018Button.ValueChangedFcn = createCallbackFcn(app, @MAGICvanDijketal2018ButtonValueChanged, true);
            app.MAGICvanDijketal2018Button.Text = 'MAGIC (van Dijk et al. 2018)';
            app.Method2Panel_3 = uipanel(app.ImputationTab);
            app.Method2Panel_3.Title = 'Method 2';
            app.mcImputeMongiaetal2019Button = uibutton(app.Method2Panel_3, 'state');
            app.mcImputeMongiaetal2019Button.ValueChangedFcn = createCallbackFcn(app, @mcImputeMongiaetal2019ButtonValueChanged, true);
            app.mcImputeMongiaetal2019Button.Text = 'mcImpute (Mongia et al. 2019)';
            app.FeatureSelectionTab = uitab(app.TabGroup);
            app.FeatureSelectionTab.Title = 'Feature Selection';
            app.DataSelectionButtonGroup_Fea = uibuttongroup(app.FeatureSelectionTab);
            app.DataSelectionButtonGroup_Fea.Title = 'Data Selection';
            app.Data1Button_Fea = uiradiobutton(app.DataSelectionButtonGroup_Fea);
            app.Data1Button_Fea.Text = 'Data 1';
            app.Data1Button_Fea.Value = true;
            app.Data2Button_Fea = uiradiobutton(app.DataSelectionButtonGroup_Fea);
            app.Data2Button_Fea.Text = 'Data 2';
            app.Data12Button_Fea = uiradiobutton(app.DataSelectionButtonGroup_Fea);
            app.Data12Button_Fea.Text = 'Data 1 & 2 ';
            app.BackButton_5 = uibutton(app.FeatureSelectionTab, 'push');
            app.BackButton_5.ButtonPushedFcn = createCallbackFcn(app, @BackButton_5Pushed, true);
            app.BackButton_5.Text = '<< Back';
            app.NextButton_6 = uibutton(app.FeatureSelectionTab, 'push');
            app.NextButton_6.ButtonPushedFcn = createCallbackFcn(app, @NextButton_6Pushed, true);
            app.NextButton_6.Text = 'Next >>';
            app.SplineFitbasedFeatureSelectionPanel = uipanel(app.FeatureSelectionTab);
            app.SplineFitbasedFeatureSelectionPanel.Title = 'Spline Fit-based Feature Selection';
            app.MeanCVDropout3DSplineFitButton = uibutton(app.SplineFitbasedFeatureSelectionPanel, 'push');
            app.MeanCVDropout3DSplineFitButton.ButtonPushedFcn = createCallbackFcn(app, @MeanCVDropout3DSplineFitButtonPushed, true);
            app.MeanCVDropout3DSplineFitButton.Text = 'Mean-CV-Dropout 3D Spline Fit';
            app.ShowFitCurveCheckBox = uicheckbox(app.SplineFitbasedFeatureSelectionPanel);
            app.ShowFitCurveCheckBox.Text = 'Show Fit Curve';
            app.HighlyVariableGeneHVGSelectionPanel = uipanel(app.FeatureSelectionTab);
            app.HighlyVariableGeneHVGSelectionPanel.Title = 'Highly Variable Gene (HVG) Selection';
            app.HVGBrenneckeetal2013Button = uibutton(app.HighlyVariableGeneHVGSelectionPanel, 'push');
            app.HVGBrenneckeetal2013Button.ButtonPushedFcn = createCallbackFcn(app, @HVGBrenneckeetal2013ButtonPushed, true);
            app.HVGBrenneckeetal2013Button.Text = 'HVG (Brennecke et al. 2013)';
            app.ShowFitCurveCheckBox_2 = uicheckbox(app.HighlyVariableGeneHVGSelectionPanel);
            app.ShowFitCurveCheckBox_2.Text = 'Show Fit Curve';
            app.VEGChenetal2016Button = uibutton(app.HighlyVariableGeneHVGSelectionPanel, 'push');
            app.VEGChenetal2016Button.ButtonPushedFcn = createCallbackFcn(app, @VEGChenetal2016ButtonPushed, true);
            app.VEGChenetal2016Button.Text = 'VEG (Chen et al. 2016)';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.DataSelectionButtonGroup_Vis = uibuttongroup(app.VisualizationTab);
            app.DataSelectionButtonGroup_Vis.Title = 'Data Selection';
            app.Data1Button_Vis = uiradiobutton(app.DataSelectionButtonGroup_Vis);
            app.Data1Button_Vis.Text = 'Data 1';
            app.Data1Button_Vis.Value = true;
            app.Data2Button_Vis = uiradiobutton(app.DataSelectionButtonGroup_Vis);
            app.Data2Button_Vis.Text = 'Data 2';
            app.Data12Button_Vis = uiradiobutton(app.DataSelectionButtonGroup_Vis);
            app.Data12Button_Vis.Text = 'Data 1 & 2';
            app.AllCellsPanel = uipanel(app.VisualizationTab);
            app.AllCellsPanel.Title = 'All Cells';
            app.PCAButton = uibutton(app.AllCellsPanel, 'push');
            app.PCAButton.ButtonPushedFcn = createCallbackFcn(app, @PCAButtonPushed, true);
            app.PCAButton.Text = 'PCA...';
            app.DiffusionMapButton = uibutton(app.AllCellsPanel, 'push');
            app.DiffusionMapButton.ButtonPushedFcn = createCallbackFcn(app, @DiffusionMapButtonPushed, true);
            app.DiffusionMapButton.Text = 'Diffusion Map...';
            app.tSNEButton = uibutton(app.AllCellsPanel, 'push');
            app.tSNEButton.ButtonPushedFcn = createCallbackFcn(app, @tSNEButtonPushed, true);
            app.tSNEButton.Text = 't-SNE...';
            app.PHATE3DButton = uibutton(app.AllCellsPanel, 'push');
            app.PHATE3DButton.ButtonPushedFcn = createCallbackFcn(app, @PHATE3DButtonPushed, true);
            app.PHATE3DButton.Text = 'PHATE 3D...';
            app.AllGenesPanel = uipanel(app.VisualizationTab);
            app.AllGenesPanel.Title = 'All Genes';
            app.MeanCVDropout3DScatterButton = uibutton(app.AllGenesPanel, 'push');
            app.MeanCVDropout3DScatterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanCVDropout3DScatterButtonPushed, true);
            app.MeanCVDropout3DScatterButton.Text = 'Mean-CV-Dropout 3D Scatter...';
            app.MeanDropout2DScatterButton = uibutton(app.AllGenesPanel, 'push');
            app.MeanDropout2DScatterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanDropout2DScatterButtonPushed, true);
            app.MeanDropout2DScatterButton.Text = 'Mean-Dropout 2D Scatter...';
            app.MeanCV2DScatterButton = uibutton(app.AllGenesPanel, 'push');
            app.MeanCV2DScatterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanCV2DScatterButtonPushed, true);
            app.MeanCV2DScatterButton.Text = 'Mean-CV 2D Scatter...';
            app.MeanlogVarlog2DScatterButton = uibutton(app.AllGenesPanel, 'push');
            app.MeanlogVarlog2DScatterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanlogVarlog2DScatterButtonPushed, true);
            app.MeanlogVarlog2DScatterButton.Text = 'Mean(log)-Var(log) 2D Scatter...';
            app.CombinedEmbeddingButton = uibutton(app.AllGenesPanel, 'push');
            app.CombinedEmbeddingButton.ButtonPushedFcn = createCallbackFcn(app, @CombinedEmbeddingButtonPushed, true);
            app.CombinedEmbeddingButton.Text = 'Combined Embedding...';
            app.FeaturedGenesPanel = uipanel(app.VisualizationTab);
            app.FeaturedGenesPanel.Title = 'Featured Genes';
            app.ExpressionLandscape3DStemButton = uibutton(app.FeaturedGenesPanel, 'push');
            app.ExpressionLandscape3DStemButton.ButtonPushedFcn = createCallbackFcn(app, @ExpressionLandscape3DStemButtonPushed, true);
            app.ExpressionLandscape3DStemButton.Text = 'Expression Landscape (3D Stem)...';
            app.BackButton_6 = uibutton(app.VisualizationTab, 'push');
            app.BackButton_6.ButtonPushedFcn = createCallbackFcn(app, @BackButton_6Pushed, true);
            app.BackButton_6.Text = '<< Back';
            app.TabGroup2 = uitabgroup(app.scGEAppUIFigure);
            app.DataTab = uitab(app.TabGroup2);
            app.DataTab.Title = 'Data';
            app.UITableData1 = uitable(app.DataTab);
            app.UITableData1.ColumnName = {'Genes'; 'Cell 1'; 'Cell 2'; 'Cell 3'};
            app.UITableData1.RowName = {};
            app.UITableData2 = uitable(app.DataTab);
            app.UITableData2.ColumnName = {'Genes'; 'Cell 1'; 'Cell 2'; 'Cell 3'};
            app.UITableData2.RowName = {};
            app.AssignData1IntoWorkspaceButton = uibutton(app.DataTab, 'push');
            app.AssignData1IntoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @AssignData1IntoWorkspaceButtonPushed, true);
            app.AssignData1IntoWorkspaceButton.Enable = 'off';
            app.AssignData1IntoWorkspaceButton.Text = 'Assign Data 1 Into Workspace...';
            app.SaveData1AsButton = uibutton(app.DataTab, 'push');
            app.SaveData1AsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveData1AsButtonPushed, true);
            app.SaveData1AsButton.Enable = 'off';
            app.SaveData1AsButton.Text = 'Save Data 1 As...';
            app.AssignData2IntoWorkspaceButton = uibutton(app.DataTab, 'push');
            app.AssignData2IntoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @AssignData2IntoWorkspaceButtonPushed, true);
            app.AssignData2IntoWorkspaceButton.Enable = 'off';
            app.AssignData2IntoWorkspaceButton.Text = 'Assign Data 2 Into Workspace...';
            app.SaveData2AsButton = uibutton(app.DataTab, 'push');
            app.SaveData2AsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveData2AsButtonPushed, true);
            app.SaveData2AsButton.Enable = 'off';
            app.SaveData2AsButton.Text = 'Save Data 2 As...';
            app.Data1NullLabel = uilabel(app.DataTab);
            app.Data1NullLabel.Text = 'Data 1: [Null]';
            app.Data2NullLabel = uilabel(app.DataTab);
            app.Data2NullLabel.Text = 'Data 2: [Null]';
            app.ResultTab = uitab(app.TabGroup2);
            app.ResultTab.Title = 'Result';
            app.UITableRes = uitable(app.ResultTab);
            app.UITableRes.ColumnName = {'Genes'; 'Log10_Mean'; 'Dropout_Rate'; 'Log10_CV'; 'Deviation_3D'};
            app.UITableRes.RowName = {};
            app.SaveTableAsButton = uibutton(app.ResultTab, 'push');
            app.SaveTableAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableAsButtonPushed, true);
            app.SaveTableAsButton.Text = 'Save Table As...';
            app.AssignTableIntoWorkspaceButton = uibutton(app.ResultTab, 'push');
            app.AssignTableIntoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @AssignTableIntoWorkspaceButtonPushed, true);
            app.AssignTableIntoWorkspaceButton.Text = 'Assign Table Into Workspace...';
            app.RunGSEAPrerankedButton = uibutton(app.ResultTab, 'push');
            app.RunGSEAPrerankedButton.ButtonPushedFcn = createCallbackFcn(app, @RunGSEAPrerankedButtonPushed, true);
            app.RunGSEAPrerankedButton.Text = 'Run GSEA (Preranked)...';
            app.RunGOrillaButton = uibutton(app.ResultTab, 'push');
            app.RunGOrillaButton.ButtonPushedFcn = createCallbackFcn(app, @RunGOrillaButtonPushed, true);
            app.RunGOrillaButton.Text = 'Run GOrilla...';
            app.AboutTab = uitab(app.TabGroup2);
            app.AboutTab.Title = 'About';
            app.ContributorsTextAreaLabel = uilabel(app.AboutTab);
            app.ContributorsTextAreaLabel.Text = 'Contributors:';
            app.ContributorsTextArea = uitextarea(app.AboutTab);
            app.ContributorsTextArea.Value = {'Jonas Lundgren <splinefit@gmail.com> --- SPLINEFIT.m'; 'Michal Marczyk <michal.marczyk@polsl.pl> --- GSEA'; 'Jean-Philippe Fortin <fortin946@gmail.com> --- ComBat'; 'Ann B Lee <annlee@stat.cmu.edu> --- DIFFUSE.m'; ''};
            app.AuthorLabel = uilabel(app.AboutTab);
            app.AuthorLabel.Text = 'Author:';
            app.AuthorTextArea = uitextarea(app.AboutTab);
            app.AuthorTextArea.Value = {'James Cai, Ph.D.'; 'Department of Veterinary Integrative Biosciences'; 'Department of Electrical & Computer Engineering'; 'Texas A&M University'; 'College Station, TX 77843-4458'; 'Tel (979) 458-5482'; 'jcai@tamu.edu'; ''};
            app.scGEApp2019SinglecellGeneExpressionAnalysisLabel = uilabel(app.AboutTab);
            app.scGEApp2019SinglecellGeneExpressionAnalysisLabel.Text = {'scGEApp (2019)'; 'Single-cell Gene Expression'; 'Analysis'};
            app.CommandlineFunctionsPanel = uipanel(app.AboutTab);
            app.CommandlineFunctionsPanel.Title = 'Commandline Functions';
            app.Demo1Button = uibutton(app.CommandlineFunctionsPanel, 'push');
            app.Demo1Button.ButtonPushedFcn = createCallbackFcn(app, @Demo1ButtonPushed, true);
            app.Demo1Button.Text = 'Demo 1...';
            app.Demo2Button = uibutton(app.CommandlineFunctionsPanel, 'push');
            app.Demo2Button.ButtonPushedFcn = createCallbackFcn(app, @Demo2ButtonPushed, true);
            app.Demo2Button.Text = 'Demo 2...';
            app.Demo3Button = uibutton(app.CommandlineFunctionsPanel, 'push');
            app.Demo3Button.ButtonPushedFcn = createCallbackFcn(app, @Demo3ButtonPushed, true);
            app.Demo3Button.Text = 'Demo 3...';
            % Show the figure after all components are created
            app.scGEAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scGEApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.scGEAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.scGEAppUIFigure)
        end
    end
end