classdef scGEAscatter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        scGEAscatterLabel  matlab.ui.control.Label
        SinglecellGeneExpressionAnalysisLabel  matlab.ui.control.Label
        VersionLabel       matlab.ui.control.Label
        StartButton        matlab.ui.control.Button
        UpdateButton       matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center");
            pw1=fileparts(mfilename('fullpath'));
            a=imread(fullfile(pw1,'resources','waiting.png'));
            app.UIAxes.XLabel.String='';
            app.UIAxes.YLabel.String='';
            app.UIAxes.Title.String='';
            % app.UIAxes.Visible=false;
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            I=imshow(a, 'parent', app.UIAxes,...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);   
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)]; 
            app.UIFigure.Visible = 'on';
            [needupdate,v1]=pkg.i_vercheck;
            % v1=strsplit(v1{1});
            app.VersionLabel.Text=v1{1};
            if ~needupdate
                app.UpdateButton.Visible=false;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            delete(app)
            sc_scatter;
        end

        % Close request function: UIFigure
        function CloseRequestFcn(app, event)
            delete(app)
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            answer = questdlg('There is a new version of scGEAToolbox. Learn how to install?');
            if strcmp(answer,'Yes')
                web('https://scgeatoolbox.readthedocs.io/en/latest/quick_installation.html');
            end           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.scGEAscatterLabel = uilabel(app.UIFigure);
            app.scGEAscatterLabel.Text = 'scGEAscatter';
            app.SinglecellGeneExpressionAnalysisLabel = uilabel(app.UIFigure);
            app.SinglecellGeneExpressionAnalysisLabel.Text = 'Single-cell Gene Expression Analysis';
            app.VersionLabel = uilabel(app.UIFigure);
            app.VersionLabel.Tag = 'vertag';
            app.VersionLabel.Text = 'scGEAToolbox v21.12.12';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start...';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update...';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scGEAscatter

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end