classdef scgeatoolApp < matlab.apps.AppBase
    %SCGEATOOLAPP App of scgeatool
    %   Single-cell Gene Expression Analysis Tool App

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ImportDataMenu                  matlab.ui.container.Menu
        ExportSaveDataMenu              matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        FilterGenesCellsMenu            matlab.ui.container.Menu
        AddBrushedCellstoaNewGroupMenu  matlab.ui.container.Menu
        MergeBrushedCellstoSameGroupMenu  matlab.ui.container.Menu
        RenameCellTypeorBatchIDMenu     matlab.ui.container.Menu
        MergeSCEDataVariablesinWorkspaceMenu  matlab.ui.container.Menu
        MergeSCEDataFilesMenu           matlab.ui.container.Menu
        AddEditCellAttributesMenu       matlab.ui.container.Menu
        ExportCellAttributeTableMenu    matlab.ui.container.Menu
        ExtractCellsbyMarkerExpressionMenu  matlab.ui.container.Menu
        MergeSubclustersofSameCellTypeMenu  matlab.ui.container.Menu
        SelectGenestoWorkonMenu         matlab.ui.container.Menu
        SelectHighlyVariableGenesHVGstoWorkonMenu  matlab.ui.container.Menu
        SelectLigandReceptorGenestoWorkonMenu  matlab.ui.container.Menu
        Subsample50CellstoWorkonMenu    matlab.ui.container.Menu
        DeleteBrushedCellsMenu          matlab.ui.container.Menu
        DeleteUnbrushedCellsMenu        matlab.ui.container.Menu
        SelectCellsbyClassOpeninNewWindowMenu  matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        EmbedCellsUsingtSNEUMPPHATEMenu  matlab.ui.container.Menu
        SwitchBetween2D3DEmbeddingsMenu  matlab.ui.container.Menu
        ClusterCellsUsingCellEmbeddingSMenu  matlab.ui.container.Menu
        ClusterCellsUsingExpressionMatrixXMenu  matlab.ui.container.Menu
        ShowGeneExpressionMenu          matlab.ui.container.Menu
        SelectedGenesMenu               matlab.ui.container.Menu
        MitochondrialMtGenesMenu        matlab.ui.container.Menu
        HemoglobinHgbGenesMenu          matlab.ui.container.Menu
        ShowCellStatesMenu              matlab.ui.container.Menu
        LabelCellGroupsMenu             matlab.ui.container.Menu
        ShowCellClustersGroupsIndividuallyMenu  matlab.ui.container.Menu
        CellQCMetricsinViolinPlotsMenu  matlab.ui.container.Menu
        MultiGroupingViewMenu           matlab.ui.container.Menu
        CrossTabulationMenu             matlab.ui.container.Menu
        ViewMetadataMenu                matlab.ui.container.Menu
        ShowGridMenu                    matlab.ui.container.Menu
        CloseAllOtherFiguresMenu        matlab.ui.container.Menu
        RefreshCurrentViewMenu          matlab.ui.container.Menu
        PlotsMenu                       matlab.ui.container.Menu
        GeneExpressionDotPlotMenu       matlab.ui.container.Menu
        GeneExpressionHeatmapMenu       matlab.ui.container.Menu
        GeneExpressionCellStateStemPlotMenu  matlab.ui.container.Menu
        GeneExpressionCellStateViolinPlotMenu  matlab.ui.container.Menu
        CorrelationPlotMenu             matlab.ui.container.Menu
        SidebySideGeneExpressionMenu    matlab.ui.container.Menu
        EnrichrResultTabletoCircosPlotMenu  matlab.ui.container.Menu
        CellkNNNetworkMenu              matlab.ui.container.Menu
        CellTrajectoryMenu              matlab.ui.container.Menu
        NextMarkerTypeMenu              matlab.ui.container.Menu
        NextColormapMenu                matlab.ui.container.Menu
        CleantSNEUMAPPHATEPlotMenu      matlab.ui.container.Menu
        AnnotateMenu                    matlab.ui.container.Menu
        AnnotateCellTypesUsingPanglaoDBMenu  matlab.ui.container.Menu
        AnnotateCellTypesUsingCustomizedMarkersMenu  matlab.ui.container.Menu
        ImportCellAnnotationfromSCEinWorkspaceMenu  matlab.ui.container.Menu
        ImportCellAnnotationfromSCEDataFileMenu  matlab.ui.container.Menu
        AnnotateCellTypesforBrushedCellsMenu  matlab.ui.container.Menu
        FindMarkerGenesforBrushedCellsMenu  matlab.ui.container.Menu
        MakeMarkerGeneHeatmapMenu       matlab.ui.container.Menu
        EstimateCellCyclePhaseMenu      matlab.ui.container.Menu
        EstimateDifferentiationPotencyPMID33244588Menu  matlab.ui.container.Menu
        EstimateStemnessPMID29625051Menu  matlab.ui.container.Menu
        EstimateDissociationGeneRatioPMID34020534Menu  matlab.ui.container.Menu
        SingleClickSolutionAnnotationMenu  matlab.ui.container.Menu
        AnalyzeMenu                     matlab.ui.container.Menu
        GeneExpressionStatisticsMenu    matlab.ui.container.Menu
        TwogeneCooccurrenceTestMenu     matlab.ui.container.Menu
        GeneVariabilityHVGFunctionAnalysisPMID31861624Menu  matlab.ui.container.Menu
        GeneProgramCellScoreAnalysisMenu  matlab.ui.container.Menu
        GeneProgramRadarPlotMenu        matlab.ui.container.Menu
        GeneProgramCorrMatMenu          matlab.ui.container.Menu
        DifferentialExpressionDEAnalysisMenu  matlab.ui.container.Menu
        DifferentialVariabilityDVAnalysisPMID40113778Menu  matlab.ui.container.Menu
        DifferentialProgramDPAnalysisMenu  matlab.ui.container.Menu
        DEAnalysisinCellTypeBatchModeMenu  matlab.ui.container.Menu
        DVAnalysisinCellTypeBatchModeMenu  matlab.ui.container.Menu
        DPAnalysisinCellTypeBatchModeMenu  matlab.ui.container.Menu
        AllDEDVDPAnalysisinCellTypeBatchModeMenu  matlab.ui.container.Menu
        LASSOFeatureSelectionMenu       matlab.ui.container.Menu
        QUBOFeatureSelectionPMID40832053Menu  matlab.ui.container.Menu
        NetworkMenu                     matlab.ui.container.Menu
        RemoveGenestoReduceGeneSpaceMenu  matlab.ui.container.Menu
        PlotGRNfromEdgeGenePairListMenu  matlab.ui.container.Menu
        BuildGRNSelectedGenesMenu       matlab.ui.container.Menu
        BuildCompareTwoGRNsSelectedGenesMenu  matlab.ui.container.Menu
        ConstructGRNwithAllGenesscTenifoldNetMenu  matlab.ui.container.Menu
        ConstructCompareGRNsscTenifoldNetMenu  matlab.ui.container.Menu
        VirtualGeneKnockoutscTenifoldKnkMenu  matlab.ui.container.Menu
        VirtualGeneKnockoutGenKIMenu    matlab.ui.container.Menu
        OneSampleTenifoldXctMenu        matlab.ui.container.Menu
        TwoSampleTenifoldXct2Menu       matlab.ui.container.Menu
        VirtualscTenifoldCkoMenu        matlab.ui.container.Menu
        TalklrIntercellularCrosstalkMenu  matlab.ui.container.Menu
        ExternalMenu                    matlab.ui.container.Menu
        SetRandomSeedMenu               matlab.ui.container.Menu
        SetWorkingFolderMenu            matlab.ui.container.Menu
        SelectLargeLanguageModelMenu    matlab.ui.container.Menu
        InstallQuantumComputingPackageMenu  matlab.ui.container.Menu
        SetUpREnvironmentMenu           matlab.ui.container.Menu
        SetUpPythonEnvironmentMenu      matlab.ui.container.Menu
        BatchIntegrationHarmonyRMenu    matlab.ui.container.Menu
        PseudotimeAnalysisMonocle3Menu  matlab.ui.container.Menu
        AneuploidDiploidAnalysiscopykatMenu  matlab.ui.container.Menu
        VariationalAneuploidyAnalysisSCEVANMenu  matlab.ui.container.Menu
        DetectAmbientRNAContaminationDecontXMenu  matlab.ui.container.Menu
        SeuratFunctionsPMID25867923Menu  matlab.ui.container.Menu
        UMAPtSNEEmbeddingMenu           matlab.ui.container.Menu
        SCTransformv2RegularizationPMID35042561Menu  matlab.ui.container.Menu
        MementoDEDVAnalysisPMID39454576Menu  matlab.ui.container.Menu
        DetectDoubletsScrubletPMID30954476Menu  matlab.ui.container.Menu
        BatchIntegrationHarmonyPMID31740819Menu  matlab.ui.container.Menu
        MELDPerturbationScoreMELDMenu   matlab.ui.container.Menu
        AtlasBasedCellTypeAnnotationMenu  matlab.ui.container.Menu
        PanHumanAzimuthsatijalaborgMenu  matlab.ui.container.Menu
        SCimilarityPMID39566551Menu     matlab.ui.container.Menu
        LocateSCimilarityModelFilesMenu  matlab.ui.container.Menu
        PreselectLibrariesEnrichrAnalysisMenu  matlab.ui.container.Menu
        PerformEnrichrAnalysisMenu      matlab.ui.container.Menu
        PerformGeneAgentAnalysisMenu    matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        OnlineDocumentationMenu         matlab.ui.container.Menu
        ShortcutsUserGuideMenu          matlab.ui.container.Menu
        ViewOnFileExchangeMenu          matlab.ui.container.Menu
        CitescGEAToolboxPaperMenu       matlab.ui.container.Menu
        PapersCitingscGEAToolboxMenu    matlab.ui.container.Menu
        VisitSCGEATOOLStandaloneWebsiteMenu  matlab.ui.container.Menu
        RunSCGEATOOLinMATLABOnlineMenu  matlab.ui.container.Menu
        ValidatePasskeyMenu             matlab.ui.container.Menu
        CheckforUpdatesMenu             matlab.ui.container.Menu
        AboutSCGEATOOLMenu              matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        PushTool_ImportData             matlab.ui.container.toolbar.PushTool
        PushTool_MultigroupingView      matlab.ui.container.toolbar.PushTool
        PushTool_CrossTabulation        matlab.ui.container.toolbar.PushTool
        PushTool_ShowGeneExprGroup      matlab.ui.container.toolbar.PushTool
        PushTool_GeneDotPlot            matlab.ui.container.toolbar.PushTool
        PushTool_GeneHeatmap            matlab.ui.container.toolbar.PushTool
        PushTool_ScatterStemPlot        matlab.ui.container.toolbar.PushTool
        PushTool_Violinplot             matlab.ui.container.toolbar.PushTool
        PushTool_ScatterCorrPlot        matlab.ui.container.toolbar.PushTool
        PushTool_CompareCellScoreBtwCls  matlab.ui.container.toolbar.PushTool
        PushTool_GetCellSignatureMatrix  matlab.ui.container.toolbar.PushTool
        PushTool_DEGene2Groups          matlab.ui.container.toolbar.PushTool
        PushTool_DVGene2Groups          matlab.ui.container.toolbar.PushTool
        PushTool_DPGene2Groups          matlab.ui.container.toolbar.PushTool
        PushTool_BuildGeneNetwork       matlab.ui.container.toolbar.PushTool
        PushTool_CompareGeneNetwork     matlab.ui.container.toolbar.PushTool
        PushTool_SaveFigToPowerPoint    matlab.ui.container.toolbar.PushTool
        PushTool_MakeVideoSnapshot      matlab.ui.container.toolbar.PushTool
        ToggleTool_ShowColorbar         matlab.ui.container.toolbar.ToggleTool
        PushTool_ResizePlotWindow       matlab.ui.container.toolbar.PushTool
        PushTool_LightDarkMode          matlab.ui.container.toolbar.PushTool
        Toolbar2                        matlab.ui.container.Toolbar
        PushTool_ShowGeneExpr           matlab.ui.container.toolbar.PushTool
        PushTool_ShowCellStates         matlab.ui.container.toolbar.PushTool
        PushTool_FilterGenesCells       matlab.ui.container.toolbar.PushTool
        PushTool_LabelCellGroups        matlab.ui.container.toolbar.PushTool
        PushTool_AddBrushedToNewGroup   matlab.ui.container.toolbar.PushTool
        PushTool_MergeBrushedToSameGroup  matlab.ui.container.toolbar.PushTool
        PushTool_RenameCellTypeBatchID  matlab.ui.container.toolbar.PushTool
        PushTool_SingleClickAnnotation  matlab.ui.container.toolbar.PushTool
        PushTool_ClusterCellsS          matlab.ui.container.toolbar.PushTool
        PushTool_DetermineCellTypeCluster  matlab.ui.container.toolbar.PushTool
        PushTool_Brush4Celltypes        matlab.ui.container.toolbar.PushTool
        PushTool_Brush4Markers          matlab.ui.container.toolbar.PushTool
        PushTool_FindAllMarkers         matlab.ui.container.toolbar.PushTool
        PushTool_ShowClustersPop        matlab.ui.container.toolbar.PushTool
        PushTool_SelectCellsByClass     matlab.ui.container.toolbar.PushTool
        PushTool_DeleteSelectedCells    matlab.ui.container.toolbar.PushTool
        PushTool_SaveX                  matlab.ui.container.toolbar.PushTool
        PushTool_EmbedCells             matlab.ui.container.toolbar.PushTool
        PushTool_Switch2D3DEmbeddings   matlab.ui.container.toolbar.PushTool
        PushTool_PickMarkerType         matlab.ui.container.toolbar.PushTool
        PushTool_PIckColormap           matlab.ui.container.toolbar.PushTool
        PushTool_Refresh                matlab.ui.container.toolbar.PushTool
        ReadytoexploreLabel             matlab.ui.control.Label
        ImportDataButton                matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

   
    properties (Access = public)
        sce = SingleCellExperiment
        h % plot handle
        c % current class id
        cL % current class label
        f_traj
        speciestag
        tmpcelltypev
        im % counter number image
        LogFileID
        SelectedPoints
        BackgroundTimer
        % cm  % context menu
    end
    
    methods (Access = private)
        
        function in_UpdateMainPlot(app)
            in_UpdatePushToolMenu(app);

            % uimenu(app.cm, ...
            %     'Text', 'Toggle Grid', ...
            %     'Checked', 'on', ...
            %     'MenuSelectedFcn', @(app, event) ShowGridMenuSelected(app, event));
            % app.UIAxes.ContextMenu = app.cm;
            
            if app.sce.NumCells == 0
                app.UIAxes.Visible = "off";
            else                
                app.h = gui.i_gscatter3(app.sce.s, app.sce.c, 1, ...
                    [], app.UIAxes);

                title(app.UIAxes, app.sce.title);
                subtitle(app.UIAxes, '[genes x cells]');
                if isempty(app.h.ZData) || isscalar(unique(app.h.ZData))
                    view(app.UIAxes, 2);
                end
                app.UIAxes.Visible = "on";
            end
        end

        function in_sc_openscedlg(app)
            app.ImportDataButton.Enable = "off";
            app.ReadytoexploreLabel.Enable = "off";
            [res] = gui.sc_openscedlg([], [], app.UIFigure);
            if ~isempty(res)
                app.sce = res;
                [app.c, app.cL] = findgroups(string(app.sce.c));
                in_UpdateMainPlot(app);
            else
                app.ImportDataButton.Enable = "on";
                app.ReadytoexploreLabel.Enable = "on";
                if app.ImportDataButton.Visible == "on"                    
                    focus(app.ImportDataButton);
                end
            end
        end
        
        function in_UpdatePushToolMenu(app)
            pushTools = i_GetConditionOnPushTool(app);
            menuItems = i_GetConditionOnMenu(app);
            tag = app.sce.NumCells ~= 0;
            set(pushTools, 'Enable', tag);
            set(menuItems, 'Enable', tag);
            app.ImportDataButton.Visible = ~tag;
            app.ReadytoexploreLabel.Visible = ~tag;
            % if tag
            %     app.ImportDataButton.Visible = "off";
            %     app.ReadytoexploreLabel.Visible = ~tag;
            % else
            %     app.ImportDataButton.Visible = "on";
            %     app.ReadytoexploreLabel.Visible = ~tag;
            % end
        end
        
        function results = i_GetAlwaysOnPushTool(app)
            results = [app.PushTool_ImportData; app.PushTool_LightDarkMode];
        end
        
        function results = i_GetConditionOnPushTool(app)
            pushTools1 = i_GetAlwaysOnPushTool(app);
            pushTools2 = findall(app.UIFigure, 'Type', 'uipushtool');
            pushTools3 = findall(app.UIFigure, 'Type', 'uitoggletool');            
            results = setdiff([pushTools2; pushTools3], pushTools1);
        end
        
        function results = i_GetAlwaysOnMenu(app)
            subItems = app.HelpMenu.Children;
            subItems2 = app.ExternalMenu.Children;
            results = [app.FileMenu; app.EditMenu; app.ViewMenu; ...
                app.AnnotateMenu; app.AnalyzeMenu; app.PlotsMenu; ...
                app.NetworkMenu; app.ExternalMenu; ...
                app.HelpMenu; ...
                app.ImportDataMenu; app.CloseMenu; ...             
                subItems; subItems2(1:4); subItems2(9);...
                subItems2(end:-1:end-5)];
        end
        
        function results = i_GetConditionOnMenu(app)
            menuItems1 = i_GetAlwaysOnMenu(app);
            menuItems2 = findall(app.UIFigure, 'Type', 'uimenu');
            results = setdiff(menuItems2, menuItems1);
        end
        
        function isdone = ix_labelclusters(app, notasking)
            if nargin < 1, notasking = true; end
            isdone = false;
            if ~isempty(app.cL)
                if notasking
                    stxtyes = app.cL(app.c);
                else
                    [~, cLx] = findgroups(string(app.c));
                    if isequal(app.cL, cLx)
                        stxtyes = app.c;
                    else
                        stxtyes = app.cL(app.c);
                    end
                end
                dtp = findobj(app.h, 'Type', 'datatip');
                delete(dtp);
                if isstring(stxtyes) || iscellstr(stxtyes)
                    stxtyes = strrep(stxtyes, "_", "\_");
                    stxtyes = strtrim(stxtyes);
                end
                row = dataTipTextRow('', stxtyes);
                app.h.DataTipTemplate.DataTipRows = row;
                % h.DataTipTemplate.FontSize = 5;
                for ik = 1:max(app.c)
                    idx = find(app.c == ik);
                    siv = app.sce.s(idx, :);
                    si = median(siv, 1);
                    % si = geometric_median(siv');
                    [kb] = dsearchn(siv, si);
                    % [~, k] = medoid(siv);  geometric_median
                    datatip(app.h, 'DataIndex', idx(kb));
                end
                % ptlabelclusters.State = 'on';
                isdone = true;
            end            
        end
        
        function in_EmbeddingAgain(app, ndim)
            readytoshow = false;
            if nargin < 2, ndim = []; end
            if isempty(app.sce.struct_cell_embeddings)
                app.sce.struct_cell_embeddings = pkg.e_makeembedstruct;
            end
            %if ~isfield(app.sce.struct_cell_embeddings, 'metaviz3d')
            %    app.sce.struct_cell_embeddings.('metaviz3d') = [];
            %end

            [vslist] = gui.i_checkexistingembed(app.sce, ndim);
            if ~isempty(vslist)
                answer = gui.myQuestdlg(app.UIFigure, ...
                    'Using exsiting embedding?');
            else
                answer = 'No';
            end
            if strcmp(answer, 'Yes')
                [sx] = gui.i_pickembedvalues(app.sce,[],app.UIFigure);
                if ~isempty(sx), app.sce.s = sx; end
            elseif strcmp(answer, 'No')
                [methodtag] = gui.i_pickembedmethod(app.UIFigure, true);
                if isempty(methodtag), return; end
                %if isempty(ndim), [ndim] = gui.i_choose2d3dnmore; end
                %if isempty(ndim), return; end
                %methoddimtag = sprintf('%s%dd',methodtag, ndim); 
    
                switch gui.myQuestdlg(app.UIFigure, 'Overwritten existing embeddings, if any?')
                    case 'Yes'
                        overwrittenold = true;
                    case 'No'
                        overwrittenold = false;
                    otherwise
                        return;
                end
                
                [K, usehvgs] = gui.i_gethvgnum(app.sce, app.UIFigure);
                if isempty(K), return; end                
                fw = gui.myWaitbar(app.UIFigure);
                try
                    forced = true;
                    %if contains(methoddimtag, 'tsne'), disp('tSNE perplexity = 30'); end
                    for k=1:length(methodtag)
                        if ~overwrittenold && ...
                            isfield(app.sce.struct_cell_embeddings, methodtag{k}) && ...
                            ~isempty(app.sce.struct_cell_embeddings.(methodtag{k}))
                            fprintf('Embedding cells using %s - skipping...\n', ...
                                upper(methodtag{k}));
                            continue;
                        else
                            gui.myWaitbar(app.UIFigure, fw, false, '', ...
                                sprintf('Embedding cells using %s', ...
                                upper(methodtag{k})), ...
                                (k-1)/length(methodtag));
                        end
                        ndim = 2 + contains(methodtag{k},'3d');
                        app.sce = app.sce.embedcells(methodtag{k}, forced, ...
                            usehvgs, ndim, K, [], false);
                    end
                    % disp('Following the library-size normalization and log1p-transformation, we visualized similarity among cells by projecting them into a reduced dimensional space using t-distributed stochastic neighbor embedding (t-SNE)/uniform manifold approximation and projection (UMAP).')
                    
                catch ME
                    gui.myWaitbar(app.UIFigure, fw);
                    gui.myErrordlg(app.UIFigure, ME.message, ME.identifier);
                    % rethrow(ME)
                    return;
                end
                if length(methodtag)>1, readytoshow = true; end
                gui.myWaitbar(app.UIFigure, fw);
    
            else
                return;
            end            
            in_UpdateMainPlot(app);
            if readytoshow            
                if strcmp(gui.myQuestdlg(app.UIFigure, 'Show all avaiable embeddings?'), 'Yes')
                    gui.sc_multiembeddingview(app.sce, [], app.UIFigure);
                end
            end
        end            
        
        
        function [hasbrushed, ptsSelected] = i_checkbrusheddata(app)
            ptsSelected = logical(app.h.BrushData.');
            if ~any(ptsSelected)
                hasbrushed = false;
                gui.myWarndlg(app.UIFigure, "No cells are selected.");
            else
                hasbrushed = true;
            end
        end
        
        function [iscelltype] = in_pickcelltypeclusterid(app, a)        
            switch gui.myQuestdlg(app.UIFigure, a, '', {'Cluster', 'Cell Type'}, 'Cluster')
                case 'Cluster'
                    iscelltype = false;
                case 'Cell Type'
                    iscelltype = true;
                otherwise
                    iscelltype = [];
            end
        end
        
        function in_SingleClickSolution(app)
            if ~isprop(app.sce, 'c_cell_type_tx')
                disp('The sce object does not have the property c_cell_type_tx.');
                return;
            end
            if ~all(app.sce.c_cell_type_tx == "undetermined")            
                if ~strcmp(gui.myQuestdlg(app.UIFigure, ...
                        "Your data has been embedded and annotated. " + ...
                        "Single Click Solution will re-embed and " + ...
                        "annotate cells. Current embedding and " + ...
                        "annotation will be overwritten. Continue?"), 'Yes')
                    return; 
                end
            else
                if ~gui.gui_showrefinfo('Single Click Solution', ...
                        app.UIFigure), return; end
            end
            %if isempty(speciestag)
                app.speciestag = gui.i_selectspecies(2, false, app.UIFigure);
            %end
            if isempty(app.speciestag), return; end
    
            prompt = {
                'tSNE Embedding?', ...            
                'Add UMAP Embedding?', ...            
                'Add PHATE Embedding?', ...
                'Estimate Cell Cycles?', ...
                'Estimate Differentiation Potency of Cells?'};
            dlgtitle = '';
            dims = [1, 85];        
            definput = {'Yes', 'No', 'No', 'Yes', 'No'};
    
            if gui.i_isuifig(app.UIFigure)
                answer = gui.myInputdlg(prompt, dlgtitle, definput, app.UIFigure);
            else
                answer = inputdlg(prompt, dlgtitle, dims, definput);
            end

    
            if isempty(answer)
                return;
            end
    
            fw = gui.myWaitbar(app.UIFigure);
            gui.myWaitbar(app.UIFigure, fw, false, '','Basic QC Filtering...', 1/8);
            app.sce = app.sce.qcfilter;
    
            count = 1;
            if ~(strcmpi(answer{count},'Yes') || strcmpi(answer{count},'Y'))
                gui.myErrordlg(app.UIFigure, 'tSNE Embedding has to be included.','');
                return;        
            end
    
            count = count + 1;
            if strcmpi(answer{count},'Yes') || strcmpi(answer{count},'Y')
                gui.myWaitbar(app.UIFigure, fw, false, '', 'Embeding Cells Using UMAP...', 2/8);
                app.sce = app.sce.embedcells('umap3d', true, true, 3);
            end

            count = count + 1;
            if strcmpi(answer{count},'Yes') || strcmpi(answer{count},'Y')
                gui.myWaitbar(app.UIFigure, fw, false, '', 'Embeding Cells Using PHATE...', 2/8);
                app.sce = app.sce.embedcells('phate3d', true, true, 3);
            end
            gui.myWaitbar(app.UIFigure, fw, false, '', 'Embeding Cells Using tSNE...', 2/8);
            try
                app.sce = app.sce.embedcells('tsne3d', true, true, 3);
            catch ME
                gui.myWaitbar(app.UIFigure, fw);
                gui.myErrordlg(app.UIFigure, ME.message, ME.identifier);
                return;
            end
            gui.myWaitbar(app.UIFigure, fw, false, '', 'Clustering Cells Using K-means...', 3/8);
            
            app.sce = app.sce.clustercells([], [], true);
            gui.myWaitbar(app.UIFigure, fw, false, '', 'Annotating Cell Types Using PanglaoDB...', 4/8);
            app.sce = app.sce.assigncelltype(app.speciestag, false);
    
            count = count + 1;
            if strcmpi(answer{count},'Yes') || strcmpi(answer{count},'Y')
                gui.myWaitbar(app.UIFigure, fw, false, '', 'Estimate Cell Cycles...', 5/8);
                app.sce = app.sce.estimatecellcycle;
            end    
            count = count + 1;
            if strcmpi(answer{count},'Yes') || strcmpi(answer{count},'Y')
                gui.myWaitbar(app.UIFigure, fw, false, '', ...
                    'Estimate Differentiation Potency of Cells...', 6/8);
                app.sce = app.sce.estimatepotency(app.speciestag);
            end
            gui.myWaitbar(app.UIFigure, fw, false, '', '', 7/8);
            [app.c, app.cL] = findgroups(string(app.sce.c_cell_type_tx));
            app.sce.c = app.c;
            gui.myWaitbar(app.UIFigure, fw);
            in_UpdateMainPlot(app);
            ix_labelclusters(app, true);
        end

        function in_SingleClickSolution2(app)
            if ~isprop(app.sce, 'c_cell_type_tx')
                disp('The sce object does not have the property c_cell_type_tx.');
                return;
            end
            if ~all(app.sce.c_cell_type_tx == "undetermined")            
                if ~strcmp(gui.myQuestdlg(app.UIFigure, ...
                        "Your data has been embedded and annotated. " + ...
                        "Single Click Solution will re-embed and " + ...
                        "annotate cells. Current embedding and " + ...
                        "annotation will be overwritten. Continue?"), 'Yes')
                    return; 
                end
            else
                if ~gui.gui_showrefinfo('Single Click Solution', ...
                        app.UIFigure), return; end
            end

            hasDuplicates = numel(unique(app.sce.g)) < numel(app.sce.g);
            if hasDuplicates

                [~, app.sce] = gui.gui_rmdugenes(app.sce, app.UIFigure);

                % messagetext = sprintf("Method 1. Appending an underscore and a " + ...
                %     "number to names of duplicates\nMethod 2. Keeping " + ...
                %     "only the first occurrence of duplicates\nMethod " + ...
                %     "3. Collapsing/merging duplicates by summing their " + ...
                %     "expression rows");
                % answer = gui.myQuestdlg(app.UIFigure, messagetext, ...
                %     "Duplicate Genes Detected",{'Method 1', 'Method 2', ...
                %     'Method 3'});
                % switch answer
                %     case 'Cancel'
                %         return;
                %     case 'Method 1'
                %         [app.sce.X, app.sce.g] = sc_rmdugenes(app.sce.X, app.sce.g, 1);
                %     case 'Method 2'
                %         [app.sce.X, app.sce.g] = sc_rmdugenes(app.sce.X, app.sce.g, 2);
                %     case 'Method 3'
                %         fw = gui.myWaitbar(app.UIFigure);
                %         [app.sce.X, app.sce.g] = sc_rmdugenes(app.sce.X, app.sce.g, 3);
                %         gui.myWaitbar(app.UIFigure, fw);
                %     otherwise
                %         return;
                % end

                
                % % messagetext1 = "SCE contains duplicate gene names. Duplicate genes will be removed; only the first occurrence of each duplicate gene will be retained. Continue?";
                % messagetext2 = "SCE contains duplicate gene names. Duplicate genes will be collapsed/merged by summing their expression rows. Continue?";
                % 
                % if ~strcmp('Yes', ...
                %         gui.myQuestdlg(app.UIFigure, messagetext2, "Duplicate Genes Detected"))
                %     return;
                % else
                %     [app.sce.X, app.sce.g] = sc_rmdugenes(app.sce.X, app.sce.g, 1);            
                % end

            end

            %if isempty(speciestag)
            app.speciestag = gui.i_selectspecies(2, false, app.UIFigure);
            %end
            if isempty(app.speciestag) || strlength(app.speciestag) == 0, return; end
    
            prompt = {
                'tSNE Embedding?', ...            
                'Add UMAP Embedding?', ...            
                'Add PHATE Embedding?', ...
                'Estimate Cell Cycles?', ...
                'Estimate Differentiation Potency of Cells?'};
            
            %{
            dlgtitle = '';
            dims = [1, 85];        
            definput = {'Yes', 'No', 'No', 'Yes', 'No'};
    
            if gui.i_isuifig(app.UIFigure)
                answer = gui.myInputdlg(prompt, dlgtitle, definput, app.UIFigure);
            else
                answer = inputdlg(prompt, dlgtitle, dims, definput);
            end

              items = {'Option 1', 'Option 2', 'Option 3', 'Option 4'};
              %}
              answer = gui.myChecklistdlg(app.UIFigure, prompt, ...
                  'Title', 'Select Items', 'DefaultSelection', 1);
    
            if isempty(answer)
                return;
            end

            count = 1;
            %if ~(strcmpi(answer{count},'Yes') || strcmpi(answer{count},'Y'))
            if ~ismember(prompt{count}, answer)
                gui.myErrordlg(app.UIFigure, 'tSNE Embedding has to be included.','');
                return;        
            end            
    
            fw = gui.myWaitbar(app.UIFigure);
            gui.myWaitbar(app.UIFigure, fw, false, '','Basic QC Filtering...', 1/8);
            app.sce = app.sce.qcfilter;
    
    
            count = count + 1;
            % if strcmpi(answer{count},'Yes') || strcmpi(answer{count},'Y')
            if ismember(prompt{count}, answer)    
                gui.myWaitbar(app.UIFigure, fw, false, '', 'Embeding Cells Using UMAP...', 2/8);
                app.sce = app.sce.embedcells('umap3d', true, true, 3);
            end

            count = count + 1;
            % if strcmpi(answer{count},'Yes') || strcmpi(answer{count},'Y')
            if ismember(prompt{count}, answer)
                gui.myWaitbar(app.UIFigure, fw, false, '', 'Embeding Cells Using PHATE...', 2/8);
                app.sce = app.sce.embedcells('phate3d', true, true, 3);
            end
            gui.myWaitbar(app.UIFigure, fw, false, '', 'Embeding Cells Using tSNE...', 2/8);
            try
                app.sce = app.sce.embedcells('tsne3d', true, true, 3);
            catch ME
                gui.myWaitbar(app.UIFigure, fw);
                gui.myErrordlg(app.UIFigure, ME.message, ME.identifier);
                return;
            end
            gui.myWaitbar(app.UIFigure, fw, false, '', 'Clustering Cells Using K-means...', 3/8);
            
            app.sce = app.sce.clustercells([], [], true);
            gui.myWaitbar(app.UIFigure, fw, false, '', 'Annotating Cell Types Using PanglaoDB...', 4/8);
            app.sce = app.sce.assigncelltype(app.speciestag, false);
    
            count = count + 1;
            if ismember(prompt{count}, answer)
            % if strcmpi(answer{count},'Yes') || strcmpi(answer{count},'Y')
                gui.myWaitbar(app.UIFigure, fw, false, '', 'Estimate Cell Cycles...', 5/8);
                app.sce = app.sce.estimatecellcycle;
            end

            count = count + 1;
            if ismember(prompt{count}, answer)
            % if strcmpi(answer{count},'Yes') || strcmpi(answer{count},'Y')
                gui.myWaitbar(app.UIFigure, fw, false, '', ...
                    'Estimate Differentiation Potency of Cells...', 6/8);
                app.sce = app.sce.estimatepotency(app.speciestag);
            end
            gui.myWaitbar(app.UIFigure, fw, false, '', '', 7/8);
            [app.c, app.cL] = findgroups(string(app.sce.c_cell_type_tx));
            app.sce.c = app.c;
            gui.myWaitbar(app.UIFigure, fw);
            in_UpdateMainPlot(app);
            ix_labelclusters(app, true);
        end

        function in_ClusterCellsS(app)        
            if ~strcmp(gui.myQuestdlg(app.UIFigure, 'Cluster cells using embedding S?',''), 'Yes'), return; end
            [sx] = gui.i_pickembedvalues(app.sce,[],app.UIFigure);
            if isempty(sx), return; end
    
            answer = gui.myQuestdlg(app.UIFigure, 'Which method?', 'Select Algorithm', ...
                {'K-means 🐇', 'SnnDpc [DOI:10.1016/j.ins.2018.03.031] 🐢'}, 'K-means 🐇');
            if strcmpi(answer, 'K-means 🐇')
                methodtag = "kmeans";
            elseif strcmpi(answer, 'SnnDpc [DOI:10.1016/j.ins.2018.03.031] 🐢')
                methodtag = "snndpc";
                if ~gui.gui_showrefinfo('SnnDpc [DOI:10.1016/j.ins.2018.03.031]', app.UIFigure), return; end
            else
                return;
            end
            in_reclustercells(app, methodtag, sx);
        end
        
        function in_reclustercells(app, methodtag, sx)
            if nargin<3, sx = []; end
            methodtag = lower(methodtag);
            usingold = false;
            if ~isempty(app.sce.struct_cell_clusterings.(methodtag))
                answer1 = gui.myQuestdlg(app.UIFigure, sprintf('Using existing %s clustering?', ...
                    upper(methodtag)), '', ...
                    {'Yes, use existing', 'No, re-compute', ...
                    'Cancel'}, 'Yes, use existing');
                switch answer1
                    case 'Yes, use existing'
                        app.sce.c_cluster_id = app.sce.struct_cell_clusterings.(methodtag);
                        usingold = true;
                    case 'No, re-compute'
                        usingold = false;
                    case 'Cancel'
                        return;
                end
            end
            if ~usingold
                defv = round(app.sce.NumCells/100, -1);
                defva = min([2, round(app.sce.NumCells/100, -2), ...
                    round(app.sce.NumCells/20, -1)]);
                if defva == 0, defva = min([10, defv]); end                
                defvb = max([round(app.sce.NumCells/20, -2), ...
                    round(app.sce.NumCells/20, -1)]);

                if any([defv, defva, defvb]==0)
                    defv=5; defva=1; defvb=100;
                end
                k = gui.i_inputnumk(defv, defva, defvb, ...
                    'Enter the number of clusters', app.UIFigure);

                if isempty(k), return; end
                fw = gui.myWaitbar(app.UIFigure);
                try
                    app.sce = app.sce.clustercells(k, methodtag, true, sx);
                catch ME
                    gui.myWaitbar(app.UIFigure, fw, true);
                    gui.myErrordlg(app.UIFigure, ME.message, ME.identifier);
                    return
                end
                gui.myWaitbar(app.UIFigure, fw);
            end
            [app.c, app.cL] = findgroups(string(app.sce.c_cluster_id));
            app.sce.c = app.c;
            in_UpdateMainPlot(app);
        end    
        
        function in_DetermineCellTypeClustersGeneral(app, usedefaultdb)
            if nargin < 2, usedefaultdb = true; end
            if usedefaultdb
                organtag = "all";
                databasetag = "panglaodb";
                if ~gui.gui_showrefinfo('PanglaoDB [PMID:30951143]', app.UIFigure), return; end
                app.speciestag = gui.i_selectspecies(2, false, app.UIFigure);
                if isempty(app.speciestag), return; end
            else
                [Tm, Tw] = pkg.i_markerlist2weight(app.sce, app.UIFigure);
                if isempty(Tm) || isempty(Tw)
                    return;
                end
                wvalu = Tw.Var2;
                wgene = string(Tw.Var1);
                celltypev = string(Tm.Var1);
                markergenev = string(Tm.Var2);
            end
    
            [manuallyselect, bestonly] = gui.i_annotemanner(app.UIFigure);
    
            dtp = findobj(app.h, 'Type', 'datatip');
            delete(dtp);
            cLdisp = app.cL;
            if ~manuallyselect, fw = gui.myWaitbar(app.UIFigure); end

            hold(app.UIAxes,'on');
    
            for ix = 1:max(app.c)
                if ~manuallyselect
                    gui.myWaitbar(app.UIFigure, fw, false, '', '', ix/max(app.c));
                end
                ptsSelected = app.c == ix;
    
                if usedefaultdb
                    [Tct] = pkg.local_celltypebrushed(app.sce.X, app.sce.g, ...
                        app.sce.s, ptsSelected, ...
                        app.speciestag, organtag, databasetag, bestonly);
                else
                    [Tct] = pkg.e_determinecelltype(app.sce, ptsSelected, wvalu, ...
                        wgene, celltypev, markergenev);
                end
    
                ctxt = Tct.C1_Cell_Type;
                if manuallyselect && length(ctxt) > 1
    
                if gui.i_isuifig(app.UIFigure)
                    [indx, tf] = gui.myListdlg(app.UIFigure, ctxt, 'Select cell type');
                else
                    [indx, tf] = listdlg('PromptString', {'Select cell type'}, ...
                        'SelectionMode', 'single', 'ListString', ctxt, 'ListSize', [220, 300]);
                end
    
                if tf ~= 1, return; end
                    ctxt = Tct.C1_Cell_Type{indx};
                else
                    ctxt = Tct.C1_Cell_Type{1};
                end    
    
    
                ctxtdisp = strrep(ctxt, '_', '\_');
                ctxtdisp = sprintf('%s_{%d}', ctxtdisp, ix);
                cLdisp(ix) = ctxtdisp;
    
                ctxt = sprintf('%s_{%d}', ctxt, ix);
                app.cL(ix) = ctxt;
    
                row = dataTipTextRow('', cLdisp(app.c));
    
                app.h.DataTipTemplate.DataTipRows = row;
              
                if size(app.sce.s, 2) >= 2
                    siv = app.sce.s(ptsSelected, :);
                    si = mean(siv, 1);
                    idx = find(ptsSelected);
                    [k] = dsearchn(siv, si); % Nearest point search
                    datatip(app.h, 'DataIndex', idx(k));
                    % text(si(:,1),si(:,2),si(:,3),sprintf('%s',ctxt),...
                    %     'fontsize',10,'FontWeight','bold','BackgroundColor','w','EdgeColor','k');
                    %     elseif size(sce.s,2)==2
                    %             si=mean(sce.s(ptsSelected,:));
                    %             text(si(:,1),si(:,2),sprintf('%s',ctxt),...
                    %                  'fontsize',10,'FontWeight','bold','BackgroundColor','w','EdgeColor','k');
                end
                drawnow;
            end
            hold(app.UIAxes,'off');

            if ~manuallyselect, gui.myWaitbar(app.UIFigure, fw); end
            app.sce.c_cell_type_tx = string(app.cL(app.c));
            nx = length(unique(app.sce.c_cell_type_tx));
            if nx > 1
                newtx = erase(app.sce.c_cell_type_tx, "_{"+digitsPattern+"}");
                if length(unique(newtx)) ~= nx                
                    if strcmp(gui.myQuestdlg(app.UIFigure, 'Merge subclusters of same cell type?'), 'Yes')
                        in_MergeSubCellTypes(app);
                    end
                end
            end
        end

        
        function in_deletecells(app, ptsSelected)
            needprogressbar = false;
            if app.sce.NumCells > 8000, needprogressbar = true; end
            if needprogressbar, fw = gui.myWaitbar(app.UIFigure); end
            app.sce = app.sce.removecells(ptsSelected);
            if needprogressbar
                gui.myWaitbar(app.UIFigure, fw);
            end
            [app.c, app.cL] = findgroups(string(app.sce.c));
            in_RefreshAll(app, true, true);
        end

        
        function in_RefreshAll(app, keepview, keepcolr)
            if nargin < 3, keepcolr = false; end
            if nargin < 2, keepview = false; end
    
            % if gui.i_isuifig(app.UIFigure)
            %     keepcolr = false;
            %     keepview = false;
            % end
    
            if keepview || keepcolr
                [para] = gui.i_getoldsettings(app, app.UIFigure);
            end
            if isempty(app.sce) || app.sce.NumCells==0
                if ~isempty(app.h) && isvalid(app.h)
                    delete(app.h);
                end
                set(app.UIAxes,'Visible','off');
                in_EnDisableMenu(app, 'off');
                return;
            end
            in_EnDisableMenu(app, 'on');
            
            if ~gui.i_isuifig(app.UIFigure)
                if isvalid(app.UIFigure) && isa(app.UIFigure, 'matlab.ui.Figure'), figure(app.UIFigure); end
            end
            
            % [c,cL]=findgroups(string(sce.c));
            % was3d = ~isempty(h.ZData);        
    
            if isempty(app.c), [app.c, app.cL] = findgroups(string(app.sce.c)); end
            if size(app.sce.s, 2) >= 3
                if keepview, [ax, bx] = view(app.UIAxes); end
                app.h = gui.i_gscatter3(app.sce.s, app.c, 1, [], app.UIAxes);
                %h=scatter3(app.UIAxes,rand(100,1),rand(100,1),rand(100,1));
                if keepview && bx~=90
                    view(app.UIAxes, ax, bx);
                end
            else        % otherwise going to show 2D
                if keepview, [ax, bx] = view(app.UIAxes); end
                app.h = gui.i_gscatter3(app.sce.s(:, 1:2), app.c, 1, [], app.UIAxes);
                if keepview && bx==90
                    view(app.UIAxes, ax, bx);
                end
            end
            if keepview
                if isfield(para,'oldMarker')
                    app.h.Marker = para.oldMarker;
                end
                if isfield(para,'oldSizeData')
                    app.h.SizeData = para.oldSizeData;
                end
            end
            if keepcolr
                if isfield(para, 'oldColorMap')
                    colormap(app.UIAxes,para.oldColorMap);
                end
            else
                kc = numel(unique(app.sce.c));
                colormap(app.UIAxes, pkg.i_mycolorlines(kc));
            end
            title(app.UIAxes, app.sce.title);
            subtitle(app.UIAxes, '[genes x cells]');
            %app.UIAxes.Toolbar.Visible = 'on';
            %set(app.UIAxes,'Visible','on');
        end
        
        function in_EnDisableMenu(app, tag)
            pushTools = i_GetConditionOnPushTool(app);
            menuItems = i_GetConditionOnMenu(app);
            set(pushTools, 'Enable', tag);
            set(menuItems, 'Enable', tag);
        end
        
        function xui_CaptureFigVid(app, ViewZ, FileName, OptionZ)
            
            if nargin < 3
                OptionZ = struct([]);
            end
            
            % check orientation of ViewZ, should be two columns and >=2 rows
            if size(ViewZ, 2) > size(ViewZ, 1)
                ViewZ = ViewZ.';
            end
            if size(ViewZ, 2) > 2
                warning('AJennings:VidWrite', ...
                    'Views should have n rows and only 2 columns. Deleting extraneous input.');
                ViewZ = ViewZ(:, 1:2); %remove any extra columns
            end
            
            if ispc
                daObj = VideoWriter(FileName, 'MPEG-4'); %my preferred format
            else
                daObj=VideoWriter(FileName); %for default video format.
            end
            % MPEG-4 CANNOT BE USED ON UNIX MACHINES
            % set values:
            % Frame rate
            if isfield(OptionZ, 'FrameRate')
                daObj.FrameRate = OptionZ.FrameRate;
            end
            if isfield(OptionZ, 'Duration') %space out view angles
                temp_n = round(OptionZ.Duration*daObj.FrameRate); % number frames
                temp_p = (temp_n - 1) / (size(ViewZ, 1) - 1); % length of each interval
                ViewZ_new = zeros(temp_n, 2);
                for inis = 1:(size(ViewZ, 1) - 1)
                    ViewZ_new(round(temp_p*(inis - 1)+1):round(temp_p*inis+1), :) = ...
                        [linspace(ViewZ(inis, 1), ViewZ(inis+1, 1), ...
                        round(temp_p*inis)-round(temp_p*(inis - 1))+1).', ...
                        linspace(ViewZ(inis, 2), ViewZ(inis+1, 2), ...
                        round(temp_p*inis)-round(temp_p*(inis - 1))+1).'];
                end
                ViewZ = ViewZ_new;
            end
            if length(ViewZ) == 2 % only initial and final given
                ViewZ = [linspace(ViewZ(1, 1), ViewZ(end, 1)).', ...
                    linspace(ViewZ(1, 2), ViewZ(end, 2)).'];
            end
            if isfield(OptionZ, 'Periodic') && OptionZ.Periodic == true
                ViewZ = ViewZ(1:(end -1), :); %remove last sample
            end
            open(daObj);
            for kathy = 1:size(ViewZ, 1)
                view(app.UIAxes, ViewZ(kathy, :));
                drawnow;
                % writeVideo(daObj, getframe(gcf)); %use figure, since axis changes size based on view
                writeVideo(daObj, getframe(app.UIFigure)); 
            end
            close(daObj);
        end
        
        function in_DrawTrajectory(app, ~)
            % xxx
            justload = false;
            if ~strcmpi('Yes', gui.myQuestdlg(app.UIFigure, ['This function is ' ...
                    'not intended for use with t-SNE or UMAP embeddings, since ' ...
                    'those methods tend to break up the data into separate ' ...
                    'clusters, which can hide the smooth transitions you''d ' ...
                    'expect in developmental trajectories. Use PHATE ' ...
                    'embedding instead. Continue?']))
                return;
            end
            if ~isempty(app.f_traj) && isvalid(app.f_traj) && isgraphics(app.f_traj, 'line')
                if strcmp({app.f_traj.Visible}, 'on')
                    switch gui.myQuestdlg(app.UIFigure, ...
                            'Remove existing trajectory curve?','')
                        case 'Yes'
                            in_RefreshAll(app, true, true);  % keepview, keepcolr
                        case 'No'
                        otherwise
                            return;
                    end
                end
            end
            if license('test', 'curve_fitting_toolbox') && ~isempty(which('cscvn'))
                answer = gui.myQuestdlg(app.UIFigure, 'Which method?', '', ...
                    {'splinefit', 'princurve', ...
                    'manual'}, 'splinefit');
            else
                answer = gui.myQuestdlg(app.UIFigure, 'Which method?', '', {'splinefit', 'princurve'}, ...
                    'splinefit');
            end
            switch answer
                case 'splinefit'
                    dim = 1;
                    [t, xyz1] = pkg.i_pseudotime_by_splinefit(app.sce.s, dim, false);
                    pseudotimemethod = 'splinefit';
                case 'princurve'
                    try
                    [t, xyz1] = pkg.i_pseudotime_by_princurve(app.sce.s, false);
                    pseudotimemethod = 'princurve';
                    catch ME
                        gui.myErrordlg(app.UIFigure,"Runtime error.");
                        return
                    end
                case 'manual'

                    % gui.myWarndlg(app.UIFigure, "Function is under development. Use scgeatool_legacy.m instead.");
                    % 
                    % return;

                    if license('test', 'curve_fitting_toolbox') && ~isempty(which('cscvn'))
                        if ~isempty(app.h.ZData)
                            switch gui.myQuestdlg(app.UIFigure, ...
                                    ['This function does not work for 3D ' ...
                                    'embedding. Continue to switch to 2D?'])
                                case 'Yes'
                                    PushTool_Switch2D3DEmbeddingsClicked(app, []);
                                    % in_Switch2D3D(app, []);  % xxx
                                otherwise
                                    return;
                            end
                        end
                        if ~isempty(app.h.ZData), return; end
    
                        answer2 = gui.myQuestdlg(app.UIFigure, ...
                            'Draw trajectory curve or load saved curve and pseudotime?', ...
                            '', {'Draw Curve', 'Load Saved', 'Cancel'}, 'Draw Curve');
    
                        switch answer2
                            case 'Load Saved'
                                [file, path] = uigetfile('*.mat', ...
                                    'Select a MAT-file to Load');
                                if isvalid(app.UIFigure) && isa(app.UIFigure, ...
                                        'matlab.ui.Figure')
                                    figure(app.UIFigure); 
                                end
                                if isequal(file, 0)
                                    disp('User canceled the file selection.');
                                    return;
                                end
                                
                                fullFileName = fullfile(path, file);
                                loadedData = load(fullFileName);
                                if isfield(loadedData, 't') && isfield(loadedData, 'xyz1') && isfield(loadedData, 'pseudotimemethod')
                                    t=loadedData.t;
                                    xyz1=loadedData.xyz1;
                                    pseudotimemethod=loadedData.pseudotimemethod;
                                else
                                    gui.myErrordlg(app.UIFigure, 'Not a valid .mat file.','');
                                    return;
                                end
                                justload = true;

                            case 'Draw Curve'
                                fx = gui.myFigure(app.UIFigure);
                                fig2 = fx.FigHandle;
                                ax2 = fx.AxHandle;
                                gui.copyUIAxesToFigure(app.UIAxes, ax2);
                                
                                fx.show; %(app.UIFigure);
                                fig2.WindowStyle="modal";
                                hold(ax2,"on");
                                x = []; y = [];
                                while true
                                    [xi, yi, button] = ginput(1);                        
                                    % Exit the loop if Enter (ASCII 13) is pressed
                                    if isempty(button) || button == 13
                                        break;
                                    end
                                    x = [x; xi];
                                    y = [y; yi];        
                                    plot(ax2, xi, yi, 'ro', 'MarkerSize', 8, 'LineWidth', 2);
                                end
                                pause(1)
                                fx.closeFigure;

                                hold(app.UIAxes, "on");
                                for k = 1:length(x)
                                    plot(app.UIAxes, x(k), y(k), 'ro', 'MarkerSize', 8, 'LineWidth', 2);
                                end

                                splineCurve = cscvn([x'; y']);
                                [xyz1] = fnplt(splineCurve);  % Plot the spline curve in red
                                xyz1 = xyz1';
                                
                                [t] = dsearchn(xyz1, app.sce.s);
                                t = (t + randn(size(t)))';
                                t = normalize(t, 'range');
                                t = t(:);
                                pseudotimemethod = 'manual';                                
                       
                            otherwise
                                return;
                        end
                    end
                otherwise
                    return;
            end

            hold(app.UIAxes, 'on');        
    
            % if size(xyz1, 2) >= 3            
            %     % assignin('base','xyz1',xyz1)
            %     if size(xyz1, 1) < 0.8*(app.sce.NumCells)
            %         xyz1 = pkg.i_interp3d(xyz1, app.sce.NumCells);
            %     end
            % 
            %     app.f_traj = plot3(app.UIAxes, xyz1(:, 1), xyz1(:, 2), xyz1(:, 3), '-r', 'linewidth', 2);
            % 
            %     % xxx
            %     if isMATLABReleaseOlderThan('R2025a') 
            %         t1 = text(app.UIAxes, xyz1(1, 1), xyz1(1, 2), xyz1(1, 3), 'Start', ...
            %             'fontsize', 10, 'FontWeight', 'bold', 'BackgroundColor', 'w', 'EdgeColor', 'k');
            %         t2 = text(app.UIAxes, xyz1(end, 1), xyz1(end, 2), xyz1(end, 3), 'End', ...
            %             'fontsize', 10, 'FontWeight', 'bold', 'BackgroundColor', 'w', 'EdgeColor', 'k');
            %     else
            %         style = app.UIFigure.Theme.BaseColorStyle;
            %         switch style
            %         case "light"
            %             t1 = text(app.UIAxes, xyz1(1, 1), xyz1(1, 2), xyz1(1, 3), 'Start', ...
            %                 'fontsize', 10, 'FontWeight', 'bold', 'BackgroundColor', 'w', 'EdgeColor', 'k');
            %             t2 = text(app.UIAxes, xyz1(end, 1), xyz1(end, 2), xyz1(end, 3), 'End', ...
            %                 'fontsize', 10, 'FontWeight', 'bold', 'BackgroundColor', 'w', 'EdgeColor', 'k');
            %         case "dark"
            %             t1 = text(app.UIAxes, xyz1(1, 1), xyz1(1, 2), xyz1(1, 3), 'Start', ...
            %                 'fontsize', 10, 'FontWeight', 'bold', 'EdgeColor', 'w');
            %             t2 = text(app.UIAxes, xyz1(end, 1), xyz1(end, 2), xyz1(end, 3), 'End', ...
            %                 'fontsize', 10, 'FontWeight', 'bold', 'EdgeColor', 'w');
            %         end
            % 
            %     end
            % elseif size(xyz1, 2) == 2
            % 
            %     app.f_traj = plot(app.UIAxes, xyz1(:, 1), xyz1(:, 2), '-r', 'linewidth', 2);
            %     if isMATLABReleaseOlderThan('R2025a') 
            %         t1 = text(app.UIAxes, xyz1(1, 1), xyz1(1, 2), 'Start', ...
            %             'fontsize', 10, 'FontWeight', 'bold', 'BackgroundColor', 'w', 'EdgeColor', 'k');
            %         t2 = text(app.UIAxes, xyz1(end, 1), xyz1(end, 2), 'End', ...
            %             'fontsize', 10, 'FontWeight', 'bold', 'BackgroundColor', 'w', 'EdgeColor', 'k');
            %     else
            %         style = app.UIFigure.Theme.BaseColorStyle;
            %         switch style
            %         case "light"
            %             t1 = text(app.UIAxes, xyz1(1, 1), xyz1(1, 2), 'Start', ...
            %                 'fontsize', 10, 'FontWeight', 'bold', 'BackgroundColor', 'w', 'EdgeColor', 'k');
            %             t2 = text(app.UIAxes, xyz1(end, 1), xyz1(end, 2), 'End', ...
            %                 'fontsize', 10, 'FontWeight', 'bold', 'BackgroundColor', 'w', 'EdgeColor', 'k');
            %         case "dark"
            %             t1 = text(app.UIAxes, xyz1(1, 1), xyz1(1, 2), 'Start', ...
            %                 'fontsize', 10, 'FontWeight', 'bold', 'EdgeColor', 'w');
            %             t2 = text(app.UIAxes, xyz1(end, 1), xyz1(end, 2), 'End', ...
            %                 'fontsize', 10, 'FontWeight', 'bold', 'EdgeColor', 'w');
            %         end
            % 
            %     end
            % end

            % Check if xyz1 has valid dimensions
            if size(xyz1, 2) < 2
                return; % Exit if less than 2 dimensions
            end
            
            % Interpolate if needed (only for 3D case)
            if size(xyz1, 2) >= 3 && size(xyz1, 1) < 0.8 * app.sce.NumCells
                xyz1 = pkg.i_interp3d(xyz1, app.sce.NumCells);
            end
            
            % Plot trajectory based on dimensions
            if size(xyz1, 2) >= 3
                app.f_traj = plot3(app.UIAxes, xyz1(:, 1), xyz1(:, 2), xyz1(:, 3), '-r', 'linewidth', 2);
                start_pos = [xyz1(1, 1), xyz1(1, 2), xyz1(1, 3)];
                end_pos = [xyz1(end, 1), xyz1(end, 2), xyz1(end, 3)];
            else % size(xyz1, 2) == 2
                app.f_traj = plot(app.UIAxes, xyz1(:, 1), xyz1(:, 2), '-r', 'linewidth', 2);
                start_pos = [xyz1(1, 1), xyz1(1, 2)];
                end_pos = [xyz1(end, 1), xyz1(end, 2)];
            end
            
            % Determine text properties based on MATLAB version and theme
            if isMATLABReleaseOlderThan('R2025a')
                text_props = {'fontsize', 10, 'FontWeight', 'bold', 'BackgroundColor', 'w', 'EdgeColor', 'k'};
            else
                style = app.UIFigure.Theme.BaseColorStyle;
                if strcmp(style, "light")
                    text_props = {'fontsize', 10, 'FontWeight', 'bold', 'BackgroundColor', 'w', 'EdgeColor', 'k'};
                else % dark theme
                    text_props = {'fontsize', 10, 'FontWeight', 'bold', 'EdgeColor', 'w'};
                end
            end
            
            % Add start and end text labels
            t1 = text(app.UIAxes, start_pos(1), start_pos(2), start_pos(end), 'Start', text_props{:});
            t2 = text(app.UIAxes, end_pos(1), end_pos(2), end_pos(end), 'End', text_props{:});            

            hold(app.UIAxes, 'off');
            drawnow;
            pause(2);
    
            % pseudotimemethod
            if ~strcmp(answer, 'manual')
                switch gui.myQuestdlg(app.UIFigure, 'Swap ''Start'' and ''End''?','')
                    case 'Yes'
                        t1.String = 'End';
                        t2.String = 'Start';
                        t = 1 - t;
                    case 'Cancel'
                        return;
                end
            end
    
            tag = sprintf('%s_pseudotime', pseudotimemethod);
            % iscellstr(sce.list_cell_attributes(1:2:end))
            try
                idx = find(contains(app.sce.list_cell_attributes(1:2:end), tag));
            catch ME
                idx = [];
                warning(ME.message);
            end
            if ~isempty(idx)
                app.sce.list_cell_attributes{idx*2} = t;
                fprintf('%s is updated.\n', upper(tag));
            else
                app.sce.list_cell_attributes{end+1} = tag;
                app.sce.list_cell_attributes{end+1} = t;
                fprintf('%s is saved.\n', upper(tag));
            end            
            
            % -----------
            if strcmp(answer, 'manual') && ~justload
                switch gui.myQuestdlg(app.UIFigure, 'Save manual trajectory curve and pseudotime to an .mat file?','')
                    case 'Yes'
                        [file, path] = uiputfile('*.mat', 'Save as', ...
                            'pseudotime_manual_trajectory.mat');
                        if isvalid(app.UIFigure) && isa(app.UIFigure, 'matlab.ui.Figure'), figure(app.UIFigure); end
                        if isequal(file, 0) || isequal(path, 0)
                            disp('User canceled the file selection.');
                            return;
                        end                                                                        
                        fullFileName = fullfile(path, file);
                        save(fullFileName, 'xyz1', 't', 'pseudotimemethod');
                        disp(['Variables saved to ', fullFileName]);
                    case 'No'
                    case 'Cancel'
                        return;
                    otherwise
                        return;
                end
            end
            % --------------
            
            switch gui.myQuestdlg(app.UIFigure, 'View expression of selected genes','')
                case 'Yes'
                    gui.sc_pseudotimegenes(app.sce, t, app.UIFigure);
            end
        end

        
        function in_MergeSCEs(app, sourcetag)
            [requirerefresh, s] = gui.callback_MergeSCEs(app, sourcetag);
            if requirerefresh && ~isempty(s)
                [app.c, app.cL] = findgroups(string(app.sce.c_batch_id));
                app.sce.c = app.c;
                if app.sce.NumCells==0
                    gui.myWarndlg(app.UIFigure, 'Merged SCE contains no cells.');
                    return;
                else
                    in_RefreshAll(app, true, false);
                    gui.myHelpdlg(app.UIFigure, sprintf('%s SCEs merged.', upper(s)));
                end
            end
        end

        function in_AddEditCellAttribs(app)        
            switch gui.myQuestdlg(app.UIFigure, 'Add or edit cell attribute?','',{'Add','Edit','Cancel'},'Add')
                case 'Edit'
                    addnew = false;
                case 'Add'
                    addnew = true;
                otherwise
                    return;
            end
            [sceback, needupdate] = gui.sc_cellattribeditor(app.sce, addnew, app.UIFigure);
            if needupdate
                app.sce = sceback;
            end
        end

        
        function in_MergeSubCellTypes(app)
            if isempty(app.sce.c_cell_type_tx), return; end
            newtx = erase(app.sce.c_cell_type_tx, "_{"+digitsPattern+"}");
            if isequal(app.sce.c_cell_type_tx, newtx)
                gui.myHelpdlg(app.UIFigure, "No sub-clusters are meraged.");
            else
                app.sce.c_cell_type_tx = newtx;
                [app.c, app.cL] = findgroups(string(app.sce.c_cell_type_tx));
                app.sce.c = app.c;
                in_RefreshAll(app, true, false);
                ix_labelclusters(app, true);
            end
        end

        
        function in_WorkonSelectedGenes(app, type)
            if nargin < 2, type = 'name'; end
    
            switch type
                case 'name'
                    [glist] = gui.i_selectngenes(app.sce, [], app.UIFigure);
                    if isempty(glist), return; end
                    [y, idx] = ismember(glist, app.sce.g);
                    if ~all(y)
                        gui.myErrordlg(app.UIFigure, 'Runtime error.','');
                        return;
                    end
                case 'hvg'
                    k = gui.i_inputnumk(2000, 1, app.sce.NumGenes, ...
                        'Enter the number of HVGs', app.UIFigure);
                    if isempty(k), return; end
                    answer = gui.myQuestdlg(app.UIFigure, 'Which HVG detecting method to use?', '', ...
                        {'Splinefit Method [PMID:31697351]', ...
                        'Brennecke et al. (2013) [PMID:24056876]'}, ...
                        'Splinefit Method [PMID:31697351]');                
                    switch answer
                        case 'Splinefit Method [PMID:31697351]'
                            fw = gui.myWaitbar(app.UIFigure);
                            T = sc_splinefit(app.sce.X, app.sce.g);
                        case 'Brennecke et al. (2013) [PMID:24056876]'
                            fw = gui.myWaitbar(app.UIFigure);
                            T = sc_hvg(app.sce.X, app.sce.g);                        
                        otherwise
                            return;
                    end
                    glist = T.genes(1:min([k, app.sce.NumGenes]));
                    [y, idx] = ismember(glist, app.sce.g);
                    if ~all(y)
                        gui.myErrordlg(app.UIFigure, 'Runtime error.','');
                        return;
                    end
                    gui.myWaitbar(app.UIFigure, fw);
                case 'ligandreceptor'
                    mfolder = fileparts(mfilename('fullpath'));
                    fw = gui.myWaitbar(app.UIFigure);
                    load(fullfile(mfolder, 'assets', 'Ligand_Receptor', ...
                         'Ligand_Receptor_more.mat'), 'ligand','receptor');
                    idx = ismember(upper(app.sce.g), unique([ligand; receptor]));
                    if ~any(idx)
                        gui.myErrordlg(app.UIFigure, 'Runtime error: No gene left after selection.','');
                        return;
                    end
                    if sum(idx) < 50                    
                        if ~strcmp(gui.myQuestdlg(app.UIFigure, 'Few genes (n < 50) selected. Continue?',''), 'Yes'), return; end
                    end
                    gui.myWaitbar(app.UIFigure, fw);
            end
    
            app.sce.g = app.sce.g(idx);
            app.sce.X = app.sce.X(idx, :);
            in_RefreshAll(app, true, false);
        end

        
        function in_SubsampleCells(app, methodoption)
            if nargin < 2, methodoption = []; end        
            if ~strcmp(gui.myQuestdlg(app.UIFigure, 'This function subsamples 50% of cells. Continue?',''), 'Yes'), return; end
            if isempty(methodoption)
                answer = gui.myQuestdlg(app.UIFigure, 'Select method:', '', ...
                    {'Uniform Sampling', ...
                    'Geometric Sketching [PMID:31176620]'}, 'Uniform Sampling');
                switch answer
                    case 'Uniform Sampling'
                        methodoption = 1;
                    case 'Geometric Sketching [PMID:31176620]'
                        if ~pkg.i_checkpython
                            gui.myWarndlg(app.UIFigure, 'Python not installed.','');
                            return;
                        end
                        methodoption = 2;
                    otherwise
                        return;
                end
            end
    
            tn = round(app.sce.NumCells/2);
            if methodoption == 1
                rng("shuffle");
                idx = randperm(app.sce.NumCells);
                ids = idx(1:tn);
            elseif methodoption == 2
                if ~gui.gui_showrefinfo('Geometric Sketching [PMID:31176620]', ...
                        app.UIFigure), return; end
                fw = gui.myWaitbar(app.UIFigure);
                Xn = log1p(sc_norm(app.sce.X))';
                gui.myWaitbar(app.UIFigure, fw);
                try
                    if issparse(Xn)
                        Xn = full(Xn);
                    end
                    [~, Xn] = pca(Xn, 'NumComponents', 300);
                    ids = run.py_geosketch(Xn, tn);
                catch ME
                    gui.myWaitbar(app.UIFigure, fw, true);
                    gui.myErrordlg(app.UIFigure, ME.message, ME.identifier);
                    return;
                end
    
            end
            if ~isempty(ids)
                app.sce = app.sce.selectcells(ids);
                app.c = app.sce.c;
                in_RefreshAll(app, true, false);
            else
                gui.myErrordlg(app.UIFigure, 'Runtime error. No action is taken.');
            end
        end
        
        function in_DeleteBrushedOrUnbrushedCells(app, action)
            if nargin<2, action='brushed'; end
    
            ptsSelected = logical(app.h.BrushData.');
            if ~any(ptsSelected)
                gui.myWarndlg(app.UIFigure, "No cells are selected.");
                return;
            end
            [ptsSelected, letdoit] = gui.i_expandbrushed(ptsSelected, ...
                app.sce, app.UIFigure);
            if ~letdoit, return; end
            
            switch lower(action)
                case 'unbrushed'
                    if strcmp(gui.myQuestdlg(app.UIFigure, ...
                            ['Unbrushed/Unselected cells will be deleted. ' ...
                            'Continue?']),'Yes')
                        in_deletecells(app, ~ptsSelected);
                    else
                        return;
                    end
                case 'brushed'
                    if strcmp(gui.myQuestdlg(app.UIFigure, ...
                            ['Brushed/Selected cells will be deleted. ' ...
                            'Continue?']),'Yes')
                        in_deletecells(app, ptsSelected);
                    else
                        return;
                    end
                otherwise
                    return;
            end
        end

        
        function in_ClusterCellsX(app)
            if ~strcmp(gui.myQuestdlg(app.UIFigure, 'Cluster cells using expression matrix X?',''), 'Yes'), return; end
            % methodtagvx = {'specter (31 secs) 🐇', 'sc3 (77 secs) 🐇', ...
            %     'simlr (400 secs) 🐢', ...
            %     'soptsc (1,182 secs) 🐢🐢', 'sinnlrr (8,307 secs) 🐢🐢🐢',};
            % methodtagv = {'specter', 'sc3', 'simlr', 'soptsc', 'sinnlrr'};
            methodtagvx = {'SC3 [PMID:28346451] 🐢🐢'};
            methodtagv = {'sc3'};
            if gui.i_isuifig(app.UIFigure)
                [indx, tf] = gui.myListdlg(app.UIFigure, methodtagvx, 'Select a clustering algorithm');
            else        
                [indx, tf] = listdlg('PromptString', ...
                    {'Select a clustering algorithm'}, ...
                    'SelectionMode', 'single', ...
                    'ListString', methodtagvx, 'ListSize', [220, 300]);
            end
            if tf == 1
                methodtag = methodtagv{indx};
            else
                return;
            end
            if (ismcc || isdeployed)
                if strcmp(methodtag, 'sc3')
                    gui.myWarndlg(app.UIFigure, 'SC3 is not working in standalone application.');
                    return;
                end
            end
            in_reclustercells(app, methodtag, []);
        end

        
        function in_MergeCellSubtypes(app, sourcetag, allcell)
            if nargin < 3
                switch gui.myQuestdlg(app.UIFigure, 'Import annotation for all cells or just cells of a subtype?', '', ...
                    {'All Cells', 'Subtype Cells', 'Cancel'}, 'All Cells')
                    case 'All Cells'
                        allcell = true;
                    case 'Subtype Cells'
                        allcell = false;
                    case 'Cancel'
                        return;
                    otherwise
                        return;
                end
            end
            [requirerefresh] = gui.callback_MergeCellSubtypes(app, [], sourcetag, allcell);
            if requirerefresh
                [app.c, app.cL] = findgroups(string(app.sce.c_cell_type_tx));
                in_RefreshAll(app, true, false);
                ix_labelclusters(app, true);
            end
        end
        
        function in_EnrichrHVGs(app)
            if ~gui.gui_showrefinfo('HVG Functional Analysis [PMID:31861624]', ...
                    app.UIFigure), return; end
            ptsSelected = logical(app.h.BrushData.');
            if any(ptsSelected)
                [ptsSelected, letdoit] = gui.i_expandbrushed(ptsSelected, ...
                    app.sce, app.UIFigure);
                if ~letdoit, return; end
                if sum(ptsSelected) < 200                
                    if ~strcmp(gui.myQuestdlg(app.UIFigure, ...
                            sprintf('Too few cells (n = %d) selected, continue?', ...
                            sum(ptsSelected))), 'Yes')
                        return; 
                    end
                end
                scetmp = copy(app.sce);
                scetmp = scetmp.removecells(~ptsSelected);
                scetmp = scetmp.qcfilter(1000, 0.15, 15);
                gui.callback_EnrichrHVGs(app, [], scetmp);
            else
                if ~strcmp(gui.myQuestdlg(app.UIFigure, ...
                        sprintf('All cells (n = %d) included, continue?', ...
                        app.sce.NumCells),''), 'Yes')
                    return; 
                end
                gui.callback_EnrichrHVGs(app);
            end
        end

        
        function in_Select5000Genes(app)
            oldm = app.sce.NumGenes;
            oldn = app.sce.NumCells;
            [requirerefresh, scenew] = gui.callback_Select5000Genes(app);
            if requirerefresh
                app.sce = scenew;
                app.c=app.sce.c;
                
                newm = app.sce.NumGenes;
                newn = app.sce.NumCells;
                gui.myHelpdlg(app.UIFigure, ...
                    sprintf('%d cells removed; %d genes removed.', ...
                    oldn-newn, oldm-newm));
                in_RefreshAll(app, true, false);
            end
        end

        
        function in_scTenifoldNet(app, methodtag)
            if numel(unique(app.sce.c_cell_type_tx)) > 1
                if ~strcmp(gui.myQuestdlg(app.UIFigure, ...
                        ['This analysis is cell type-specific; however, ' ...
                        'current SCE contains multiple cell ' ...
                        'types. Continue?']),'Yes')
                    return;
                end
            end
            switch gui.myQuestdlg(app.UIFigure, ['Perform 10-fold ' ...
                    'bootstrapping of cells?'],'', ...
                    {'Yes 🐢','No 🐇','Cancel'},'No 🐇')
                case 'No 🐇'
                    if methodtag==1
                        gui.callback_scPCNet1(app);
                    elseif methodtag==2
                        gui.callback_scTenifoldNet2lite(app);
                    end
                case 'Yes 🐢'
                    if methodtag==1
                        gui.callback_scTenifoldNet1(app);
                    elseif methodtag==2
                        gui.callback_scTenifoldNet2(app);
                    end
            end
        end

        
        function in_RunSeuratWorkflow(app)
            extprogname = 'R_Seurat';
            preftagname = 'externalwrkpath';
            [wkdir] = gui.gui_setprgmwkdir(extprogname, ...
                preftagname, app.UIFigure);
            if isempty(wkdir), return; end
            [ok] = gui.i_confirmscript('Run Seurat Workflow (Seurat/R)?', ...
                'R_Seurat', 'r');
            if ~ok, return; end
    
            [ndim] = gui.i_choose2d3d;
            if isempty(ndim), return; end
            fw = gui.myWaitbar(app.UIFigure);
            try
                [app.sce] = run.r_seurat(app.sce, ndim, wkdir, true);
                [app.c, app.cL] = findgroups(string(app.sce.c));
            catch
                gui.myWaitbar(app.UIFigure, fw);
                return;
            end
            gui.myWaitbar(app.UIFigure, fw);
            in_RefreshAll(app, true, false);
        end
    
        function in_DecontX(app)
            if ~gui.gui_showrefinfo('DecontX [PMID:32138770]', ...
                    app.UIFigure)
                return;
            end
            extprogname = 'R_decontX';
            preftagname = 'externalwrkpath';
            [wkdir] = gui.gui_setprgmwkdir(extprogname, preftagname, app.UIFigure);
            if isempty(wkdir), return; end
            fw = gui.myWaitbar(app.UIFigure);
            try
                [Xdecon, ~] = run.r_decontX(app.sce, wkdir);
            catch ME
               gui.myWaitbar(app.UIFigure, fw);
               gui.myErrordlg(app.UIFigure, ME.message, ME.identifier);
               return;
            end
            gui.myWaitbar(app.UIFigure, fw);
    
            if ~(size(Xdecon, 1) == app.sce.NumGenes && ...
                    size(Xdecon, 2) == app.sce.NumCells)
               gui.myErrordlg(app.UIFigure, 'DecontX runtime error.','');
               return;
            end
    
    %       figure('WindowStyle', 'modal');
    %       gui.i_stemscatter(app.sce.s, contamination);
    %       zlabel('Contamination rate')
    %       title('Ambient RNA contamination')        
            if strcmp(gui.myQuestdlg(app.UIFigure, ...
                    "Remove contamination? Click ''No'' " + ...
                    "to keep data unchanged."), 'Yes')
                app.sce.X = round(Xdecon);
                gui.myHelpdlg(app.UIFigure, 'Contamination removed.');
            end
        end
        
        function in_Panhumanpy(app)
            if ~pkg.i_checkpython
                gui.myWarndlg(app.UIFigure, 'Python not installed.');
                return;
            end
            if ~gui.gui_showrefinfo('Panhumanpy', ...
                    app.UIFigure), return; end
            [needupdate, T] = gui.callback_RunPanhumanpy(app, events);
            if needupdate
                [app.c, app.cL] = findgroups(string(app.sce.c_cell_type_tx));
                in_RefreshAll(app, true, false);
                if strcmp('Yes', gui.myQuestdlg(app.UIFigure, ...
                        'View ouput table?'))
                    gui.TableViewerApp(T, app.UIFigure);
                end
            end        
        end
    
        function in_SCimilarity(app)
            if ~pkg.i_checkpython
                gui.myWarndlg(app.UIFigure, 'Python not installed.');
                return;
            end
            if ~gui.gui_showrefinfo('SCimilarity [PMID:39566551]', ...
                    app.UIFigure), return; end
            if gui.callback_RunSCimilarity(app, events)
                [app.c, app.cL] = findgroups(string(app.sce.c_cell_type_tx));
                in_RefreshAll(app, true, false);            
            end
        end

        function in_HarmonyR(app, usepython)
            if nargin<2, usepython = false; end
            if usepython
                fun = @gui.callback_Harmonypy;
                if ~pkg.i_checkpython
                    gui.myWarndlg(app.UIFigure, 'Python is not installed.');
                    return;
                end
            else
                fun = @gui.callback_HarmonyR;
            end
            if ~gui.gui_showrefinfo('Harmony [PMID:31740819]', ...
                    app.UIFigure), return; end
            if numel(unique(app.sce.c_batch_id)) < 2
                gui.myWarndlg(app.UIFigure, ['No batch effect ' ...
                    '(SCE.C_BATCH_ID is empty)']);
                return;
            end
            [c1] = findgroups(string(app.sce.c));
            [c2] = findgroups(string(app.sce.c_batch_id));
            if ~isequal(c1, c2)
                answer = gui.myQuestdlg(app.UIFigure, ...
                    'Color cells by batch id (SCE.C_BATCH_ID)?', '');
                switch answer
                    case 'Yes'
                        [app.c, app.cL] = findgroups(string(app.sce.c_batch_id));
                        app.sce.c = app.c;
                        in_RefreshAll(app, true, false);
                        pause(1)
                    case 'No'
                    case 'Cancel'
                        return;
                    otherwise
                        return;
                end
            end


            if fun(app)
                [app.c, app.cL] = findgroups(string(app.sce.c));
                in_RefreshAll(app, true, false);
    
                ButtonName = gui.myQuestdlg(app.UIFigure, ...
                    'Update Saved Embedding?', '');
                switch ButtonName
                    case 'Yes'
                        dim = size(app.sce.s, 2);
                        [methodtag] = gui.i_pickembedmethod(app.UIFigure, false, dim);
                        if isempty(methodtag), return; end
                        if iscell(methodtag), methodtag = cell2mat(methodtag); end
                        if ismember(methodtag, ...
                                fieldnames(app.sce.struct_cell_embeddings))
                            app.sce.struct_cell_embeddings.(methodtag) = app.sce.s;
                        end
                        gui.myHelpdlg(app.UIFigure, ...
                            sprintf('%s Embedding is updated.', methodtag));
                end
            end
        end        
    
        function in_DoubletDetection(app)
            if ~pkg.i_checkpython
                gui.myWarndlg(app.UIFigure, 'Python not installed.');
                return;
            end
            if ~gui.gui_showrefinfo('Scrublet [PMID:30954476]', ...
                    app.UIFigure)
                return; 
            end
            if numel(unique(app.sce.c_batch_id)) > 1            
                if ~strcmp(gui.myQuestdlg(app.UIFigure, ...
                        ['"When working with data from multiple ' ...
                        'samples, run Scrublet on each sample ' ...
                        'separately." Your data contains multiple ' ...
                        'samples (cells with different c_batch_id). ' ...
                        'Continue?'],''), 'Yes')
                    return; 
                end
            end
            
            [isDoublet, doubletscore, methodtag, done] = ...
            gui.callback_DoubletDetection(app);

            if done && ~any(isDoublet)
                gui.myHelpdlg(app.UIFigure, 'No doublet detected.');
                return;
            end
            if done && any(isDoublet) && app.sce.NumCells == length(doubletscore)
                tmpf_doubletdetection = figure('WindowStyle', 'modal');
                gui.i_stemscatter(app.sce.s, doubletscore);
                ax = gca;
                zlabel(ax, 'Doublet Score');
                title(ax, sprintf('Doublet Detection (%s)', methodtag))
                if strcmp(gui.myQuestdlg(app.UIFigure, ...
                        sprintf("Remove %d doublets?", ...
                        sum(isDoublet))),'Yes')
                    close(tmpf_doubletdetection);
                    app.sce = app.sce.removecells(isDoublet);
                    [app.c, app.cL] = findgroups(string(app.sce.c));
                    in_RefreshAll(app, true, false);
                    gui.myHelpdlg(app.UIFigure, 'Doublets deleted.');
                end
            end
        end
        
        function in_RunMonocle3(app)
            if gui.callback_RunMonocle3(app)
                [y, idx] = ismember({'monocle3_pseudotime'}, ...
                     app.sce.list_cell_attributes(1:2:end));
                if y
                    answer = gui.myQuestdlg(app.UIFigure, ...
                        ['Color cells using pseudotime T and show' ...
                        ' Monocle3 embedding S?'],...
                        '',{'Yes','Color cells only', ...
                        'Show embedding only'},'Yes');
                    switch answer
                        case 'Yes'
                            app.sce.c = app.sce.list_cell_attributes{idx*2};
                            app.sce.s = app.sce.struct_cell_embeddings.('monocle2d');
                            [app.c, app.cL] = findgroups(string(app.sce.c));
                            in_RefreshAll(app, true, false);
                        case 'Color cells only'
                            app.sce.c = app.sce.list_cell_attributes(idx*2);
                            [app.c, app.cL] = findgroups(string(app.sce.c));
                            in_RefreshAll(app, true, false);
                        case 'Show embedding only'
                            app.sce.s = app.sce.struct_cell_embeddings.('monocle2d');
                            in_RefreshAll(app, true, false);
                        otherwise
                    end                
                end
            end
        end
        
        function in_DeleteSelectedCells(app)
            ptsSelected = logical(app.h.BrushData.');
            if ~any(ptsSelected)
                gui.myWarndlg(app.UIFigure, "No cells are selected.");
                return;
            end
            [ptsSelected, letdoit] = gui.i_expandbrushed(ptsSelected, ...
                app.sce, app.UIFigure);
            if ~letdoit, return; end
    
            answer = gui.myQuestdlg(app.UIFigure, ...
                'Delete selected or unselected cells?', '', ...
                {'Selected', 'Unselected'}, 'Selected');
            if isempty(answer), return; end
            if strcmp(answer, 'Unselected')
                in_deletecells(app, ~ptsSelected);
            elseif strcmp(answer, 'Selected')
                in_deletecells(app, ptsSelected);
            else
                return;
            end        
        end        
       
        
        function logMessage(app, msg)
            if ~isempty(app.LogFileID)
                ts = datetime("now", "Format", "y-MM-dd HH:mm:ss");
                timestamp = char(ts);
                line = sprintf('[%s] %s', timestamp, msg);
                fprintf(app.LogFileID, '%s\n', line);
            end
            fprintf('%s\n', line);
        end        
      
        function in_SwitchDarkLightIcons(app, style, reverse)
            if nargin<3, reverse = false; end
            if reverse
                style = char(setdiff({'light','dark'}, style));
            end
            pathToMLAPP = fileparts(mfilename('fullpath'));
            switch style
                case 'light'
                    app.PushTool_LightDarkMode.Icon = ...
                    fullfile(pathToMLAPP, "assets","Images", ...
                    "Material_Symbols","dark_mode_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png");
                case 'dark'
                    app.PushTool_LightDarkMode.Icon = ...
                    fullfile(pathToMLAPP, "assets","Images", ...
                    "Material_Symbols","light_mode_24dp_E3E3E3_FILL0_wght400_GRAD0_opsz24.png");
            end            
            % app.PushTool_EmbedCells
            in_updateicons(app, "PushTool_EmbedCells", "tactic", style);
            in_updateicons(app, "PushTool_ScatterCorrPlot", "show_chart", style);
            in_updateicons(app, "PushTool_ScatterStemPlot", "align_vertical_bottom", style);
            in_updateicons(app, "PushTool_GeneHeatmap", "solar_power", style);
            in_updateicons(app, "PushTool_FindAllMarkers", "editor_choice", style);
            in_updateicons(app, "PushTool_ShowGeneExprGroup", "grid_view", style);
            in_updateicons(app, "PushTool_ShowClustersPop", "dashboard", style);
            in_updateicons(app, "PushTool_DetermineCellTypeCluster", "asterisk", style);
            in_updateicons(app, "PushTool_Brush4Celltypes", "ink_highlighter_move", style);
            in_updateicons(app, "PushTool_AddBrushedToNewGroup", "ad_group", style);
            in_updateicons(app, "PushTool_DeleteSelectedCells", "variable_remove", style);
            in_updateicons(app, "PushTool_ShowCellStates", "event_list", style);
            in_updateicons(app, "PushTool_SelectCellsByClass", "checklist_rtl", style);
            in_updateicons(app, "PushTool_MakeVideoSnapshot", "video_camera_back", style);
            in_updateicons(app, "PushTool_DPGene2Groups", "component_exchange", style);
            in_updateicons(app, "PushTool_DVGene2Groups", "format_letter_spacing", style);
            in_updateicons(app, "PushTool_DEGene2Groups", "balance", style);  
            in_updateicons(app, "PushTool_CrossTabulation", "full_stacked_bar_chart", style);  
            in_updateicons(app, "PushTool_GetCellSignatureMatrix", "hexagon", style);  
            in_updateicons(app, "PushTool_MergeBrushedToSameGroup", "stack_group", style);  
            in_updateicons(app, "PushTool_PickMarkerType", "category", style);  
            in_updateicons(app, "PushTool_PIckColormap", "palette", style);  
            in_updateicons(app, "PushTool_SaveX", "save", style);
            in_updateicons(app, "PushTool_ClusterCellsS", "bubble_chart", style);
            in_updateicons(app, "PushTool_SingleClickAnnotation", "sprint", style);  
            in_updateicons(app, "PushTool_LabelCellGroups", "label", style);
            in_updateicons(app, "PushTool_FilterGenesCells", "filter_alt", style);
            in_updateicons(app, "PushTool_RenameCellTypeBatchID", "border_color", style);
            in_updateicons(app, "PushTool_Switch2D3DEmbeddings", "2d", style);
            in_updateicons(app, "PushTool_CompareCellScoreBtwCls", "fingerprint", style);
            in_updateicons(app, "PushTool_MultigroupingView", "visibility", style);
            in_updateicons(app, "PushTool_Violinplot", "candlestick_chart", style);
            in_updateicons(app, "PushTool_GeneDotPlot", "blur_linear", style);
            in_updateicons(app, "PushTool_Brush4Markers", "military_tech", style);
            in_updateicons(app, "PushTool_ShowGeneExpr", "article", style);
            in_updateicons(app, "PushTool_Refresh", "refresh", style);
            in_updateicons(app, "PushTool_ImportData", "borg", style);
            in_updateicons(app, "PushTool_BuildGeneNetwork", "graph_3", style);
            in_updateicons(app, "PushTool_CompareGeneNetwork", "graph_5", style);
            in_updateicons(app, "PushTool_ResizePlotWindow", "resize", style);            
            
            app.ImportDataButton.Icon = app.PushTool_ImportData.Icon;
        end
        
        function in_updateicons(app, name, iconfile, style)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            switch style
                case 'light'
                    tag = "1F1F1F";
                case 'dark'
                    tag = "E3E3E3";
            end
            f = fullfile(pathToMLAPP, "assets","Images", ...
                    "Material_Symbols", ...
                    sprintf("%s_24dp_%s_FILL0_wght400_GRAD0_opsz24.png", ...
                            iconfile, tag));
            if isfile(f)
                app.(name).Icon = f;
            else
                f
            end
        end
        
        function axesClickCallback(app, ax, event)
            switch event.Button
                case 1
                    cp = ax.CurrentPoint;
                    x = cp(1,1); y = cp(1,2);
                    app.SelectedPoints = [app.SelectedPoints; x, y];
                    plot(app.UIAxes, x, y, 'rx', 'MarkerSize', 12, 'LineWidth', 2);
                case 3
                    uialert(app.UIFigure, ...
                        sprintf('You selected %d points.', size(app.SelectedPoints,1)), ...
                        'Done');
                    ax.ButtonDownFcn = [];
            end
        end
        
        function in_UpdateLockedFun(app)
            s = app.PerformGeneAgentAnalysisMenu.Text;
            if pkg.i_license
                s_new = replace(s, "🔒", "🔓");
                app.PerformGeneAgentAnalysisMenu.Text = s_new;
            end            
        end
        

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sce)
            app.UIFigure.Visible = "off";
            app.UIAxes.Visible = "off";
            if nargin<=1
                try
                    [fx, v1] = gui.sc_splashscreen;
                catch
                    [fx, v1] = gui.sc_simplesplash;
                end
            else
                v1 = pkg.i_get_versionnum;
            end
            try
                movegui(app.UIFigure, 'center');
            catch
            end
            app.UIFigure.Name = "SCGEATOOL v" + v1;
            in_UpdatePushToolMenu(app);
            app.UIFigure.Visible = "on";
            % drawnow;
            figure(app.UIFigure);

            % app.cm = uicontextmenu(app.UIFigure);
            
            goodsce = true;
            if nargin <= 1
                %app.ImportDataButton.Visible = "on";
                %app.ReadytoexploreLabel.Visible = "on";
                % [app.button1, app.button2] = gui.gui_createbuttons(app.UIFigure, ...
                %     @in_sc_openscedlg);
                % PushTool_ImportDataClicked(app, []);
                %d = uifigure('WindowStyle','modal','Visible','off');               
                [~, ~, ~, app.im] = pkg.i_majvercheck([false false false true]);
                drawnow;                
                delete(fx);
                focus(app.ImportDataButton);
                %delete(d);
            else
                if isa(sce, 'SingleCellExperiment')
                    app.ImportDataButton.Visible = "off";
                    app.ReadytoexploreLabel.Visible = "off";

                    if ~isscalar(unique(sce.c_cell_type_tx)) && isscalar(unique(sce.c))
                        [app.c, app.cL] = findgroups(string(sce.c_cell_type_tx));
                        sce.c = app.c;
                    else
                        [app.c, app.cL] = findgroups(string(sce.c));
                    end
                    
                    % if isMATLABReleaseOlderThan('R2025a')
                    %     if ~isspars(sce.X)
                    %         sce.X = sparse(sce.X);
                    %     end
                    % else
                    %     if ~isspars(sce.X)
                    %         sce.X = single(sparse(sce.X));
                    %     else
                    %         if ~isa(sce.X, "single")
                    %             sce.X = single(sce.X);
                    %         end
                    %     end
                    % end                    
                    app.sce = sce;
                    in_UpdateMainPlot(app);
                    ix_labelclusters(app, true);                    
                else               
                    goodsce = false;                    
                end
            end

            ts = datetime("now", "Format", "yyyyMMdd_HHmmss_SSS");
            timestamp = char(ts);        
            LogFilePath = fullfile(tempdir, ['scgeatoolappLog_' timestamp '.txt']);
            if exist(LogFilePath, 'file'); delete(app.LogFilePath); end
            app.LogFileID = fopen(LogFilePath,'a');            
            app.logMessage('App launched');
            %diary(app.LogFilePath);
            %diary on
            if ~isMATLABReleaseOlderThan('R2025a')
                if isdeployed
                    theme(app.UIFigure,"light");
                end
                style = app.UIFigure.Theme.BaseColorStyle;
                in_SwitchDarkLightIcons(app, style);                
                % switch style
                %     case "light"
                %         % do nothing
                %     case "dark"
                %         in_SwitchDarkLightIcons(app, style);
                % end
            end
            in_UpdateLockedFun(app);
            % function hGate
            if ~goodsce
                gui.myWarndlg(app.UIFigure, 'Invalid input SCE.', 'Error');
            end


            % "TimerFcn", @(~,~)app.AboutSCGEATOOLMenuSelected(), ...

            app.BackgroundTimer = timer( ...
                "StartDelay", 10, ...
                "TimerFcn", @(src,evt)pkg.geatoolUsageLogger(src, evt, v1), ...
                "ExecutionMode", "singleShot" ...
            );
            start(app.BackgroundTimer);
            
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            app.logMessage('App closing');
            if ~isempty(app.LogFileID)
                fclose(app.LogFileID);
            end

            if isvalid(app.BackgroundTimer)
                stop(app.BackgroundTimer);
                delete(app.BackgroundTimer);
            end
            
            % disp('Closing scgeatoolApp.');
            %{
            diary off
            % lines = regexp(fileread(dfile), '\r?\n', 'split');
            logFile = app.LogFilePath;
            if isfile(logFile)
                if ispc
                    winopen(logFile);
                elseif ismac
                    system(sprintf('open "%s" &', logFile));
                elseif isunix
                    system(sprintf('xdg-open "%s" &', logFile));
                end
            end
            %}
            delete(app);
        end

        % Callback function: ImportDataButton, ImportDataMenu, 
        % ...and 1 other component
        function PushTool_ImportDataClicked(app, event)
            in_sc_openscedlg(app);
            % [res] = gui.sc_openscedlg([], [], app.UIFigure);
            % if ~isempty(res)
            %     app.sce = res;  
            % end
            % [app.c, app.cL] = findgroups(string(app.sce.c));
            % in_UpdateMainPlot(app);
        end

        % Callback function: PushTool_ShowGeneExpr, SelectedGenesMenu
        function PushTool_ShowGeneExprClicked(app, event)
            gui.callback_ShowGeneExpr(app);
        end

        % Callback function: ExportSaveDataMenu, PushTool_SaveX
        function PushTool_SaveXClicked(app, event)
            gui.callback_SaveX(app);
        end

        % Callback function: MultiGroupingViewMenu, 
        % ...and 1 other component
        function PushTool_MultigroupingViewClicked(app, event)
            gui.callback_MultiGroupingView(app);
        end

        % Callback function: CrossTabulationMenu, PushTool_CrossTabulation
        function PushTool_CrossTabulationClicked(app, event)
            gui.callback_CrossTabulation(app);
        end

        % Callback function: FilterGenesCellsMenu,
        % PushTool_FilterGenesCells
        function PushTool_FilterGenesCellsClicked(app, event)
            try
                [requirerefresh, highlightindex] = ...
                    gui.callback_SelectCellsByQC(app);
            catch ME
                gui.myErrordlg(app.UIFigure, ME.message, ME.identifier);
                return;
            end
            if requirerefresh
                [app.c, app.cL] = findgroups(string(app.sce.c));                
                % in_UpdateMainPlot(app);
                in_RefreshAll(app, true, false);
            end
            if ~isempty(highlightindex)
                app.h.BrushData = highlightindex;
            end
            
        end

        % Callback function: LabelCellGroupsMenu, PushTool_LabelCellGroups
        function PushTool_LabelCellGroupsClicked(app, event)
            dtp = findobj(app.h, 'Type', 'datatip');
            if ~isempty(dtp) % switch from on to off
                delete(dtp);
            else
                [thisc, clabel] = gui.i_select1class(app.sce, true, ...
                    [], [], app.UIFigure);
                if isempty(thisc), return; end
                [app.c, app.cL] = findgroups(string(thisc));
                app.sce.c = app.c;
                in_UpdateMainPlot(app);
                fprintf('Cells are colored by %s.\n', lower(clabel));
                if max(app.c) > 200
                    gui.myWarndlg(app.UIFigure, ...
                        'Labels are not showing. Too many categories (n>200).');
                else
                    ix_labelclusters(app, true);
                end
            end
            
        end

        % Callback function: PushTool_ShowCellStates, ShowCellStatesMenu
        function PushTool_ShowCellStatesClicked(app, event)
            [thisc, clabel, ~, newpickclabel] = gui.i_select1state(app.sce, ...
                false, false, true, false, app.UIFigure);
            if isempty(thisc), return; end
            if strcmp(clabel, 'Cell Cycle Phase') && ~all(strcmp(unique(thisc), "undetermined"))
                app.sce.c_cell_cycle_tx = thisc;
            end        
            if strcmp(clabel, 'Workspace Variable...')
                clabel = gui.i_renamec(clabel, app.sce, newpickclabel, app.UIFigure);
                app.sce.list_cell_attributes = [app.sce.list_cell_attributes, clabel];
                app.sce.list_cell_attributes = [app.sce.list_cell_attributes, thisc];
            end
    
            switch gui.i_selvariabletype(thisc, app.UIFigure)
                case 'Categorical/Discrete'
                    [app.c, app.cL] = findgroups(string(thisc));
                    app.sce.c = app.c;
                    in_UpdateMainPlot(app);
                    target{1} = app.UIAxes;
                    n = max(app.c);
                    f = 0.5 * (n - 1) ./ n;
                    f = 1 + f .* (1:2:2 * n);
                    cb = colorbar(target{1}, 'Ticks', f, 'TickLabels', ...
                        strrep(cellstr(app.cL), '_', '\_'));
                    cb.Label.String = strrep(clabel, '_', '\_');
                    cbtogg = findall(app.UIFigure, 'Tag', 'figToglColorbar');
                    set(cbtogg,'State','on');                
                    row = dataTipTextRow('', strrep(cellstr(app.cL(app.c)),'_','\_'));
                    app.h.DataTipTemplate.DataTipRows = row;
                case {'Numerical/Continuous','Unknown'}
                    app.sce.c = thisc;
                    switch gui.myQuestdlg(app.UIFigure, 'Show scores in new figure?','', ...
                            {'Yes, new figure', 'No, current figure', 'Cancel'}, ...
                            'Yes, new figure')
                        case 'No, current figure'
                            in_UpdateMainPlot(app);
                            target{1} = app.UIAxes;
                            target{2} = app.h;
                            if isnumeric(thisc)
                                set(target{2}, 'CData', thisc);
                            else
                                [app.c, app.cL] = findgroups(string(thisc));
                                set(target{2}, 'CData', app.c);
                            end                     
                            cb = colorbar(app.UIAxes);
                            cb.Label.String = strrep(clabel, '_', '\_');
                            cbtogg = findall(app.UIFigure, 'Tag', 'figToglColorbar');
                            set(cbtogg,'State','on');
                        case 'Yes, new figure'
                            switch gui.myQuestdlg(app.UIFigure, 'Plot scatter plot type:','', ...
                                    {'Heat','Stem'},'Heat')
                                case 'Heat'
                                    figfun = @gui.i_heatscatterfig;
                                case 'Stem'
                                    figfun = @gui.i_stemscatterfig;
                                otherwise
                                    return;
                            end
                            if ~isnumeric(thisc)
                                thisc = findgroups(string(thisc));
                            end
                            figfun(app.sce, thisc, [], clabel, app.UIFigure);                            
                        otherwise
                            return;
                    end
                otherwise
                    return;
            end
        end

        % Callback function: PushTool_Refresh
        function PushTool_RefreshClicked(app, event)
            in_UpdateMainPlot(app);
        end

        % Callback function: NextColormapMenu, PushTool_PIckColormap
        function PushTool_PIckColormapClicked(app, event)
            gui.callback_PickColorMap(app);
        end

        % Callback function: EmbedCellsUsingtSNEUMPPHATEMenu, 
        % ...and 1 other component
        function PushTool_EmbedCellsClicked(app, event)
            in_EmbeddingAgain(app);
        end

        % Callback function: NextMarkerTypeMenu, PushTool_PickMarkerType
        function PushTool_PickMarkerTypeClicked(app, event)
            if app.h.Marker == '.'
                app.h.Marker = 'o';
                app.h.SizeData = 10 * randi(10);
            else
                app.h.Marker = '.';
                app.h.SizeData = 50 * randi(10);
            end
        end

        % Callback function: PushTool_Switch2D3DEmbeddings, 
        % ...and 1 other component
        function PushTool_Switch2D3DEmbeddingsClicked(app, event)
            [para] = gui.i_getoldsettings(app);
            [ax, bx] = view(app.UIAxes);
%            if bx == 90 && ~isempty(app.h.ZData)
%                view(app.UIAxes, 3);
%                return;
%            end
            if bx == 90   % isempty(h.ZData)               % current 2D            
                if ~strcmp(gui.myQuestdlg(app.UIFigure, 'Switch to 3D?',''), 'Yes'), return; end
                % if isvalid(app.UIFigure) && isa(app.UIFigure, 'matlab.ui.Figure'), figure(app.UIFigure); end
                if size(app.sce.s, 2) >= 3
                    app.h = gui.i_gscatter3(app.sce.s, app.c, 1, [], app.UIAxes);
                    if ~isempty(ax) && ~isempty(bx) && ~any([ax, bx] == 0)
                        view(app.UIAxes, ax, bx);
                    else
                        view(app.UIAxes, 3);
                    end
                else
                    [vslist] = gui.i_checkexistingembed(app.sce, 3);
                    if isempty(vslist)
                        if strcmp('Yes', gui.myQuestdlg(app.UIFigure, 'No 3D embedding is available. Embed cells?'))
                             in_EmbeddingAgain(app, 3);
                        else
                            return;
                        end
                    else
                        ansx = gui.myQuestdlg(app.UIFigure, 'Using existing 3D embedding? Select "No" to re-embed.');
                        if isempty(ansx), return; end
                        switch ansx
                            case 'Yes'
                                [sx] = gui.i_pickembedvalues(app.sce, 3, app.UIFigure);
                                if ~isempty(sx) && size(sx,1) == app.sce.NumCells
                                    app.sce.s = sx;
                                    in_UpdateMainPlot(app);
                                    view(app.UIAxes, 3);
                                else
                                    return;
                                end
                            case 'No'
                                in_EmbeddingAgain(app, 3);
                            case 'Cancel'
                                return;
                        end
                    end
                end
            else        % current 3D do following
                if ~strcmp(gui.myQuestdlg(app.UIFigure, 'Switch to 2D?',''), 'Yes'), return; end
                [vslist] = gui.i_checkexistingembed(app.sce, 2);
                
                if ~isempty(vslist)
                    answer = gui.myQuestdlg(app.UIFigure, 'How to make 2D embedding?','', ...
                        {'Pick existing 2D','Re-embed cells', ...
                        'Reduce current 3D'},'Pick existing 2D');
                else
                    answer = gui.myQuestdlg(app.UIFigure, 'How to make 2D embedding?','', ...
                        {'Embed Cells to 2D', ...
                        'Project Current 3D Embedding to 2D','Cancel'},'Embed Cells to 2D');
                end
                if isempty(answer), return; end
                switch answer
                    case 'Cancel'
                        return;
                    case 'Re-embed cells'
                         in_EmbeddingAgain(app, 2);
                    case {'Project Current 3D Embedding to 2D', 'Reduce current 3D'}
                        [ax, bx] = view(app.UIAxes);
                        answer2 = gui.myQuestdlg(app.UIFigure, 'Which view to be used to project 3D to 2D?', '', ...
                            {'X-Y Plane', 'Screen/Camera', 'PCA-Rotated'}, 'X-Y Plane');
                        switch answer2
                            case 'X-Y Plane'
                                sx = app.sce.s;
                            case 'Screen/Camera'
                                sx = pkg.i_3d2d(app.sce.s, ax, bx);
                            case {'PCA-Rotated'}
                                [~, sx] = pca(app.sce.s);
                            otherwise
                                return;
                        end
                        app.h = gui.i_gscatter3(sx(:, 1:2), app.c, ...
                            1, [], app.UIAxes);
                        app.sce.s = sx(:, 1:2);
                        title(app.UIAxes, app.sce.title);
                        subtitle(app.UIAxes, '[genes x cells]');
                        app.h.Marker = para.oldMarker;
                        app.h.SizeData = para.oldSizeData;
                        colormap(app.UIAxes,para.oldColorMap);
                        view(app.UIAxes, 2);
                        return;
                    case 'Pick existing 2D'
                        [sx] = gui.i_pickembedvalues(app.sce, 2, app.UIFigure);
                        if ~isempty(sx) && size(sx,1) == app.sce.NumCells
                            app.sce.s = sx;
                            in_UpdateMainPlot(app);
                            view(app.UIAxes, 2);
                        else
                            return;
                        end
                end
            end
            
        end

        % Callback function: AddBrushedCellstoaNewGroupMenu, 
        % ...and 1 other component
        function PushTool_AddBrushedToNewGroupClicked(app, event)
            [hasbrushed, ptsSelected] = i_checkbrusheddata(app);
            if ~hasbrushed, return; end
            [iscelltype] = in_pickcelltypeclusterid(app, 'Make a new cluster or new cell type group out of brushed cells?');
            if isempty(iscelltype), return; end
            if iscelltype
                n = sum(contains(unique(app.sce.c_cell_type_tx), "New cell type"));
                if n > 0
                    nname = sprintf('New cell type %d', n+1);
                else
                    nname = 'New cell type';
                end
                
                if gui.i_isuifig(app.UIFigure)                
                    newctype = gui.myInputdlg({'Enter new cell type name:'}, ...
                        '', {nname}, app.UIFigure);
                else
                    newctype = inputdlg('Enter new cell type name:', ...
                        '', [1, 50], {nname});
                end
    
                if isempty(newctype), return; end
                app.sce.c_cell_type_tx(ptsSelected) = string(newctype);
                [app.c, app.cL] = findgroups(string(app.sce.c_cell_type_tx));
            else
                app.c(ptsSelected) = max(app.c) + 1;
                [app.c, app.cL] = findgroups(string(app.c));
                app.sce.c_cluster_id = app.c;
            end
            app.sce.c = app.c;
            [ax, bx] = view(app.UIAxes);
            [app.h] = gui.i_gscatter3(app.sce.s, app.c, 1, [], app.UIAxes);
            title(app.UIAxes, app.sce.title);
            subtitle(app.UIAxes, '[genes x cells]');
            view(app.UIAxes, [ax, bx]);
            ix_labelclusters(app, true);         
        end

        % Callback function: MergeBrushedCellstoSameGroupMenu, 
        % ...and 1 other component
        function PushTool_MergeBrushedToSameGroupClicked(app, event)
            [hasbrushed, ptsSelected] = i_checkbrusheddata(app);
            if ~hasbrushed, return; end
            [iscelltype] = in_pickcelltypeclusterid(app,'Merge brushed cells into same cluster or same cell type?');
            if isempty(iscelltype), return; end
            if iscelltype
                c_members = unique(app.sce.c_cell_type_tx(ptsSelected));
            else
                c_members = unique(app.c(ptsSelected));
            end
    
            if isscalar(c_members)
                gui.myWarndlg(app.UIFigure, ...
                "All brushed cells are in one cluster or belong to the same cell type.",'');
                return;
            end
    
            if gui.i_isuifig(app.UIFigure)
                [indx, tf] = gui.myListdlg(app.UIFigure, string(c_members), ...
                    'Select target cluster');
            else
                [indx, tf] = listdlg('PromptString', ...
                    {'Select target cluster'}, 'SelectionMode', ...
                    'single', 'ListString', string(c_members), ...
                    'ListSize', [220, 300]);
            end
            if tf == 1
                c_target = c_members(indx);
            else
                return;
            end
    
            if iscelltype
                app.sce.c_cell_type_tx(ismember(app.sce.c_cell_type_tx, c_members)) = c_target;
                [app.c, app.cL] = findgroups(string(app.sce.c_cell_type_tx));
            else
                app.c(ismember(app.c, c_members)) = c_target;
                [app.c, app.cL] = findgroups(string(app.c));
                app.sce.c = app.c;
                app.sce.c_cluster_id = app.c;
            end
            [ax, bx] = view(app.UIAxes);
            [app.h] = gui.i_gscatter3(app.sce.s, app.c, 1, [], app.UIAxes);
            title(app.UIAxes, app.sce.title);
            subtitle(app.UIAxes, '[genes x cells]');
            view(app.UIAxes, [ax, bx]);
            ix_labelclusters(app, true);            
        end

        % Callback function: PushTool_ResizePlotWindow
        function PushTool_ResizePlotWindowClicked(app, event)
            gui.i_resizewin([],[],app.UIFigure);
        end

        % Callback function: PushTool_SaveFigToPowerPoint
        function PushTool_SaveFigToPowerPointClicked(app, event)
            gui.i_savemainfig(app, [], 3);
        end

        % Callback function: PushTool_MakeVideoSnapshot
        function PushTool_MakeVideoSnapshotClicked(app, event)
            answer = gui.myQuestdlg(app.UIFigure, 'Make video snapshot?');
            if ~strcmp(answer, 'Yes'), return; end
    
            [caz, cel] = view(app.UIAxes);        
            OptionZ.FrameRate = 15;
            OptionZ.Duration = 5.5;
            OptionZ.Periodic = true;
            fname = tempname;
            warning off
            flatview = rand>0.5;
            if flatview
                ax = [-20, 50; -110, 65; -190, 80; -290, 60; -380, 40];
            else
                ax = [-20, 10; -110, 10; -190, 80; -290, 10; -380, 10];
            end
            try
                xui_CaptureFigVid(app, ax, fname, OptionZ);
                % xui.CaptureFigVid(ax, fname, OptionZ, app.UIAxes, app.UIFigure);
            catch
               
            end
            view(app.UIAxes,caz,cel);
            warning on
            %pause(1);
            %winopen(tempdir);
            %pause(1);
            vfile = sprintf('%s.mp4', fname);
            if exist(vfile, 'file')
                winopen(vfile);
            else
                vfile = sprintf('%s.avi', fname);
                if exist(vfile, 'file')
                    winopen(vfile);
                end
            end
        end

        % Callback function: PushTool_RenameCellTypeBatchID, 
        % ...and 1 other component
        function PushTool_RenameCellTypeBatchIDClicked(app, event)
            answer = gui.myQuestdlg(app.UIFigure, 'Rename cell type, batch ID, or gene name?', ...
                '', {'Cell type', 'Batch ID', 'Others...'}, 'Cell type');
            switch answer
                case 'Cell type'
                    [requirerefresh] = gui.callback_RenameCellType(app);
                case 'Batch ID'
                    [requirerefresh] = gui.callback_RenameBatchID(app);
                case 'Gene name'
                    [requirerefresh] = gui.callback_RenameGenes(app);
                case 'Others...'
                    [requirerefresh, answer] = gui.callback_RenameOthers(app);
                otherwise
                    return;
            end
            if requirerefresh
                switch answer
                    case 'Cell type'
                        [app.c, app.cL] = findgroups(string(app.sce.c_cell_type_tx));
                    case 'Batch ID'
                        [app.c, app.cL] = findgroups(string(app.sce.c_batch_id));
                    case 'Cluster ID'
                        [app.c, app.cL] = findgroups(string(app.sce.c_cluster_id));
                    otherwise
                        return;
                end
                ix_labelclusters(app, true);
            end
            
        end

        % Off callback: ToggleTool_ShowColorbar
        function ToggleTool_ShowColorbarOff(app, event)
            colorbar(app.UIAxes, "off");
        end

        % On callback: ToggleTool_ShowColorbar
        function ToggleTool_ShowColorbarOn(app, event)
            colorbar(app.UIAxes);
        end

        % Menu selected function: AboutSCGEATOOLMenu
        function AboutSCGEATOOLMenuSelected(app, event)
            gui.sc_simpleabout(app.UIFigure, app.im);
        end

        % Menu selected function: PerformEnrichrAnalysisMenu
        function PerformEnrichrAnalysisMenuSelected(app, event)
            gui.callback_RunEnrichr(app, event);
        end

        % Menu selected function: SelectLargeLanguageModelMenu
        function SelectLargeLanguageModelMenuSelected(app, event)
            gui.i_setllmmodel(app);
        end

        % Menu selected function: SetWorkingFolderMenu
        function SetWorkingFolderMenuSelected(app, event)
            [done] = gui.i_setwrkdir('externalwrkpath');
            if done
                 gui.myHelpdlg(app.UIFigure, "External program working " + ...
                     "directory is set successfully.");
            end
        end

        % Menu selected function: ViewOnFileExchangeMenu
        function ViewOnFileExchangeMenuSelected(app, event)
            web('https://www.mathworks.com/matlabcentral/fileexchange/72917-scgeatoolbox-single-cell-gene-expression-analysis-toolbox');
        end

        % Callback function: GeneExpressionDotPlotMenu,
        % PushTool_GeneDotPlot
        function PushTool_GeneDotPlotClicked(app, event)
            gui.callback_Dotplot(app);
        end

        % Callback function: GeneExpressionHeatmapMenu,
        % PushTool_GeneHeatmap
        function PushTool_GeneHeatmapClicked(app, event)
            gui.callback_Heatmap(app);
        end

        % Callback function: GeneExpressionCellStateStemPlotMenu, 
        % ...and 1 other component
        function PushTool_ScatterStemPlotClicked(app, event)
            gui.callback_ScatterStemPlot(app);
        end

        % Callback function: PushTool_SingleClickAnnotation
        function PushTool_SingleClickAnnotationClicked(app, event)
            in_SingleClickSolution2(app);
        end

        % Callback function: PushTool_ClusterCellsS
        function PushTool_ClusterCellsSClicked(app, event)
            in_ClusterCellsS(app);
        end

        % Callback function: PushTool_CompareCellScoreBtwCls
        function PushTool_CompareCellScoreBtwClsClicked(app, event)
            gui.callback_CompareCellScoreBtwCls(app);
        end

        % Callback function: PushTool_DetermineCellTypeCluster
        function PushTool_DetermineCellTypeClusterClicked(app, event)
            in_DetermineCellTypeClustersGeneral(app, true);
        end

        % Callback function: GeneProgramRadarPlotMenu, 
        % ...and 1 other component
        function PushTool_GetCellSignatureMatrixClicked(app, event)
            gui.callback_GetCellSignatureMatrix(app);
        end

        % Callback function: PushTool_DEGene2Groups
        function PushTool_DEGene2GroupsClicked(app, event)
            st = dbstack;
            app.logMessage(st(1).name);            
            gui.callback_DEGene2Groups_New(app);
            % answer = gui.myQuestdlg(app.UIFigure, 'Select procedure.', ...
            %     '',{'Old', 'New'},{'New'});
            % switch answer
            %     case 'New'
            %         try
            %             if pkg.checkCoderLicense
            %                 app.logMessage(coder.mfunctionname);
            %             end
            %         catch
            %         end
            %         gui.callback_DEGene2Groups_New(app);
            %     case 'Old'
            %         st = dbstack;
            %         app.logMessage(st(1).name);
            %         gui.callback_DEGene2Groups(app);
            % end
        end

        % Callback function: PushTool_DVGene2Groups
        function PushTool_DVGene2GroupsClicked(app, event)
            st = dbstack;
            app.logMessage(st(1).name);
            gui.callback_DVGene2Groups(app);
        end

        % Callback function: PushTool_DPGene2Groups
        function PushTool_DPGene2GroupsClicked(app, event)
            st = dbstack;
            app.logMessage(st(1).name);
            gui.callback_DPGene2Groups(app);
        end

        % Callback function: PushTool_BuildGeneNetwork
        function PushTool_BuildGeneNetworkClicked(app, event)
            gui.callback_BuildGeneNetwork(app);
        end

        % Callback function: PushTool_CompareGeneNetwork
        function PushTool_CompareGeneNetworkClicked(app, event)
            gui.callback_CompareGeneNetwork(app);
        end

        % Callback function: PushTool_Brush4Celltypes
        function PushTool_Brush4CelltypesClicked(app, event)
            ptsSelected = logical(app.h.BrushData.');
            if ~any(ptsSelected)
                gui.myHelpdlg(app.UIFigure,...
                    "No cells are selected. Please use the data brush tool to " + ...
                    "select cells for cell type assignment.");
                return;
            end        
            if ~strcmp(gui.myQuestdlg(app.UIFigure, ...
                    ['This is a one-time analysis. Cell type labels will ' ...
                    'not be saved. Continue?']), 'Yes')
                return;
            end
            if ~exist('speciestag', 'var') || isempty(app.speciestag)
                app.speciestag = gui.i_selectspecies(2, false, app.UIFigure);
            end
            if isempty(app.speciestag), return; end
            fw = gui.myWaitbar(app.UIFigure);
            [Tct] = pkg.local_celltypebrushed(app.sce.X, app.sce.g, app.sce.s, ...
                ptsSelected, ...
                app.speciestag, "all", "panglaodb", false);
            ctxt = Tct.C1_Cell_Type;
            gui.myWaitbar(app.UIFigure,fw);
    
            if gui.i_isuifig(app.UIFigure)
                [indx, tf] = gui.myListdlg(app.UIFigure, ctxt, 'Select cell type');
            else        
                [indx, tf] = listdlg('PromptString', ...
                    {'Select cell type'}, 'SelectionMode', 'single', ...
                    'ListString', ctxt, 'ListSize', [220, 300]);
            end
            if tf == 1
                ctxt = Tct.C1_Cell_Type{indx};
            else
                return;
            end
            ctxt = strrep(ctxt, '_', '\_');
            delete(findall(app.UIFigure, 'Type', 'hggroup'));
            if ~exist('tmpcelltypev', 'var') || length(app.tmpcelltypev) ~= app.sce.NumCells
                app.tmpcelltypev = cell(app.sce.NumCells, 1);            
            end
            siv = app.sce.s(ptsSelected, :);
            si = mean(app.sce.s(ptsSelected, :));
            [k] = dsearchn(siv, si);
            idx = find(ptsSelected);
            app.tmpcelltypev{idx(k)} = ctxt;
            rowx = dataTipTextRow('', app.tmpcelltypev);
            app.h.DataTipTemplate.DataTipRows = rowx;
            datatip(app.h, 'DataIndex', idx(k));            
        end

        % Callback function: PushTool_Brush4Markers
        function PushTool_Brush4MarkersClicked(app, event)
            gui.callback_Brush4Markers(app);
        end

        % Callback function: PushTool_FindAllMarkers
        function PushTool_FindAllMarkersClicked(app, event)
            gui.callback_FindAllMarkers(app);
        end

        % Callback function: PushTool_ShowClustersPop, 
        % ...and 1 other component
        function PushTool_ShowClustersPopClicked(app, event)
            gui.callback_ShowClustersPop(app);
        end

        % Callback function: PushTool_SelectCellsByClass
        function PushTool_SelectCellsByClassClicked(app, event)
            gui.callback_SelectCellsByClass(app);
        end

        % Callback function: PushTool_DeleteSelectedCells
        function PushTool_DeleteSelectedCellsClicked(app, event)
            ptsSelected = logical(app.h.BrushData.');
            if ~any(ptsSelected)
                gui.myWarndlg(app.UIFigure, "No cells are selected.");
                return;
            end
            [ptsSelected, letdoit] = gui.i_expandbrushed(ptsSelected, ...
                app.sce, app.UIFigure);
            if ~letdoit, return; end
    
            answer = gui.myQuestdlg(app.UIFigure, 'Delete selected or unselected cells?', '', ...
                {'Selected', 'Unselected'}, 'Selected');
            if isempty(answer), return; end
            if strcmp(answer, 'Unselected')
                in_deletecells(app, ~ptsSelected);
            elseif strcmp(answer, 'Selected')
                in_deletecells(app, ptsSelected);
            else
                return;
            end         
        end

        % Menu selected function: OnlineDocumentationMenu
        function OnlineDocumentationMenuSelected(app, event)
             web('https://scgeatoolbox.readthedocs.io/en/latest/');
        end

        % Menu selected function: ShortcutsUserGuideMenu
        function ShortcutsUserGuideMenuSelected(app, event)
            gui.gui_showrefinfo('Shortcuts Guide',app.UIFigure);
        end

        % Menu selected function: PapersCitingscGEAToolboxMenu
        function PapersCitingscGEAToolboxMenuSelected(app, event)
            web('https://scholar.google.com/scholar?cites=4661048952867744439&as_sdt=5,44&sciodt=0,44&hl=en');
        end

        % Menu selected function: VisitSCGEATOOLStandaloneWebsiteMenu
        function VisitSCGEATOOLStandaloneWebsiteMenuSelected(app, event)
            web('https://scgeatool.github.io/');
        end

        % Menu selected function: RunSCGEATOOLinMATLABOnlineMenu
        function RunSCGEATOOLinMATLABOnlineMenuSelected(app, event)
            web('https://matlab.mathworks.com/open/github/v1?repo=jamesjcai/scGEAToolbox&file=online_landing.m');
        end

        % Menu selected function: CitescGEAToolboxPaperMenu
        function CitescGEAToolboxPaperMenuSelected(app, event)
            web('https://pubmed.ncbi.nlm.nih.gov/31697351/');
        end

        % Menu selected function: PlotGRNfromEdgeGenePairListMenu
        function PlotGRNfromEdgeGenePairListMenuSelected(app, event)
            gui.callback_DrawNetwork(app);
        end

        % Menu selected function: CleantSNEUMAPPHATEPlotMenu
        function CleantSNEUMAPPHATEPlotMenuSelected(app, event)
            answer = gui.myQuestdlg(app.UIFigure,'Select embedding method label.', ...
                '',{'tSNE','UMAP','PHATE'},'tSNE');
            if isempty(answer), return; end
            a = colormap(app.UIAxes);
            ax2 = gui.i_baredrplot(app.UIAxes, [], answer, app.UIFigure);
            colormap(ax2,a);

            %{
            pause(3)
            fx = gui.myFigure(app.UIFigure);
            %fig2 = fx.FigHandle;
            ax2 = fx.AxHandle;
            gui.copyUIAxesToFigure(app.UIAxes, ax2);       
            fx.show; %(app.UIFigure);
            %fig2.WindowStyle="modal";
            set(ax2, 'XColor', 'none', 'YColor', 'none', 'ZColor', 'none');
            grid(ax2, 'off');
            colormap(ax2, a);  
            %}
        end

        % Menu selected function: CellkNNNetworkMenu
        function CellkNNNetworkMenuSelected(app, event)
            k = gui.i_inputnumk(4, 0, [], [], app.UIFigure);
            if isempty(k), return; end            
            % fw = gui.myWaitbar(app.UIFigure);            
            %set(0, 'CurrentFigure', app.UIFigure);
            %figure('WindowStyle', 'modal');
            %hx = gui.myFigure(app.UIFigure);
            % figure;
            fw = gui.myWaitbar(app.UIFigure);
            [A] = sc_knngraph(app.sce.s, k, false, 1, app.UIFigure);
            a = string(app.sce.c_cell_id);
            a = strrep(a, '_', '\_');
            G = graph(A, a);
            p = gui.i_singlegraph(G, '', app.UIFigure);
            p.XData = app.sce.s(:, 1)';
            p.YData = app.sce.s(:, 2)';
            gui.myWaitbar(app.UIFigure, fw);


            % sc_knngraph(app.sce.s, k, true, 1, hx.FigHandle);
            %hx.show(app.UIFigure);

            % gui.myWaitbar(app.UIFigure, fw);
        end

        % Menu selected function: CellTrajectoryMenu
        function CellTrajectoryMenuSelected(app, event)
            in_DrawTrajectory(app, event);
        end

        % Callback function: GeneExpressionCellStateViolinPlotMenu, 
        % ...and 1 other component
        function PushTool_ViolinplotClicked(app, event)
            gui.callback_Violinplot(app);
        end

        % Callback function: CorrelationPlotMenu, PushTool_ScatterCorrPlot
        function PushTool_ScatterCorrPlotClicked(app, event)
            gui.callback_ScatterCorrPlot(app);
        end

        % Callback function: PushTool_ShowGeneExprGroup
        function PushTool_ShowGeneExprGroupClicked(app, event)
            gui.callback_ShowGeneExprGroup(app);
        end

        % Menu selected function: MergeSCEDataVariablesinWorkspaceMenu
        function MergeSCEDataVariablesinWorkspaceMenuSelected(app, event)
            in_MergeSCEs(app, 1);
        end

        % Menu selected function: MergeSCEDataFilesMenu
        function MergeSCEDataFilesMenuSelected(app, event)
            in_MergeSCEs(app, 2);
        end

        % Menu selected function: AddEditCellAttributesMenu
        function AddEditCellAttributesMenuSelected(app, event)
            % Add/Edit Cell Attributes... 
            in_AddEditCellAttribs(app);
        end

        % Menu selected function: ExportCellAttributeTableMenu
        function ExportCellAttributeTableMenuSelected(app, event)
            T = pkg.makeattributestable(app.sce);
            gui.i_exporttable(T,true, ...
                "Tcellattrib","CellAttribTable",[],[],app.UIFigure);
        end

        % Menu selected function: ExtractCellsbyMarkerExpressionMenu
        function ExtractCellsbyMarkerExpressionMenuSelected(app, event)
            gui.callback_SelectCellsByMarker(app);
        end

        % Menu selected function: MergeSubclustersofSameCellTypeMenu
        function MergeSubclustersofSameCellTypeMenuSelected(app, event)
            in_MergeSubCellTypes(app);
        end

        % Menu selected function: SelectGenestoWorkonMenu
        function SelectGenestoWorkonMenuSelected(app, event)
            in_WorkonSelectedGenes(app, 'name');
        end

        % Menu selected function: SelectHighlyVariableGenesHVGstoWorkonMenu
        function SelectHighlyVariableGenesHVGstoWorkonMenuSelected(app, event)
            in_WorkonSelectedGenes(app, 'hvg');
        end

        % Menu selected function: SelectLigandReceptorGenestoWorkonMenu
        function SelectLigandReceptorGenestoWorkonMenuSelected(app, event)
            in_WorkonSelectedGenes(app, 'ligandreceptor');
        end

        % Menu selected function: Subsample50CellstoWorkonMenu
        function Subsample50CellstoWorkonMenuSelected(app, event)
            in_SubsampleCells(app, []);
        end

        % Menu selected function: DeleteBrushedCellsMenu
        function DeleteBrushedCellsMenuSelected(app, event)
            in_DeleteBrushedOrUnbrushedCells(app, 'brushed');
        end

        % Menu selected function: DeleteUnbrushedCellsMenu
        function DeleteUnbrushedCellsMenuSelected(app, event)
            in_DeleteBrushedOrUnbrushedCells(app, 'unbrushed');
        end

        % Menu selected function: SelectCellsbyClassOpeninNewWindowMenu
        function SelectCellsbyClassOpeninNewWindowMenuSelected(app, event)
            gui.callback_SelectCellsByClass(app);
        end

        % Menu selected function: ClusterCellsUsingExpressionMatrixXMenu
        function ClusterCellsUsingExpressionMatrixXMenuSelected(app, event)
            in_ClusterCellsX(app);
        end

        % Menu selected function: ClusterCellsUsingCellEmbeddingSMenu
        function ClusterCellsUsingCellEmbeddingSMenuSelected(app, event)
            in_ClusterCellsS(app);
        end

        % Menu selected function: ViewMetadataMenu
        function ViewMetadataMenuSelected(app, event)
            gui.callback_ViewMetaData(app);
        end

        % Menu selected function: HemoglobinHgbGenesMenu
        function HemoglobinHgbGenesExpressionMenuSelected(app, event)
            gui.callback_ShowHgBGeneExpression(app);
        end

        % Menu selected function: MitochondrialMtGenesMenu
        function MitochondrialMtGenesExpressionMenuSelected(app, event)
            gui.callback_ShowMtGeneExpression(app);
        end

        % Menu selected function: CellQCMetricsinViolinPlotsMenu
        function CellQCMetricsinViolinPlotsMenuSelected(app, event)
            gui.i_qcviolin(app.sce.X, app.sce.g, app.UIFigure);
        end

        % Menu selected function: CloseAllOtherFiguresMenu
        function CloseAllOtherFiguresMenuSelected(app, event)
            gui.callback_CloseAllOthers(app);
        end

        % Menu selected function: RefreshCurrentViewMenu
        function RefreshCurrentViewMenuSelected(app, event)
            in_RefreshAll(app);
        end

        % Menu selected function: SidebySideGeneExpressionMenu
        function SidebySideGeneExpressionMenuSelected(app, event)
            gui.callback_ShowGeneExprCompr(app);
        end

        % Menu selected function: EnrichrResultTabletoCircosPlotMenu
        function EnrichrResultTabletoCircosPlotMenuSelected(app, event)
            gui.callback_EnrichrTab2Circos(app);
        end

        % Menu selected function: AnnotateCellTypesUsingPanglaoDBMenu
        function AnnotateCellTypesUsingPanglaoDBMenuSelected(app, event)
            in_DetermineCellTypeClustersGeneral(app, true);
        end

        % Menu selected function: 
        % AnnotateCellTypesUsingCustomizedMarkersMenu
        function AnnotateCellTypesUsingCustomizedMarkersMenuSelected(app, event)
            in_DetermineCellTypeClustersGeneral(app, false);
        end

        % Menu selected function: 
        % ImportCellAnnotationfromSCEinWorkspaceMenu
        function ImportCellAnnotationfromSCEinWorkspaceMenuSelected(app, event)
            in_MergeCellSubtypes(app, 1);
        end

        % Menu selected function: ImportCellAnnotationfromSCEDataFileMenu
        function ImportCellAnnotationfromSCEDataFileMenuSelected(app, event)
            in_MergeCellSubtypes(app, 2);
        end

        % Menu selected function: AnnotateCellTypesforBrushedCellsMenu
        function AnnotateCellTypesforBrushedCellsMenuSelected(app, event)
            gui.callback_Brush4Markers(app);
        end

        % Menu selected function: FindMarkerGenesforBrushedCellsMenu
        function FindMarkerGenesforBrushedCellsMenuSelected(app, event)
            gui.callback_FindAllMarkers(app);
        end

        % Menu selected function: EstimateCellCyclePhaseMenu
        function EstimateCellCyclePhaseMenuSelected(app, event)
            gui.callback_CellCyclePotency(app, [], 1);
        end

        % Menu selected function: 
        % EstimateDifferentiationPotencyPMID33244588Menu
        function EstimateDifferentiationPotencyPMID33244588MenuSelected(app, event)
            gui.callback_CellCyclePotency(app, [], 2);
        end

        % Menu selected function: EstimateStemnessPMID29625051Menu
        function EstimateStemnessPMID29625051MenuSelected(app, event)
            gui.callback_CellCyclePotency(app, [], 3);
        end

        % Menu selected function: 
        % EstimateDissociationGeneRatioPMID34020534Menu
        function EstimateDissociationGeneRatioPMID34020534MenuSelected(app, event)
            gui.callback_CellCyclePotency(app, [], 4);
        end

        % Menu selected function: SingleClickSolutionAnnotationMenu
        function SingleClickSolutionAnnotationMenuSelected(app, event)
            in_SingleClickSolution(app);
        end

        % Menu selected function: GeneExpressionStatisticsMenu
        function GeneExpressionStatisticsMenuSelected(app, event)
            gui.callback_CalculateGeneStats(app);
        end

        % Menu selected function: 
        % GeneVariabilityHVGFunctionAnalysisPMID31861624Menu
        function GeneVariabilityHVGFunctionAnalysisPMID31861624MenuSelected(app, event)
            in_EnrichrHVGs(app);
        end

        % Menu selected function: DEAnalysisinCellTypeBatchModeMenu
        function DEAnalysisinCellTypeBatchModeMenuSelected(app, event)
            gui.callback_DEGene2GroupsBatch(app);
        end

        % Menu selected function: DVAnalysisinCellTypeBatchModeMenu
        function DVAnalysisinCellTypeBatchModeMenuSelected(app, event)
            gui.callback_DVGene2GroupsBatch(app);
        end

        % Menu selected function: DPAnalysisinCellTypeBatchModeMenu
        function DPAnalysisinCellTypeBatchModeMenuSelected(app, event)
            gui.callback_DPGene2GroupsBatch(app);
        end

        % Menu selected function: AllDEDVDPAnalysisinCellTypeBatchModeMenu
        function AllDEDVDPAnalysisinCellTypeBatchModeMenuSelected(app, event)
            gui.callback_DEVP2GroupsBatch(app);
        end

        % Menu selected function: DifferentialExpressionDEAnalysisMenu
        function DifferentialExpressionDEAnalysisMenuSelected(app, event)
            %gui.callback_DEGene2Groups_New(app);
            PushTool_DEGene2GroupsClicked(app, event);
        end

        % Menu selected function: 
        % DifferentialVariabilityDVAnalysisPMID40113778Menu
        function DifferentialVariabilityDVAnalysisPMID40113778MenuSelected(app, event)
            gui.callback_DVGene2Groups(app);
        end

        % Menu selected function: DifferentialProgramDPAnalysisMenu
        function DifferentialProgramDPAnalysisMenuSelected(app, event)
            gui.callback_DPGene2Groups(app);
        end

        % Menu selected function: LASSOFeatureSelectionMenu
        function LASSOFeatureSelectionMenuSelected(app, event)
            gui.callback_LASSO(app);
        end

        % Menu selected function: QUBOFeatureSelectionPMID40832053Menu
        function QUBOFeatureSelectionPMID40832053MenuSelected(app, event)
            gui.callback_QUBOFeatureSelection(app);
        end

        % Menu selected function: RemoveGenestoReduceGeneSpaceMenu
        function RemoveGenestoReduceGeneSpaceMenuSelected(app, event)
            in_Select5000Genes(app);
        end

        % Menu selected function: BuildGRNSelectedGenesMenu
        function BuildGRNSelectedGenesMenuSelected(app, event)
            gui.callback_BuildGeneNetwork(app);
        end

        % Menu selected function: BuildCompareTwoGRNsSelectedGenesMenu
        function BuildCompareTwoGRNsSelectedGenesMenuSelected(app, event)
            gui.callback_CompareGeneNetwork(app);
        end

        % Menu selected function: ConstructGRNwithAllGenesscTenifoldNetMenu
        function ConstructGRNwithAllGenesscTenifoldNetMenuSelected(app, event)
            in_scTenifoldNet(app, 1);
        end

        % Menu selected function: ConstructCompareGRNsscTenifoldNetMenu
        function ConstructCompareGRNsscTenifoldNetMenuSelected(app, event)
            in_scTenifoldNet(app, 2);
        end

        % Menu selected function: VirtualGeneKnockoutscTenifoldKnkMenu
        function VirtualGeneKnockoutscTenifoldKnkMenuSelected(app, event)
            gui.callback_scTenifoldKnk1(app);
        end

        % Menu selected function: VirtualGeneKnockoutGenKIMenu
        function VirtualGeneKnockoutGenKIMenuSelected(app, event)
            gui.callback_VirtualKOGenKI(app);
        end

        % Menu selected function: OneSampleTenifoldXctMenu
        function OneSampleTenifoldXctMenuSelected(app, event)
            gui.callback_scTenifoldXct(app);
        end

        % Menu selected function: TwoSampleTenifoldXct2Menu
        function TwoSampleTenifoldXct2MenuSelected(app, event)
            gui.callback_scTenifoldXct2(app);
        end

        % Menu selected function: VirtualscTenifoldCkoMenu
        function VirtualscTenifoldCkoMenuSelected(app, event)
            gui.callback_scTenifoldCko(app);
        end

        % Menu selected function: SetRandomSeedMenu
        function SetRandomSeedMenuSelected(app, event)
            gui.i_resetrngseed(app);
        end

        % Menu selected function: LocateSCimilarityModelFilesMenu
        function LocateSCimilarityModelFilesMenuSelected(app, event)
            gui.i_setscimilaritymodelpath(app);
        end

        % Menu selected function: InstallQuantumComputingPackageMenu
        function InstallQuantumComputingPackageMenuSelected(app, event)
            gui.i_setqtmservice(app);
        end

        % Menu selected function: SetUpREnvironmentMenu
        function SetUpREnvironmentMenuSelected(app, event)
            gui.i_setrenv(app);
        end

        % Menu selected function: SetUpPythonEnvironmentMenu
        function SetUpPythonEnvironmentMenuSelected(app, event)
            gui.i_setpyenv(app, [], app.UIFigure);
        end

        % Menu selected function: UMAPtSNEEmbeddingMenu
        function UMAPtSNEEmbeddingUsingSeuratMenuSelected(app, event)
            in_RunSeuratWorkflow(app);
        end

        % Menu selected function: 
        % SCTransformv2RegularizationPMID35042561Menu
        function SCTransformv2RegularizationUsingSeuratMenuSelected(app, event)
            gui.callback_RunSeuratSCTransform(app);
        end

        % Menu selected function: PseudotimeAnalysisMonocle3Menu
        function PseudotimeAnalysisMonocle3MenuSelected(app, event)
            in_RunMonocle3(app);
        end

        % Menu selected function: AneuploidDiploidAnalysiscopykatMenu
        function AneuploidDiploidAnalysiscopykatMenuSelected(app, event)
            gui.callback_CellCyclePotency(app, [], 5);
        end

        % Menu selected function: VariationalAneuploidyAnalysisSCEVANMenu
        function VariationalAneuploidyAnalysisSCEVANMenuSelected(app, event)
            gui.callback_CellCyclePotency(app, [], 6);
        end

        % Menu selected function: DetectAmbientRNAContaminationDecontXMenu
        function DetectAmbientRNAContaminationDecontXMenuSelected(app, event)
            in_DecontX(app);
        end

        % Menu selected function: MementoDEDVAnalysisPMID39454576Menu
        function MementoDEDVAnalysisPMID39454576MenuSelected(app, event)
            gui.callback_RunMemento(app);
        end

        % Menu selected function: DetectDoubletsScrubletPMID30954476Menu
        function DetectDoubletsScrubletPMID30954476MenuSelected(app, event)
            in_DoubletDetection(app);
        end

        % Menu selected function: BatchIntegrationHarmonyPMID31740819Menu
        function BatchIntegrationHarmonyPMID31740819MenuSelected(app, event)
            in_HarmonyR(app, true);
        end

        % Menu selected function: MELDPerturbationScoreMELDMenu
        function MELDPerturbationScoreMELDMenuSelected(app, event)
            gui.callback_MELDPerturbationScore(app);
        end

        % Menu selected function: SCimilarityPMID39566551Menu
        function AnnotateCellTypesSCimilarityPMID39566551MenuSelected(app, event)
            in_SCimilarity(app);
        end

        % Menu selected function: PanHumanAzimuthsatijalaborgMenu
        function AnnotateCellTypesPanHumanAzimuthsatijalaborgMenuSelected(app, event)
            in_Panhumanpy(app);
        end

        % Menu selected function: CheckforUpdatesMenu
        function CheckforUpdatesMenuSelected(app, event)
            gui.callback_CheckUpdates(app);
        end

        % Menu selected function: GeneProgramCellScoreAnalysisMenu
        function GeneProgramCellScoreAnalysisMenuSelected(app, event)
            gui.callback_CompareCellScoreBtwCls(app);
        end

        % Menu selected function: PreselectLibrariesEnrichrAnalysisMenu
        function Libraries4EnrichrMenuSelected(app, event)
            gui.callback_SelectEnrichrLibraries(app);
        end

        % Menu selected function: GeneProgramCorrMatMenu
        function GeneProgramCorrMatMenuSelected(app, event)
            gui.callback_CellScoreCorrMatrix(app);
        end

        % Menu selected function: TwogeneCooccurrenceTestMenu
        function TwogeneCooccurrenceTestMenuSelected(app, event)
            gui.callback_2GeneCooccurrenceTest(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            CloseMenuSelected(app, event);
        end

        % Menu selected function: ShowGridMenu
        function ShowGridMenuSelected(app, event)
            if strcmp(app.ShowGridMenu.Checked, 'on')
                grid(app.UIAxes, 'off');
                app.ShowGridMenu.Checked = 'off';
            else
                grid(app.UIAxes, 'on');
                app.ShowGridMenu.Checked = 'on';
            end            
        end

        % Callback function: PushTool_LightDarkMode
        function PushTool_LightDarkModeClicked(app, event)
            if isMATLABReleaseOlderThan('R2025a')
                gui.myErrordlg(app.UIFigure, "Dark mode is only available in MATLAB 2025a and newer.");
            else
                % pathToMLAPP = fileparts(mfilename('fullpath'));
                style = app.UIFigure.Theme.BaseColorStyle;
                switch style
                    case "light"
                        theme(app.UIFigure, "dark");
                    case "dark"
                        theme(app.UIFigure, "light");
                end
                in_SwitchDarkLightIcons(app, style, true);
            end
        end

        % Menu selected function: TalklrIntercellularCrosstalkMenu
        function TalklrIntercellularCrosstalkMenuSelected(app, event)
            gui.callback_ExploreCellularCrosstalk(app);
        end

        % Menu selected function: PerformGeneAgentAnalysisMenu
        function PerformGeneAgentAnalysisMenuSelected(app, event)
            gui.callback_RunGeneAgent(app, event);
        end

        % Menu selected function: ValidatePasskeyMenu
        function ValidatePasskeyMenuSelected(app, event)
            gui.callback_Passkey(app, event);
            in_UpdateLockedFun(app);
        end

        % Menu selected function: BatchIntegrationHarmonyRMenu
        function BatchIntegrationHarmonyRMenuSelected(app, event)
            in_HarmonyR(app, false);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCGEATOOL APP';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'icon_24.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = '&File';
            app.ImportDataMenu = uimenu(app.FileMenu);
            app.ImportDataMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_ImportDataClicked, true);
            app.ImportDataMenu.Accelerator = 'i';
            app.ImportDataMenu.Text = '&Import Data...';
            app.ExportSaveDataMenu = uimenu(app.FileMenu);
            app.ExportSaveDataMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_SaveXClicked, true);
            app.ExportSaveDataMenu.Accelerator = 's';
            app.ExportSaveDataMenu.Text = 'Export/Save Data...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Separator = 'on';
            app.CloseMenu.Accelerator = 'w';
            app.CloseMenu.Text = 'Close';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = '&Edit';
            app.FilterGenesCellsMenu = uimenu(app.EditMenu);
            app.FilterGenesCellsMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_FilterGenesCellsClicked, true);
            app.FilterGenesCellsMenu.Accelerator = 'f';
            app.FilterGenesCellsMenu.Text = '&Filter Genes && Cells...';
            app.AddBrushedCellstoaNewGroupMenu = uimenu(app.EditMenu);
            app.AddBrushedCellstoaNewGroupMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_AddBrushedToNewGroupClicked, true);
            app.AddBrushedCellstoaNewGroupMenu.Separator = 'on';
            app.AddBrushedCellstoaNewGroupMenu.Text = 'Add Brushed Cells to a New Group';
            app.MergeBrushedCellstoSameGroupMenu = uimenu(app.EditMenu);
            app.MergeBrushedCellstoSameGroupMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_MergeBrushedToSameGroupClicked, true);
            app.MergeBrushedCellstoSameGroupMenu.Text = 'Merge Brushed Cells to Same Group';
            app.RenameCellTypeorBatchIDMenu = uimenu(app.EditMenu);
            app.RenameCellTypeorBatchIDMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_RenameCellTypeBatchIDClicked, true);
            app.RenameCellTypeorBatchIDMenu.Text = 'Rename Cell Type or Batch ID...';
            app.MergeSCEDataVariablesinWorkspaceMenu = uimenu(app.EditMenu);
            app.MergeSCEDataVariablesinWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @MergeSCEDataVariablesinWorkspaceMenuSelected, true);
            app.MergeSCEDataVariablesinWorkspaceMenu.Tooltip = {'Merge SCE Data Variables in Workspace...'};
            app.MergeSCEDataVariablesinWorkspaceMenu.Separator = 'on';
            app.MergeSCEDataVariablesinWorkspaceMenu.Text = 'Merge SCE Data Variables in Workspace...';
            app.MergeSCEDataFilesMenu = uimenu(app.EditMenu);
            app.MergeSCEDataFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @MergeSCEDataFilesMenuSelected, true);
            app.MergeSCEDataFilesMenu.Tooltip = {'Merge SCE Data Files...'};
            app.MergeSCEDataFilesMenu.Text = 'Merge SCE Data Files...';
            app.AddEditCellAttributesMenu = uimenu(app.EditMenu);
            app.AddEditCellAttributesMenu.MenuSelectedFcn = createCallbackFcn(app, @AddEditCellAttributesMenuSelected, true);
            app.AddEditCellAttributesMenu.Separator = 'on';
            app.AddEditCellAttributesMenu.Text = 'Add/Edit Cell Attributes...';
            app.ExportCellAttributeTableMenu = uimenu(app.EditMenu);
            app.ExportCellAttributeTableMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportCellAttributeTableMenuSelected, true);
            app.ExportCellAttributeTableMenu.Text = 'Export Cell Attribute Table...';
            app.ExtractCellsbyMarkerExpressionMenu = uimenu(app.EditMenu);
            app.ExtractCellsbyMarkerExpressionMenu.MenuSelectedFcn = createCallbackFcn(app, @ExtractCellsbyMarkerExpressionMenuSelected, true);
            app.ExtractCellsbyMarkerExpressionMenu.Separator = 'on';
            app.ExtractCellsbyMarkerExpressionMenu.Text = 'Extract Cells by Marker (+/-) Expression...';
            app.MergeSubclustersofSameCellTypeMenu = uimenu(app.EditMenu);
            app.MergeSubclustersofSameCellTypeMenu.MenuSelectedFcn = createCallbackFcn(app, @MergeSubclustersofSameCellTypeMenuSelected, true);
            app.MergeSubclustersofSameCellTypeMenu.Text = 'Merge Subclusters of Same Cell Type';
            app.SelectGenestoWorkonMenu = uimenu(app.EditMenu);
            app.SelectGenestoWorkonMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectGenestoWorkonMenuSelected, true);
            app.SelectGenestoWorkonMenu.Separator = 'on';
            app.SelectGenestoWorkonMenu.Text = 'Select Genes to Work on...';
            app.SelectHighlyVariableGenesHVGstoWorkonMenu = uimenu(app.EditMenu);
            app.SelectHighlyVariableGenesHVGstoWorkonMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectHighlyVariableGenesHVGstoWorkonMenuSelected, true);
            app.SelectHighlyVariableGenesHVGstoWorkonMenu.Text = 'Select Highly Variable Genes (HVGs) to Work on...';
            app.SelectLigandReceptorGenestoWorkonMenu = uimenu(app.EditMenu);
            app.SelectLigandReceptorGenestoWorkonMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectLigandReceptorGenestoWorkonMenuSelected, true);
            app.SelectLigandReceptorGenestoWorkonMenu.Text = 'Select Ligand Receptor Genes to Work on...';
            app.Subsample50CellstoWorkonMenu = uimenu(app.EditMenu);
            app.Subsample50CellstoWorkonMenu.MenuSelectedFcn = createCallbackFcn(app, @Subsample50CellstoWorkonMenuSelected, true);
            app.Subsample50CellstoWorkonMenu.Text = 'Subsample 50% Cells to Work on...';
            app.DeleteBrushedCellsMenu = uimenu(app.EditMenu);
            app.DeleteBrushedCellsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteBrushedCellsMenuSelected, true);
            app.DeleteBrushedCellsMenu.Separator = 'on';
            app.DeleteBrushedCellsMenu.Text = 'Delete Brushed Cells...';
            app.DeleteUnbrushedCellsMenu = uimenu(app.EditMenu);
            app.DeleteUnbrushedCellsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteUnbrushedCellsMenuSelected, true);
            app.DeleteUnbrushedCellsMenu.Text = 'Delete Unbrushed Cells...';
            app.SelectCellsbyClassOpeninNewWindowMenu = uimenu(app.EditMenu);
            app.SelectCellsbyClassOpeninNewWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectCellsbyClassOpeninNewWindowMenuSelected, true);
            app.SelectCellsbyClassOpeninNewWindowMenu.Separator = 'on';
            app.SelectCellsbyClassOpeninNewWindowMenu.Text = 'Select Cells by Class && Open in New Window...';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.EmbedCellsUsingtSNEUMPPHATEMenu = uimenu(app.ViewMenu);
            app.EmbedCellsUsingtSNEUMPPHATEMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_EmbedCellsClicked, true);
            app.EmbedCellsUsingtSNEUMPPHATEMenu.Accelerator = 'b';
            app.EmbedCellsUsingtSNEUMPPHATEMenu.Text = 'Embed Cells Using tSNE, UMP, PHATE...';
            app.SwitchBetween2D3DEmbeddingsMenu = uimenu(app.ViewMenu);
            app.SwitchBetween2D3DEmbeddingsMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_Switch2D3DEmbeddingsClicked, true);
            app.SwitchBetween2D3DEmbeddingsMenu.Text = 'Switch Between 2D/3D Embeddings...';
            app.ClusterCellsUsingCellEmbeddingSMenu = uimenu(app.ViewMenu);
            app.ClusterCellsUsingCellEmbeddingSMenu.MenuSelectedFcn = createCallbackFcn(app, @ClusterCellsUsingCellEmbeddingSMenuSelected, true);
            app.ClusterCellsUsingCellEmbeddingSMenu.Separator = 'on';
            app.ClusterCellsUsingCellEmbeddingSMenu.Accelerator = 'c';
            app.ClusterCellsUsingCellEmbeddingSMenu.Text = 'Cluster Cells Using Cell Embedding (S)';
            app.ClusterCellsUsingExpressionMatrixXMenu = uimenu(app.ViewMenu);
            app.ClusterCellsUsingExpressionMatrixXMenu.MenuSelectedFcn = createCallbackFcn(app, @ClusterCellsUsingExpressionMatrixXMenuSelected, true);
            app.ClusterCellsUsingExpressionMatrixXMenu.Text = 'Cluster Cells Using Expression Matrix (X)...';
            app.ShowGeneExpressionMenu = uimenu(app.ViewMenu);
            app.ShowGeneExpressionMenu.Separator = 'on';
            app.ShowGeneExpressionMenu.Text = 'Show Gene Expression';
            app.SelectedGenesMenu = uimenu(app.ShowGeneExpressionMenu);
            app.SelectedGenesMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_ShowGeneExprClicked, true);
            app.SelectedGenesMenu.Accelerator = 'E';
            app.SelectedGenesMenu.Text = 'Selected Genes...';
            app.MitochondrialMtGenesMenu = uimenu(app.ShowGeneExpressionMenu);
            app.MitochondrialMtGenesMenu.MenuSelectedFcn = createCallbackFcn(app, @MitochondrialMtGenesExpressionMenuSelected, true);
            app.MitochondrialMtGenesMenu.Text = 'Mitochondrial (Mt-) Genes...';
            app.HemoglobinHgbGenesMenu = uimenu(app.ShowGeneExpressionMenu);
            app.HemoglobinHgbGenesMenu.MenuSelectedFcn = createCallbackFcn(app, @HemoglobinHgbGenesExpressionMenuSelected, true);
            app.HemoglobinHgbGenesMenu.Text = 'Hemoglobin (Hgb) Genes...';
            app.ShowCellStatesMenu = uimenu(app.ViewMenu);
            app.ShowCellStatesMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_ShowCellStatesClicked, true);
            app.ShowCellStatesMenu.Accelerator = 'T';
            app.ShowCellStatesMenu.Text = 'Show Cell States...';
            app.LabelCellGroupsMenu = uimenu(app.ViewMenu);
            app.LabelCellGroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_LabelCellGroupsClicked, true);
            app.LabelCellGroupsMenu.Accelerator = 'G';
            app.LabelCellGroupsMenu.Text = 'Label Cell Groups...';
            app.ShowCellClustersGroupsIndividuallyMenu = uimenu(app.ViewMenu);
            app.ShowCellClustersGroupsIndividuallyMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_ShowClustersPopClicked, true);
            app.ShowCellClustersGroupsIndividuallyMenu.Separator = 'on';
            app.ShowCellClustersGroupsIndividuallyMenu.Text = 'Show Cell Clusters/Groups Individually...';
            app.CellQCMetricsinViolinPlotsMenu = uimenu(app.ViewMenu);
            app.CellQCMetricsinViolinPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @CellQCMetricsinViolinPlotsMenuSelected, true);
            app.CellQCMetricsinViolinPlotsMenu.Text = 'Cell QC Metrics in Violin Plots...';
            app.MultiGroupingViewMenu = uimenu(app.ViewMenu);
            app.MultiGroupingViewMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_MultigroupingViewClicked, true);
            app.MultiGroupingViewMenu.Text = 'Multi-Grouping View...';
            app.CrossTabulationMenu = uimenu(app.ViewMenu);
            app.CrossTabulationMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_CrossTabulationClicked, true);
            app.CrossTabulationMenu.Text = 'Cross Tabulation';
            app.ViewMetadataMenu = uimenu(app.ViewMenu);
            app.ViewMetadataMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMetadataMenuSelected, true);
            app.ViewMetadataMenu.Accelerator = 'M';
            app.ViewMetadataMenu.Text = 'View Metadata...';
            app.ShowGridMenu = uimenu(app.ViewMenu);
            app.ShowGridMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowGridMenuSelected, true);
            app.ShowGridMenu.Separator = 'on';
            app.ShowGridMenu.Checked = 'on';
            app.ShowGridMenu.Text = 'Show Grid';
            app.CloseAllOtherFiguresMenu = uimenu(app.ViewMenu);
            app.CloseAllOtherFiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseAllOtherFiguresMenuSelected, true);
            app.CloseAllOtherFiguresMenu.Separator = 'on';
            app.CloseAllOtherFiguresMenu.Accelerator = 'x';
            app.CloseAllOtherFiguresMenu.Text = 'Close All Other Figures';
            app.RefreshCurrentViewMenu = uimenu(app.ViewMenu);
            app.RefreshCurrentViewMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshCurrentViewMenuSelected, true);
            app.RefreshCurrentViewMenu.Accelerator = 'r';
            app.RefreshCurrentViewMenu.Text = 'Refresh Current View';
            app.PlotsMenu = uimenu(app.UIFigure);
            app.PlotsMenu.Text = 'Plots';
            app.GeneExpressionDotPlotMenu = uimenu(app.PlotsMenu);
            app.GeneExpressionDotPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_GeneDotPlotClicked, true);
            app.GeneExpressionDotPlotMenu.Text = 'Gene Expression Dot Plot...';
            app.GeneExpressionHeatmapMenu = uimenu(app.PlotsMenu);
            app.GeneExpressionHeatmapMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_GeneHeatmapClicked, true);
            app.GeneExpressionHeatmapMenu.Text = 'Gene Expression Heatmap...';
            app.GeneExpressionCellStateStemPlotMenu = uimenu(app.PlotsMenu);
            app.GeneExpressionCellStateStemPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_ScatterStemPlotClicked, true);
            app.GeneExpressionCellStateStemPlotMenu.Text = 'Gene Expression/Cell State Stem Plot...';
            app.GeneExpressionCellStateViolinPlotMenu = uimenu(app.PlotsMenu);
            app.GeneExpressionCellStateViolinPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_ViolinplotClicked, true);
            app.GeneExpressionCellStateViolinPlotMenu.Text = 'Gene Expression/Cell State Violin Plot...';
            app.CorrelationPlotMenu = uimenu(app.PlotsMenu);
            app.CorrelationPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_ScatterCorrPlotClicked, true);
            app.CorrelationPlotMenu.Separator = 'on';
            app.CorrelationPlotMenu.Text = 'Correlation Plot...';
            app.SidebySideGeneExpressionMenu = uimenu(app.PlotsMenu);
            app.SidebySideGeneExpressionMenu.MenuSelectedFcn = createCallbackFcn(app, @SidebySideGeneExpressionMenuSelected, true);
            app.SidebySideGeneExpressionMenu.Separator = 'on';
            app.SidebySideGeneExpressionMenu.Text = 'Side-by-Side Gene Expression...';
            app.EnrichrResultTabletoCircosPlotMenu = uimenu(app.PlotsMenu);
            app.EnrichrResultTabletoCircosPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @EnrichrResultTabletoCircosPlotMenuSelected, true);
            app.EnrichrResultTabletoCircosPlotMenu.Text = 'Enrichr Result Table to Circos Plot...';
            app.CellkNNNetworkMenu = uimenu(app.PlotsMenu);
            app.CellkNNNetworkMenu.MenuSelectedFcn = createCallbackFcn(app, @CellkNNNetworkMenuSelected, true);
            app.CellkNNNetworkMenu.Separator = 'on';
            app.CellkNNNetworkMenu.Text = 'Cell kNN Network...';
            app.CellTrajectoryMenu = uimenu(app.PlotsMenu);
            app.CellTrajectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @CellTrajectoryMenuSelected, true);
            app.CellTrajectoryMenu.Text = 'Cell Trajectory...';
            app.NextMarkerTypeMenu = uimenu(app.PlotsMenu);
            app.NextMarkerTypeMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_PickMarkerTypeClicked, true);
            app.NextMarkerTypeMenu.Separator = 'on';
            app.NextMarkerTypeMenu.Text = 'Next Marker Type';
            app.NextColormapMenu = uimenu(app.PlotsMenu);
            app.NextColormapMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_PIckColormapClicked, true);
            app.NextColormapMenu.Text = 'Next Colormap';
            app.CleantSNEUMAPPHATEPlotMenu = uimenu(app.PlotsMenu);
            app.CleantSNEUMAPPHATEPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @CleantSNEUMAPPHATEPlotMenuSelected, true);
            app.CleantSNEUMAPPHATEPlotMenu.Separator = 'on';
            app.CleantSNEUMAPPHATEPlotMenu.Text = 'Clean tSNE/UMAP/PHATE Plot';
            app.AnnotateMenu = uimenu(app.UIFigure);
            app.AnnotateMenu.Text = 'Annotate';
            app.AnnotateCellTypesUsingPanglaoDBMenu = uimenu(app.AnnotateMenu);
            app.AnnotateCellTypesUsingPanglaoDBMenu.MenuSelectedFcn = createCallbackFcn(app, @AnnotateCellTypesUsingPanglaoDBMenuSelected, true);
            app.AnnotateCellTypesUsingPanglaoDBMenu.Text = 'Annotate Cell Types Using PanglaoDB Marker Genes';
            app.AnnotateCellTypesUsingCustomizedMarkersMenu = uimenu(app.AnnotateMenu);
            app.AnnotateCellTypesUsingCustomizedMarkersMenu.MenuSelectedFcn = createCallbackFcn(app, @AnnotateCellTypesUsingCustomizedMarkersMenuSelected, true);
            app.AnnotateCellTypesUsingCustomizedMarkersMenu.Text = 'Annotate Cell Types Using Customized Marker Genes...';
            app.ImportCellAnnotationfromSCEinWorkspaceMenu = uimenu(app.AnnotateMenu);
            app.ImportCellAnnotationfromSCEinWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportCellAnnotationfromSCEinWorkspaceMenuSelected, true);
            app.ImportCellAnnotationfromSCEinWorkspaceMenu.Separator = 'on';
            app.ImportCellAnnotationfromSCEinWorkspaceMenu.Text = 'Import Cell Annotation from SCE in Workspace...';
            app.ImportCellAnnotationfromSCEDataFileMenu = uimenu(app.AnnotateMenu);
            app.ImportCellAnnotationfromSCEDataFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportCellAnnotationfromSCEDataFileMenuSelected, true);
            app.ImportCellAnnotationfromSCEDataFileMenu.Text = 'Import Cell Annotation from SCE Data File...';
            app.AnnotateCellTypesforBrushedCellsMenu = uimenu(app.AnnotateMenu);
            app.AnnotateCellTypesforBrushedCellsMenu.MenuSelectedFcn = createCallbackFcn(app, @AnnotateCellTypesforBrushedCellsMenuSelected, true);
            app.AnnotateCellTypesforBrushedCellsMenu.Separator = 'on';
            app.AnnotateCellTypesforBrushedCellsMenu.Text = 'Annotate Cell Types for Brushed Cells';
            app.FindMarkerGenesforBrushedCellsMenu = uimenu(app.AnnotateMenu);
            app.FindMarkerGenesforBrushedCellsMenu.MenuSelectedFcn = createCallbackFcn(app, @FindMarkerGenesforBrushedCellsMenuSelected, true);
            app.FindMarkerGenesforBrushedCellsMenu.Text = 'Find Marker Genes for Brushed Cells';
            app.MakeMarkerGeneHeatmapMenu = uimenu(app.AnnotateMenu);
            app.MakeMarkerGeneHeatmapMenu.Text = 'Make Marker Gene Heatmap';
            app.EstimateCellCyclePhaseMenu = uimenu(app.AnnotateMenu);
            app.EstimateCellCyclePhaseMenu.MenuSelectedFcn = createCallbackFcn(app, @EstimateCellCyclePhaseMenuSelected, true);
            app.EstimateCellCyclePhaseMenu.Separator = 'on';
            app.EstimateCellCyclePhaseMenu.Text = 'Estimate Cell Cycle Phase...';
            app.EstimateDifferentiationPotencyPMID33244588Menu = uimenu(app.AnnotateMenu);
            app.EstimateDifferentiationPotencyPMID33244588Menu.MenuSelectedFcn = createCallbackFcn(app, @EstimateDifferentiationPotencyPMID33244588MenuSelected, true);
            app.EstimateDifferentiationPotencyPMID33244588Menu.Text = 'Estimate Differentiation Potency [PMID:33244588]...';
            app.EstimateStemnessPMID29625051Menu = uimenu(app.AnnotateMenu);
            app.EstimateStemnessPMID29625051Menu.MenuSelectedFcn = createCallbackFcn(app, @EstimateStemnessPMID29625051MenuSelected, true);
            app.EstimateStemnessPMID29625051Menu.Text = 'Estimate Stemness [PMID:29625051]...';
            app.EstimateDissociationGeneRatioPMID34020534Menu = uimenu(app.AnnotateMenu);
            app.EstimateDissociationGeneRatioPMID34020534Menu.MenuSelectedFcn = createCallbackFcn(app, @EstimateDissociationGeneRatioPMID34020534MenuSelected, true);
            app.EstimateDissociationGeneRatioPMID34020534Menu.Text = 'Estimate Dissociation Gene Ratio [PMID:34020534]...';
            app.SingleClickSolutionAnnotationMenu = uimenu(app.AnnotateMenu);
            app.SingleClickSolutionAnnotationMenu.MenuSelectedFcn = createCallbackFcn(app, @SingleClickSolutionAnnotationMenuSelected, true);
            app.SingleClickSolutionAnnotationMenu.Separator = 'on';
            app.SingleClickSolutionAnnotationMenu.Text = 'Single Click Solution (from Raw Data to Annotation)...';
            app.AnalyzeMenu = uimenu(app.UIFigure);
            app.AnalyzeMenu.Text = 'Analyze';
            app.GeneExpressionStatisticsMenu = uimenu(app.AnalyzeMenu);
            app.GeneExpressionStatisticsMenu.MenuSelectedFcn = createCallbackFcn(app, @GeneExpressionStatisticsMenuSelected, true);
            app.GeneExpressionStatisticsMenu.Text = 'Gene Expression Statistics...';
            app.TwogeneCooccurrenceTestMenu = uimenu(app.AnalyzeMenu);
            app.TwogeneCooccurrenceTestMenu.MenuSelectedFcn = createCallbackFcn(app, @TwogeneCooccurrenceTestMenuSelected, true);
            app.TwogeneCooccurrenceTestMenu.Text = 'Two-gene Co-occurrence Test...';
            app.GeneVariabilityHVGFunctionAnalysisPMID31861624Menu = uimenu(app.AnalyzeMenu);
            app.GeneVariabilityHVGFunctionAnalysisPMID31861624Menu.MenuSelectedFcn = createCallbackFcn(app, @GeneVariabilityHVGFunctionAnalysisPMID31861624MenuSelected, true);
            app.GeneVariabilityHVGFunctionAnalysisPMID31861624Menu.Text = 'Gene Variability (HVG Function) Analysis [PMID:31861624]...';
            app.GeneProgramCellScoreAnalysisMenu = uimenu(app.AnalyzeMenu);
            app.GeneProgramCellScoreAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @GeneProgramCellScoreAnalysisMenuSelected, true);
            app.GeneProgramCellScoreAnalysisMenu.Separator = 'on';
            app.GeneProgramCellScoreAnalysisMenu.Text = 'Gene Program (Cell Score) Analysis...';
            app.GeneProgramRadarPlotMenu = uimenu(app.AnalyzeMenu);
            app.GeneProgramRadarPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_GetCellSignatureMatrixClicked, true);
            app.GeneProgramRadarPlotMenu.Text = 'Gene Program Radar Plot...';
            app.GeneProgramCorrMatMenu = uimenu(app.AnalyzeMenu);
            app.GeneProgramCorrMatMenu.MenuSelectedFcn = createCallbackFcn(app, @GeneProgramCorrMatMenuSelected, true);
            app.GeneProgramCorrMatMenu.Text = 'Gene Program Correlation Matrix [PMID:34450029]...';
            app.DifferentialExpressionDEAnalysisMenu = uimenu(app.AnalyzeMenu);
            app.DifferentialExpressionDEAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @DifferentialExpressionDEAnalysisMenuSelected, true);
            app.DifferentialExpressionDEAnalysisMenu.Separator = 'on';
            app.DifferentialExpressionDEAnalysisMenu.Accelerator = 'd';
            app.DifferentialExpressionDEAnalysisMenu.Text = 'Differential Expression (DE) Analysis...';
            app.DifferentialVariabilityDVAnalysisPMID40113778Menu = uimenu(app.AnalyzeMenu);
            app.DifferentialVariabilityDVAnalysisPMID40113778Menu.MenuSelectedFcn = createCallbackFcn(app, @DifferentialVariabilityDVAnalysisPMID40113778MenuSelected, true);
            app.DifferentialVariabilityDVAnalysisPMID40113778Menu.Accelerator = 'v';
            app.DifferentialVariabilityDVAnalysisPMID40113778Menu.Text = 'Differential Variability (DV) Analysis [PMID:40113778]...';
            app.DifferentialProgramDPAnalysisMenu = uimenu(app.AnalyzeMenu);
            app.DifferentialProgramDPAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @DifferentialProgramDPAnalysisMenuSelected, true);
            app.DifferentialProgramDPAnalysisMenu.Accelerator = 'p';
            app.DifferentialProgramDPAnalysisMenu.Text = 'Differential Program (DP) Analysis...';
            app.DEAnalysisinCellTypeBatchModeMenu = uimenu(app.AnalyzeMenu);
            app.DEAnalysisinCellTypeBatchModeMenu.MenuSelectedFcn = createCallbackFcn(app, @DEAnalysisinCellTypeBatchModeMenuSelected, true);
            app.DEAnalysisinCellTypeBatchModeMenu.Separator = 'on';
            app.DEAnalysisinCellTypeBatchModeMenu.Text = 'DE Analysis in Cell Type Batch Mode...';
            app.DVAnalysisinCellTypeBatchModeMenu = uimenu(app.AnalyzeMenu);
            app.DVAnalysisinCellTypeBatchModeMenu.MenuSelectedFcn = createCallbackFcn(app, @DVAnalysisinCellTypeBatchModeMenuSelected, true);
            app.DVAnalysisinCellTypeBatchModeMenu.Text = 'DV Analysis in Cell Type Batch Mode...';
            app.DPAnalysisinCellTypeBatchModeMenu = uimenu(app.AnalyzeMenu);
            app.DPAnalysisinCellTypeBatchModeMenu.MenuSelectedFcn = createCallbackFcn(app, @DPAnalysisinCellTypeBatchModeMenuSelected, true);
            app.DPAnalysisinCellTypeBatchModeMenu.Text = 'DP Analysis in Cell Type Batch Mode...';
            app.AllDEDVDPAnalysisinCellTypeBatchModeMenu = uimenu(app.AnalyzeMenu);
            app.AllDEDVDPAnalysisinCellTypeBatchModeMenu.MenuSelectedFcn = createCallbackFcn(app, @AllDEDVDPAnalysisinCellTypeBatchModeMenuSelected, true);
            app.AllDEDVDPAnalysisinCellTypeBatchModeMenu.Separator = 'on';
            app.AllDEDVDPAnalysisinCellTypeBatchModeMenu.Text = 'All (DE, DV, DP) Analysis in Cell Type Batch Mode...';
            app.LASSOFeatureSelectionMenu = uimenu(app.AnalyzeMenu);
            app.LASSOFeatureSelectionMenu.MenuSelectedFcn = createCallbackFcn(app, @LASSOFeatureSelectionMenuSelected, true);
            app.LASSOFeatureSelectionMenu.Separator = 'on';
            app.LASSOFeatureSelectionMenu.Text = 'LASSO Feature Selection...';
            app.QUBOFeatureSelectionPMID40832053Menu = uimenu(app.AnalyzeMenu);
            app.QUBOFeatureSelectionPMID40832053Menu.MenuSelectedFcn = createCallbackFcn(app, @QUBOFeatureSelectionPMID40832053MenuSelected, true);
            app.QUBOFeatureSelectionPMID40832053Menu.Text = 'QUBO Feature Selection [PMID:40832053]🐢...';
            app.NetworkMenu = uimenu(app.UIFigure);
            app.NetworkMenu.Text = 'Network';
            app.RemoveGenestoReduceGeneSpaceMenu = uimenu(app.NetworkMenu);
            app.RemoveGenestoReduceGeneSpaceMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveGenestoReduceGeneSpaceMenuSelected, true);
            app.RemoveGenestoReduceGeneSpaceMenu.Text = 'Remove Less Informative Genes to Reduce Gene Space...';
            app.PlotGRNfromEdgeGenePairListMenu = uimenu(app.NetworkMenu);
            app.PlotGRNfromEdgeGenePairListMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotGRNfromEdgeGenePairListMenuSelected, true);
            app.PlotGRNfromEdgeGenePairListMenu.Text = 'Plot GRN from Edge (Gene Pair) List...';
            app.BuildGRNSelectedGenesMenu = uimenu(app.NetworkMenu);
            app.BuildGRNSelectedGenesMenu.MenuSelectedFcn = createCallbackFcn(app, @BuildGRNSelectedGenesMenuSelected, true);
            app.BuildGRNSelectedGenesMenu.Separator = 'on';
            app.BuildGRNSelectedGenesMenu.Text = 'Build GRN (Selected Genes)...';
            app.BuildCompareTwoGRNsSelectedGenesMenu = uimenu(app.NetworkMenu);
            app.BuildCompareTwoGRNsSelectedGenesMenu.MenuSelectedFcn = createCallbackFcn(app, @BuildCompareTwoGRNsSelectedGenesMenuSelected, true);
            app.BuildCompareTwoGRNsSelectedGenesMenu.Text = 'Build && Compare Two GRNs (Selected Genes)...';
            app.ConstructGRNwithAllGenesscTenifoldNetMenu = uimenu(app.NetworkMenu);
            app.ConstructGRNwithAllGenesscTenifoldNetMenu.MenuSelectedFcn = createCallbackFcn(app, @ConstructGRNwithAllGenesscTenifoldNetMenuSelected, true);
            app.ConstructGRNwithAllGenesscTenifoldNetMenu.Text = 'Build GRN (All Genes) - scTenifoldNet [PMID:33336197] 🐢..';
            app.ConstructCompareGRNsscTenifoldNetMenu = uimenu(app.NetworkMenu);
            app.ConstructCompareGRNsscTenifoldNetMenu.MenuSelectedFcn = createCallbackFcn(app, @ConstructCompareGRNsscTenifoldNetMenuSelected, true);
            app.ConstructCompareGRNsscTenifoldNetMenu.Text = 'Build && Compare GRNs (All Genes) - scTenifoldNet [PMID:33336197] 🐢...';
            app.VirtualGeneKnockoutscTenifoldKnkMenu = uimenu(app.NetworkMenu);
            app.VirtualGeneKnockoutscTenifoldKnkMenu.MenuSelectedFcn = createCallbackFcn(app, @VirtualGeneKnockoutscTenifoldKnkMenuSelected, true);
            app.VirtualGeneKnockoutscTenifoldKnkMenu.Separator = 'on';
            app.VirtualGeneKnockoutscTenifoldKnkMenu.Text = 'Virtual Gene Knockout - scTenifoldKnk [PMID:35510185] 🐢...';
            app.VirtualGeneKnockoutGenKIMenu = uimenu(app.NetworkMenu);
            app.VirtualGeneKnockoutGenKIMenu.MenuSelectedFcn = createCallbackFcn(app, @VirtualGeneKnockoutGenKIMenuSelected, true);
            app.VirtualGeneKnockoutGenKIMenu.Text = 'Virtual Gene Knockout (GenKI) [PMID:37246643] ...';
            app.OneSampleTenifoldXctMenu = uimenu(app.NetworkMenu);
            app.OneSampleTenifoldXctMenu.MenuSelectedFcn = createCallbackFcn(app, @OneSampleTenifoldXctMenuSelected, true);
            app.OneSampleTenifoldXctMenu.Separator = 'on';
            app.OneSampleTenifoldXctMenu.Text = 'One-Sample Cell-Cell Communication (scTenifoldXct/🐍) [PMID:36787742]...';
            app.TwoSampleTenifoldXct2Menu = uimenu(app.NetworkMenu);
            app.TwoSampleTenifoldXct2Menu.MenuSelectedFcn = createCallbackFcn(app, @TwoSampleTenifoldXct2MenuSelected, true);
            app.TwoSampleTenifoldXct2Menu.Text = 'Two-Sample Cell-Cell Communication (scTenifoldXct2/🐍) [PMID:36787742]...';
            app.VirtualscTenifoldCkoMenu = uimenu(app.NetworkMenu);
            app.VirtualscTenifoldCkoMenu.MenuSelectedFcn = createCallbackFcn(app, @VirtualscTenifoldCkoMenuSelected, true);
            app.VirtualscTenifoldCkoMenu.Text = 'Virtual Cell-Cell Communication Knockout - scTenifoldCko/🐍) [Experimental] 🐢 ...';
            app.TalklrIntercellularCrosstalkMenu = uimenu(app.NetworkMenu);
            app.TalklrIntercellularCrosstalkMenu.MenuSelectedFcn = createCallbackFcn(app, @TalklrIntercellularCrosstalkMenuSelected, true);
            app.TalklrIntercellularCrosstalkMenu.Separator = 'on';
            app.TalklrIntercellularCrosstalkMenu.Text = 'Talklr Intercellular Crosstalk [DOI:10.1101/2020.02.01.930602]...';
            app.ExternalMenu = uimenu(app.UIFigure);
            app.ExternalMenu.Text = 'External';
            app.SetRandomSeedMenu = uimenu(app.ExternalMenu);
            app.SetRandomSeedMenu.MenuSelectedFcn = createCallbackFcn(app, @SetRandomSeedMenuSelected, true);
            app.SetRandomSeedMenu.Text = 'Set Random Seed...';
            app.SetWorkingFolderMenu = uimenu(app.ExternalMenu);
            app.SetWorkingFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @SetWorkingFolderMenuSelected, true);
            app.SetWorkingFolderMenu.Text = 'Set Working Folder...';
            app.SelectLargeLanguageModelMenu = uimenu(app.ExternalMenu);
            app.SelectLargeLanguageModelMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectLargeLanguageModelMenuSelected, true);
            app.SelectLargeLanguageModelMenu.Separator = 'on';
            app.SelectLargeLanguageModelMenu.Text = '🤖 - Select Large Language Model...';
            app.InstallQuantumComputingPackageMenu = uimenu(app.ExternalMenu);
            app.InstallQuantumComputingPackageMenu.MenuSelectedFcn = createCallbackFcn(app, @InstallQuantumComputingPackageMenuSelected, true);
            app.InstallQuantumComputingPackageMenu.Text = '⚛ - Install Quantum Computing Support Package...';
            app.SetUpREnvironmentMenu = uimenu(app.ExternalMenu);
            app.SetUpREnvironmentMenu.MenuSelectedFcn = createCallbackFcn(app, @SetUpREnvironmentMenuSelected, true);
            app.SetUpREnvironmentMenu.Separator = 'on';
            app.SetUpREnvironmentMenu.Text = 'Set Up R (ℝ) Environment';
            app.SetUpPythonEnvironmentMenu = uimenu(app.ExternalMenu);
            app.SetUpPythonEnvironmentMenu.MenuSelectedFcn = createCallbackFcn(app, @SetUpPythonEnvironmentMenuSelected, true);
            app.SetUpPythonEnvironmentMenu.Text = 'Set Up Python (🐍) Environment';
            app.BatchIntegrationHarmonyRMenu = uimenu(app.ExternalMenu);
            app.BatchIntegrationHarmonyRMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchIntegrationHarmonyRMenuSelected, true);
            app.BatchIntegrationHarmonyRMenu.Separator = 'on';
            app.BatchIntegrationHarmonyRMenu.Text = 'ℝ - Batch Integration (Harmony) [PMID:31740819]...';
            app.PseudotimeAnalysisMonocle3Menu = uimenu(app.ExternalMenu);
            app.PseudotimeAnalysisMonocle3Menu.MenuSelectedFcn = createCallbackFcn(app, @PseudotimeAnalysisMonocle3MenuSelected, true);
            app.PseudotimeAnalysisMonocle3Menu.Text = 'ℝ - Pseudotime Analysis (Monocle3) [PMID:28825705]...';
            app.AneuploidDiploidAnalysiscopykatMenu = uimenu(app.ExternalMenu);
            app.AneuploidDiploidAnalysiscopykatMenu.MenuSelectedFcn = createCallbackFcn(app, @AneuploidDiploidAnalysiscopykatMenuSelected, true);
            app.AneuploidDiploidAnalysiscopykatMenu.Text = 'ℝ - Aneuploid/Diploid Analysis (copykat) [PMID:33462507]...';
            app.VariationalAneuploidyAnalysisSCEVANMenu = uimenu(app.ExternalMenu);
            app.VariationalAneuploidyAnalysisSCEVANMenu.MenuSelectedFcn = createCallbackFcn(app, @VariationalAneuploidyAnalysisSCEVANMenuSelected, true);
            app.VariationalAneuploidyAnalysisSCEVANMenu.Text = 'ℝ - Variational Aneuploidy Analysis (SCEVAN) [PMID:36841879]...';
            app.DetectAmbientRNAContaminationDecontXMenu = uimenu(app.ExternalMenu);
            app.DetectAmbientRNAContaminationDecontXMenu.MenuSelectedFcn = createCallbackFcn(app, @DetectAmbientRNAContaminationDecontXMenuSelected, true);
            app.DetectAmbientRNAContaminationDecontXMenu.Text = 'ℝ - Detect Ambient RNA Contamination (DecontX) [PMID:32138770]...';
            app.SeuratFunctionsPMID25867923Menu = uimenu(app.ExternalMenu);
            app.SeuratFunctionsPMID25867923Menu.Text = 'ℝ - Seurat Functions [PMID:25867923]';
            app.UMAPtSNEEmbeddingMenu = uimenu(app.SeuratFunctionsPMID25867923Menu);
            app.UMAPtSNEEmbeddingMenu.MenuSelectedFcn = createCallbackFcn(app, @UMAPtSNEEmbeddingUsingSeuratMenuSelected, true);
            app.UMAPtSNEEmbeddingMenu.Text = 'UMAP/tSNE Embedding...';
            app.SCTransformv2RegularizationPMID35042561Menu = uimenu(app.SeuratFunctionsPMID25867923Menu);
            app.SCTransformv2RegularizationPMID35042561Menu.MenuSelectedFcn = createCallbackFcn(app, @SCTransformv2RegularizationUsingSeuratMenuSelected, true);
            app.SCTransformv2RegularizationPMID35042561Menu.Text = 'SCTransform v2 Regularization [PMID:35042561]...';
            app.MementoDEDVAnalysisPMID39454576Menu = uimenu(app.ExternalMenu);
            app.MementoDEDVAnalysisPMID39454576Menu.MenuSelectedFcn = createCallbackFcn(app, @MementoDEDVAnalysisPMID39454576MenuSelected, true);
            app.MementoDEDVAnalysisPMID39454576Menu.Separator = 'on';
            app.MementoDEDVAnalysisPMID39454576Menu.Text = '🐍 - Memento DE/DV Analysis [PMID:39454576]...';
            app.DetectDoubletsScrubletPMID30954476Menu = uimenu(app.ExternalMenu);
            app.DetectDoubletsScrubletPMID30954476Menu.MenuSelectedFcn = createCallbackFcn(app, @DetectDoubletsScrubletPMID30954476MenuSelected, true);
            app.DetectDoubletsScrubletPMID30954476Menu.Text = '🐍 - Detect Doublets (Scrublet) [PMID:30954476]...';
            app.BatchIntegrationHarmonyPMID31740819Menu = uimenu(app.ExternalMenu);
            app.BatchIntegrationHarmonyPMID31740819Menu.MenuSelectedFcn = createCallbackFcn(app, @BatchIntegrationHarmonyPMID31740819MenuSelected, true);
            app.BatchIntegrationHarmonyPMID31740819Menu.Text = '🐍 - Batch Integration (Harmony) [PMID:31740819]...';
            app.MELDPerturbationScoreMELDMenu = uimenu(app.ExternalMenu);
            app.MELDPerturbationScoreMELDMenu.MenuSelectedFcn = createCallbackFcn(app, @MELDPerturbationScoreMELDMenuSelected, true);
            app.MELDPerturbationScoreMELDMenu.Text = '🐍 - MELD Perturbation Score (MELD) [PMID:33558698]...';
            app.AtlasBasedCellTypeAnnotationMenu = uimenu(app.ExternalMenu);
            app.AtlasBasedCellTypeAnnotationMenu.Text = '🐍 - Atlas-Based Cell Type Annotation';
            app.PanHumanAzimuthsatijalaborgMenu = uimenu(app.AtlasBasedCellTypeAnnotationMenu);
            app.PanHumanAzimuthsatijalaborgMenu.MenuSelectedFcn = createCallbackFcn(app, @AnnotateCellTypesPanHumanAzimuthsatijalaborgMenuSelected, true);
            app.PanHumanAzimuthsatijalaborgMenu.Text = 'Pan-Human Azimuth [satijalab.org]...';
            app.SCimilarityPMID39566551Menu = uimenu(app.AtlasBasedCellTypeAnnotationMenu);
            app.SCimilarityPMID39566551Menu.MenuSelectedFcn = createCallbackFcn(app, @AnnotateCellTypesSCimilarityPMID39566551MenuSelected, true);
            app.SCimilarityPMID39566551Menu.Text = 'SCimilarity 🔢 [PMID:39566551]...';
            app.LocateSCimilarityModelFilesMenu = uimenu(app.AtlasBasedCellTypeAnnotationMenu);
            app.LocateSCimilarityModelFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @LocateSCimilarityModelFilesMenuSelected, true);
            app.LocateSCimilarityModelFilesMenu.Text = '🔢 - Locate SCimilarity Model Files...';
            app.PreselectLibrariesEnrichrAnalysisMenu = uimenu(app.ExternalMenu);
            app.PreselectLibrariesEnrichrAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @Libraries4EnrichrMenuSelected, true);
            app.PreselectLibrariesEnrichrAnalysisMenu.Separator = 'on';
            app.PreselectLibrariesEnrichrAnalysisMenu.Text = 'Preselect Gene Set Libraries Used for Enrichr Analysis...';
            app.PerformEnrichrAnalysisMenu = uimenu(app.ExternalMenu);
            app.PerformEnrichrAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @PerformEnrichrAnalysisMenuSelected, true);
            app.PerformEnrichrAnalysisMenu.Text = '🌐 - Enrichr Analysis [PMID:27141961]...';
            app.PerformGeneAgentAnalysisMenu = uimenu(app.ExternalMenu);
            app.PerformGeneAgentAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @PerformGeneAgentAnalysisMenuSelected, true);
            app.PerformGeneAgentAnalysisMenu.Text = '🌐 - GeneAgent Analysis [PMID:40721871] 🔒...';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.OnlineDocumentationMenu = uimenu(app.HelpMenu);
            app.OnlineDocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @OnlineDocumentationMenuSelected, true);
            app.OnlineDocumentationMenu.Text = 'Online Documentation...';
            app.ShortcutsUserGuideMenu = uimenu(app.HelpMenu);
            app.ShortcutsUserGuideMenu.MenuSelectedFcn = createCallbackFcn(app, @ShortcutsUserGuideMenuSelected, true);
            app.ShortcutsUserGuideMenu.Text = 'Shortcuts User Guide...';
            app.ViewOnFileExchangeMenu = uimenu(app.HelpMenu);
            app.ViewOnFileExchangeMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewOnFileExchangeMenuSelected, true);
            app.ViewOnFileExchangeMenu.Separator = 'on';
            app.ViewOnFileExchangeMenu.Text = 'View scGEAToolbox on File Exchange...';
            app.CitescGEAToolboxPaperMenu = uimenu(app.HelpMenu);
            app.CitescGEAToolboxPaperMenu.MenuSelectedFcn = createCallbackFcn(app, @CitescGEAToolboxPaperMenuSelected, true);
            app.CitescGEAToolboxPaperMenu.Text = 'Cite scGEAToolbox Paper...';
            app.PapersCitingscGEAToolboxMenu = uimenu(app.HelpMenu);
            app.PapersCitingscGEAToolboxMenu.MenuSelectedFcn = createCallbackFcn(app, @PapersCitingscGEAToolboxMenuSelected, true);
            app.PapersCitingscGEAToolboxMenu.Text = 'Papers Citing scGEAToolbox...';
            app.VisitSCGEATOOLStandaloneWebsiteMenu = uimenu(app.HelpMenu);
            app.VisitSCGEATOOLStandaloneWebsiteMenu.MenuSelectedFcn = createCallbackFcn(app, @VisitSCGEATOOLStandaloneWebsiteMenuSelected, true);
            app.VisitSCGEATOOLStandaloneWebsiteMenu.Separator = 'on';
            app.VisitSCGEATOOLStandaloneWebsiteMenu.Text = 'Visit SCGEATOOL-Standalone Website...';
            app.RunSCGEATOOLinMATLABOnlineMenu = uimenu(app.HelpMenu);
            app.RunSCGEATOOLinMATLABOnlineMenu.MenuSelectedFcn = createCallbackFcn(app, @RunSCGEATOOLinMATLABOnlineMenuSelected, true);
            app.RunSCGEATOOLinMATLABOnlineMenu.Text = 'Run SCGEATOOL in MATLAB Online...';
            app.ValidatePasskeyMenu = uimenu(app.HelpMenu);
            app.ValidatePasskeyMenu.MenuSelectedFcn = createCallbackFcn(app, @ValidatePasskeyMenuSelected, true);
            app.ValidatePasskeyMenu.Text = 'Validate Passkey 🔑...';
            app.CheckforUpdatesMenu = uimenu(app.HelpMenu);
            app.CheckforUpdatesMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckforUpdatesMenuSelected, true);
            app.CheckforUpdatesMenu.Separator = 'on';
            app.CheckforUpdatesMenu.Text = 'Check for Updates...';
            app.AboutSCGEATOOLMenu = uimenu(app.HelpMenu);
            app.AboutSCGEATOOLMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutSCGEATOOLMenuSelected, true);
            app.AboutSCGEATOOLMenu.Separator = 'on';
            app.AboutSCGEATOOLMenu.Text = 'About SCGEATOOL';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool_ImportData = uipushtool(app.Toolbar);
            app.PushTool_ImportData.Tooltip = {'Import Data...'};
            app.PushTool_ImportData.ClickedCallback = createCallbackFcn(app, @PushTool_ImportDataClicked, true);
            app.PushTool_ImportData.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'borg_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_MultigroupingView = uipushtool(app.Toolbar);
            app.PushTool_MultigroupingView.Tooltip = {'Multi-grouping View...'};
            app.PushTool_MultigroupingView.ClickedCallback = createCallbackFcn(app, @PushTool_MultigroupingViewClicked, true);
            app.PushTool_MultigroupingView.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'visibility_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_CrossTabulation = uipushtool(app.Toolbar);
            app.PushTool_CrossTabulation.Tooltip = {'Cross tabulation...'};
            app.PushTool_CrossTabulation.ClickedCallback = createCallbackFcn(app, @PushTool_CrossTabulationClicked, true);
            app.PushTool_CrossTabulation.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'full_stacked_bar_chart_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_ShowGeneExprGroup = uipushtool(app.Toolbar);
            app.PushTool_ShowGeneExprGroup.ClickedCallback = createCallbackFcn(app, @PushTool_ShowGeneExprGroupClicked, true);
            app.PushTool_ShowGeneExprGroup.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'grid_view_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_GeneDotPlot = uipushtool(app.Toolbar);
            app.PushTool_GeneDotPlot.Tooltip = {'Gene Expression Dot Plot...'};
            app.PushTool_GeneDotPlot.ClickedCallback = createCallbackFcn(app, @PushTool_GeneDotPlotClicked, true);
            app.PushTool_GeneDotPlot.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'blur_linear_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_GeneDotPlot.Separator = 'on';
            app.PushTool_GeneHeatmap = uipushtool(app.Toolbar);
            app.PushTool_GeneHeatmap.Tooltip = {'Gene Expression Heatmap...'};
            app.PushTool_GeneHeatmap.ClickedCallback = createCallbackFcn(app, @PushTool_GeneHeatmapClicked, true);
            app.PushTool_GeneHeatmap.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'solar_power_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_ScatterStemPlot = uipushtool(app.Toolbar);
            app.PushTool_ScatterStemPlot.Tooltip = {'Gene Expression/Cell State Stem Plot...'};
            app.PushTool_ScatterStemPlot.ClickedCallback = createCallbackFcn(app, @PushTool_ScatterStemPlotClicked, true);
            app.PushTool_ScatterStemPlot.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'align_vertical_bottom_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_Violinplot = uipushtool(app.Toolbar);
            app.PushTool_Violinplot.ClickedCallback = createCallbackFcn(app, @PushTool_ViolinplotClicked, true);
            app.PushTool_Violinplot.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'candlestick_chart_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_ScatterCorrPlot = uipushtool(app.Toolbar);
            app.PushTool_ScatterCorrPlot.ClickedCallback = createCallbackFcn(app, @PushTool_ScatterCorrPlotClicked, true);
            app.PushTool_ScatterCorrPlot.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'show_chart_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_CompareCellScoreBtwCls = uipushtool(app.Toolbar);
            app.PushTool_CompareCellScoreBtwCls.Tooltip = {'Cell score analysis--obtaining gene signature score for each cell'};
            app.PushTool_CompareCellScoreBtwCls.ClickedCallback = createCallbackFcn(app, @PushTool_CompareCellScoreBtwClsClicked, true);
            app.PushTool_CompareCellScoreBtwCls.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'fingerprint_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_CompareCellScoreBtwCls.Separator = 'on';
            app.PushTool_GetCellSignatureMatrix = uipushtool(app.Toolbar);
            app.PushTool_GetCellSignatureMatrix.Tooltip = {'Cell state analysis--obtaining multiple gene signature scores to reveal functional state of cells'};
            app.PushTool_GetCellSignatureMatrix.ClickedCallback = createCallbackFcn(app, @PushTool_GetCellSignatureMatrixClicked, true);
            app.PushTool_GetCellSignatureMatrix.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'hexagon_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_DEGene2Groups = uipushtool(app.Toolbar);
            app.PushTool_DEGene2Groups.Tooltip = {'Differential expression (DE) analysis'};
            app.PushTool_DEGene2Groups.ClickedCallback = createCallbackFcn(app, @PushTool_DEGene2GroupsClicked, true);
            app.PushTool_DEGene2Groups.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'balance_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_DVGene2Groups = uipushtool(app.Toolbar);
            app.PushTool_DVGene2Groups.Tooltip = {'Differential variability (DV) analysis'};
            app.PushTool_DVGene2Groups.ClickedCallback = createCallbackFcn(app, @PushTool_DVGene2GroupsClicked, true);
            app.PushTool_DVGene2Groups.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'format_letter_spacing_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_DPGene2Groups = uipushtool(app.Toolbar);
            app.PushTool_DPGene2Groups.Tooltip = {'Differential program (DP) analysis'};
            app.PushTool_DPGene2Groups.ClickedCallback = createCallbackFcn(app, @PushTool_DPGene2GroupsClicked, true);
            app.PushTool_DPGene2Groups.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'component_exchange_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_BuildGeneNetwork = uipushtool(app.Toolbar);
            app.PushTool_BuildGeneNetwork.Tooltip = {'Build gene regulatory network'};
            app.PushTool_BuildGeneNetwork.ClickedCallback = createCallbackFcn(app, @PushTool_BuildGeneNetworkClicked, true);
            app.PushTool_BuildGeneNetwork.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'graph_3_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_BuildGeneNetwork.Separator = 'on';
            app.PushTool_CompareGeneNetwork = uipushtool(app.Toolbar);
            app.PushTool_CompareGeneNetwork.Tooltip = {'Compare two scGRNs'};
            app.PushTool_CompareGeneNetwork.ClickedCallback = createCallbackFcn(app, @PushTool_CompareGeneNetworkClicked, true);
            app.PushTool_CompareGeneNetwork.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'graph_5_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_SaveFigToPowerPoint = uipushtool(app.Toolbar);
            app.PushTool_SaveFigToPowerPoint.ClickedCallback = createCallbackFcn(app, @PushTool_SaveFigToPowerPointClicked, true);
            app.PushTool_SaveFigToPowerPoint.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'presentation.jpg');
            app.PushTool_SaveFigToPowerPoint.Separator = 'on';
            app.PushTool_MakeVideoSnapshot = uipushtool(app.Toolbar);
            app.PushTool_MakeVideoSnapshot.Tooltip = {'Make Video Snapshot...'};
            app.PushTool_MakeVideoSnapshot.ClickedCallback = createCallbackFcn(app, @PushTool_MakeVideoSnapshotClicked, true);
            app.PushTool_MakeVideoSnapshot.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'video_camera_back_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.ToggleTool_ShowColorbar = uitoggletool(app.Toolbar);
            app.ToggleTool_ShowColorbar.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'colorbarcdata.png');
            app.ToggleTool_ShowColorbar.OffCallback = createCallbackFcn(app, @ToggleTool_ShowColorbarOff, true);
            app.ToggleTool_ShowColorbar.OnCallback = createCallbackFcn(app, @ToggleTool_ShowColorbarOn, true);
            app.PushTool_ResizePlotWindow = uipushtool(app.Toolbar);
            app.PushTool_ResizePlotWindow.ClickedCallback = createCallbackFcn(app, @PushTool_ResizePlotWindowClicked, true);
            app.PushTool_ResizePlotWindow.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'resize_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');

            app.PushTool_LightDarkMode = uipushtool(app.Toolbar);
            app.PushTool_LightDarkMode.ClickedCallback = createCallbackFcn(app, @PushTool_LightDarkModeClicked, true);
            app.PushTool_LightDarkMode.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'dark_mode_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.Toolbar2 = uitoolbar(app.UIFigure);
            app.PushTool_ShowGeneExpr = uipushtool(app.Toolbar2);
            app.PushTool_ShowGeneExpr.ClickedCallback = createCallbackFcn(app, @PushTool_ShowGeneExprClicked, true);
            app.PushTool_ShowGeneExpr.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'article_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_ShowCellStates = uipushtool(app.Toolbar2);
            app.PushTool_ShowCellStates.Tooltip = {'Show Cell States...'};
            app.PushTool_ShowCellStates.ClickedCallback = createCallbackFcn(app, @PushTool_ShowCellStatesClicked, true);
            app.PushTool_ShowCellStates.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'event_list_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_FilterGenesCells = uipushtool(app.Toolbar2);
            app.PushTool_FilterGenesCells.Tooltip = {'Filter genes & cells...'};
            app.PushTool_FilterGenesCells.ClickedCallback = createCallbackFcn(app, @PushTool_FilterGenesCellsClicked, true);
            app.PushTool_FilterGenesCells.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'filter_alt_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_LabelCellGroups = uipushtool(app.Toolbar2);
            app.PushTool_LabelCellGroups.Tooltip = {'Label Cell Groups...'};
            app.PushTool_LabelCellGroups.ClickedCallback = createCallbackFcn(app, @PushTool_LabelCellGroupsClicked, true);
            app.PushTool_LabelCellGroups.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'label_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_LabelCellGroups.Separator = 'on';
            app.PushTool_AddBrushedToNewGroup = uipushtool(app.Toolbar2);
            app.PushTool_AddBrushedToNewGroup.Tooltip = {'Add brushed cells to a new group'};
            app.PushTool_AddBrushedToNewGroup.ClickedCallback = createCallbackFcn(app, @PushTool_AddBrushedToNewGroupClicked, true);
            app.PushTool_AddBrushedToNewGroup.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'ad_group_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_MergeBrushedToSameGroup = uipushtool(app.Toolbar2);
            app.PushTool_MergeBrushedToSameGroup.Tooltip = {'Merge Brushed Cells to Same Group'};
            app.PushTool_MergeBrushedToSameGroup.ClickedCallback = createCallbackFcn(app, @PushTool_MergeBrushedToSameGroupClicked, true);
            app.PushTool_MergeBrushedToSameGroup.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'stack_group_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_RenameCellTypeBatchID = uipushtool(app.Toolbar2);
            app.PushTool_RenameCellTypeBatchID.ClickedCallback = createCallbackFcn(app, @PushTool_RenameCellTypeBatchIDClicked, true);
            app.PushTool_RenameCellTypeBatchID.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'border_color_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_SingleClickAnnotation = uipushtool(app.Toolbar2);
            app.PushTool_SingleClickAnnotation.Tooltip = {'Single-click cell type annotation'};
            app.PushTool_SingleClickAnnotation.ClickedCallback = createCallbackFcn(app, @PushTool_SingleClickAnnotationClicked, true);
            app.PushTool_SingleClickAnnotation.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'sprint_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_ClusterCellsS = uipushtool(app.Toolbar2);
            app.PushTool_ClusterCellsS.Tooltip = {'Clustering using cell embedding (S)'};
            app.PushTool_ClusterCellsS.ClickedCallback = createCallbackFcn(app, @PushTool_ClusterCellsSClicked, true);
            app.PushTool_ClusterCellsS.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'bubble_chart_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_DetermineCellTypeCluster = uipushtool(app.Toolbar2);
            app.PushTool_DetermineCellTypeCluster.Tooltip = {'Assign cell types to groups'};
            app.PushTool_DetermineCellTypeCluster.ClickedCallback = createCallbackFcn(app, @PushTool_DetermineCellTypeClusterClicked, true);
            app.PushTool_DetermineCellTypeCluster.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'asterisk_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_Brush4Celltypes = uipushtool(app.Toolbar2);
            app.PushTool_Brush4Celltypes.Tooltip = {'Assign cell type to selected cells'};
            app.PushTool_Brush4Celltypes.ClickedCallback = createCallbackFcn(app, @PushTool_Brush4CelltypesClicked, true);
            app.PushTool_Brush4Celltypes.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'ink_highlighter_move_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_Brush4Markers = uipushtool(app.Toolbar2);
            app.PushTool_Brush4Markers.Tooltip = {'Find Marker Genes for Brushed Cells'};
            app.PushTool_Brush4Markers.ClickedCallback = createCallbackFcn(app, @PushTool_Brush4MarkersClicked, true);
            app.PushTool_Brush4Markers.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'military_tech_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_FindAllMarkers = uipushtool(app.Toolbar2);
            app.PushTool_FindAllMarkers.Tooltip = {'Make Marker Gene Heatmap'};
            app.PushTool_FindAllMarkers.ClickedCallback = createCallbackFcn(app, @PushTool_FindAllMarkersClicked, true);
            app.PushTool_FindAllMarkers.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'editor_choice_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_ShowClustersPop = uipushtool(app.Toolbar2);
            app.PushTool_ShowClustersPop.Tooltip = {'Show cell clusters/groups individually'};
            app.PushTool_ShowClustersPop.ClickedCallback = createCallbackFcn(app, @PushTool_ShowClustersPopClicked, true);
            app.PushTool_ShowClustersPop.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'dashboard_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_ShowClustersPop.Separator = 'on';
            app.PushTool_SelectCellsByClass = uipushtool(app.Toolbar2);
            app.PushTool_SelectCellsByClass.Tooltip = {'SelectCellsByClass'};
            app.PushTool_SelectCellsByClass.ClickedCallback = createCallbackFcn(app, @PushTool_SelectCellsByClassClicked, true);
            app.PushTool_SelectCellsByClass.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'checklist_rtl_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_DeleteSelectedCells = uipushtool(app.Toolbar2);
            app.PushTool_DeleteSelectedCells.Tooltip = {'Delete brushed/selected cells'};
            app.PushTool_DeleteSelectedCells.ClickedCallback = createCallbackFcn(app, @PushTool_DeleteSelectedCellsClicked, true);
            app.PushTool_DeleteSelectedCells.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'variable_remove_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_SaveX = uipushtool(app.Toolbar2);
            app.PushTool_SaveX.ClickedCallback = createCallbackFcn(app, @PushTool_SaveXClicked, true);
            app.PushTool_SaveX.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'save_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_EmbedCells = uipushtool(app.Toolbar2);
            app.PushTool_EmbedCells.Tooltip = {'Embed Cells Using tSNE, UMP, PHATE...'};
            app.PushTool_EmbedCells.ClickedCallback = createCallbackFcn(app, @PushTool_EmbedCellsClicked, true);
            app.PushTool_EmbedCells.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'tactic_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_EmbedCells.Separator = 'on';
            app.PushTool_Switch2D3DEmbeddings = uipushtool(app.Toolbar2);
            app.PushTool_Switch2D3DEmbeddings.Tooltip = {'Switch Between 2D/3D Embeddings...'};
            app.PushTool_Switch2D3DEmbeddings.ClickedCallback = createCallbackFcn(app, @PushTool_Switch2D3DEmbeddingsClicked, true);
            app.PushTool_Switch2D3DEmbeddings.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', '2d_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_PickMarkerType = uipushtool(app.Toolbar2);
            app.PushTool_PickMarkerType.Tooltip = {'Pick scatter plot marker type'};
            app.PushTool_PickMarkerType.ClickedCallback = createCallbackFcn(app, @PushTool_PickMarkerTypeClicked, true);
            app.PushTool_PickMarkerType.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'category_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_PIckColormap = uipushtool(app.Toolbar2);
            app.PushTool_PIckColormap.ClickedCallback = createCallbackFcn(app, @PushTool_PIckColormapClicked, true);
            app.PushTool_PIckColormap.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'palette_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.PushTool_Refresh = uipushtool(app.Toolbar2);
            app.PushTool_Refresh.Tooltip = {'Refresh'};
            app.PushTool_Refresh.ClickedCallback = createCallbackFcn(app, @PushTool_RefreshClicked, true);
            app.PushTool_Refresh.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'refresh_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ImportDataButton = uibutton(app.UIFigure, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @PushTool_ImportDataClicked, true);
            app.ImportDataButton.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'Material_Symbols', 'borg_24dp_1F1F1F_FILL0_wght400_GRAD0_opsz24.png');
            app.ImportDataButton.Tooltip = {'Ctrl+I'};
            app.ImportDataButton.Text = 'Import Data...';
            app.ReadytoexploreLabel = uilabel(app.UIFigure);
            app.ReadytoexploreLabel.Text = 'Ready to explore.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scgeatoolApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end