classdef scgeatoolui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SCGEATOOLUIFigure            matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        OpenSCEDataFilematMenu       matlab.ui.container.Menu
        OpenTXTTSVCSVFiletxtMenu     matlab.ui.container.Menu
        OpenSeuratRdsFilerdsMenu     matlab.ui.container.Menu
        OpenAnnDataH5adFileh5adMenu  matlab.ui.container.Menu
        OpenLoomFileloomMenu         matlab.ui.container.Menu
        LoadExampleDataMenu          matlab.ui.container.Menu
        CloseSCEDataMenu             matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        EditMenu                     matlab.ui.container.Menu
        ViewMenu                     matlab.ui.container.Menu
        ToolsMenu                    matlab.ui.container.Menu
        NetworkMenu                  matlab.ui.container.Menu
        ExternalMenu                 matlab.ui.container.Menu
        ExperimentalMenu             matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        Toolbar                      matlab.ui.container.Toolbar
        PushTool                     matlab.ui.container.toolbar.PushTool
        PushTool2                    matlab.ui.container.toolbar.PushTool
        PushTool3                    matlab.ui.container.toolbar.PushTool
        PushTool4                    matlab.ui.container.toolbar.PushTool
        PushTool5                    matlab.ui.container.toolbar.PushTool
        PushTool6                    matlab.ui.container.toolbar.PushTool
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        SCE SingleCellExperiment
    end
    
    methods (Access = private)        
        function in_update_figure(app)
            gui.i_gscatter3(app.SCE.s, app.SCE.c, 1, 1, app.UIAxes);
            title(app.UIAxes, app.SCE.title);
            subtitle(app.UIAxes, '[genes x cells]');
            app.UIAxes.Visible="on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.SCGEATOOLUIFigure,'center');            
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app.SCGEATOOLUIFigure)
        end

        % Menu selected function: LoadExampleDataMenu
        function LoadExampleDataMenuSelected(app, event)
            msg = "Load processed or raw data?";
            title = "Load Data";
            selection = uiconfirm(app.SCGEATOOLUIFigure, msg, title, ...
                "Options",["Processed","Raw","Cancel"], ...
                "DefaultOption",1,"CancelOption",3);
            switch selection 
                case 'Processed'
                    answer=1;
                case 'Raw'
                    answer=2;
                case 'Cancel'
                    return;
            end
            pw1 = fileparts(mfilename('fullpath'));
            fprintf('Loading SCE Data File example_data/workshop_example.mat...');
            tic;
            file1 = fullfile(pw1, '..', 'example_data', 'workshop_example.mat');
            if ~exist(file1, "file")
                errordlg("Example data file does not exist.");
                return;
            end
            load(file1, 'sce');
            if answer == 2
                a = SingleCellExperiment(sce.X, sce.g);
                a.c_batch_id = sce.c_batch_id;
                a.c_cell_id = sce.c_cell_id;
                a.metadata = sce.metadata;
                app.SCE = a;
            else
                app.SCE = sce;
            end
            fprintf('Done.\n');
            toc;
            app.in_update_figure;
        end

        % Menu selected function: OpenSCEDataFilematMenu
        function OpenSCEDataFilematMenuSelected(app, event)
            [fname, pathname] = uigetfile( ...
                            {'*.mat', 'SCE Data Files (*.mat)'; ...
                            '*.*', 'All Files (*.*)'}, ...
                            'Pick a SCE Data File');
            if isequal(fname, 0), return; end
            scefile = fullfile(pathname, fname);
            try                
                load(scefile, 'sce');
                app.SCE = sce;
                app.in_update_figure;
            catch ME                
                errordlg(ME.message);
                return;
            end
        end

        % Menu selected function: CloseSCEDataMenu
        function CloseSCEDataMenuSelected(app, event)
            app.SCE = [];
            delete(app.UIAxes.Children);
            app.UIAxes.Visible="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SCGEATOOLUIFigure = uifigure('Visible', 'off');
            app.SCGEATOOLUIFigure.Name = 'SCGEATOOL';
            app.FileMenu = uimenu(app.SCGEATOOLUIFigure);
            app.FileMenu.Text = '&File';
            app.OpenSCEDataFilematMenu = uimenu(app.FileMenu);
            app.OpenSCEDataFilematMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenSCEDataFilematMenuSelected, true);
            app.OpenSCEDataFilematMenu.Text = 'Open SCE Data File (*.mat)...';
            app.OpenTXTTSVCSVFiletxtMenu = uimenu(app.FileMenu);
            app.OpenTXTTSVCSVFiletxtMenu.Text = 'Open TXT/TSV/CSV File (*.txt)...';
            app.OpenSeuratRdsFilerdsMenu = uimenu(app.FileMenu);
            app.OpenSeuratRdsFilerdsMenu.Text = 'Open Seurat/Rds File (*.rds)...';
            app.OpenAnnDataH5adFileh5adMenu = uimenu(app.FileMenu);
            app.OpenAnnDataH5adFileh5adMenu.Text = 'Open AnnData/H5ad File (*.h5ad)...';
            app.OpenLoomFileloomMenu = uimenu(app.FileMenu);
            app.OpenLoomFileloomMenu.Text = 'Open Loom File (*.loom)...';
            app.LoadExampleDataMenu = uimenu(app.FileMenu);
            app.LoadExampleDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadExampleDataMenuSelected, true);
            app.LoadExampleDataMenu.Separator = 'on';
            app.LoadExampleDataMenu.Text = 'Load Example Data...';
            app.CloseSCEDataMenu = uimenu(app.FileMenu);
            app.CloseSCEDataMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseSCEDataMenuSelected, true);
            app.CloseSCEDataMenu.Separator = 'on';
            app.CloseSCEDataMenu.Text = 'Close SCE Data';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.SCGEATOOLUIFigure);
            app.EditMenu.Text = '&Edit';
            app.ViewMenu = uimenu(app.SCGEATOOLUIFigure);
            app.ViewMenu.Text = '&View';
            app.ToolsMenu = uimenu(app.SCGEATOOLUIFigure);
            app.ToolsMenu.Text = '&Tools';
            app.NetworkMenu = uimenu(app.SCGEATOOLUIFigure);
            app.NetworkMenu.Text = '&Network';
            app.ExternalMenu = uimenu(app.SCGEATOOLUIFigure);
            app.ExternalMenu.Text = 'E&xternal';
            app.ExperimentalMenu = uimenu(app.SCGEATOOLUIFigure);
            app.ExperimentalMenu.Text = 'Ex&perimental';
            app.HelpMenu = uimenu(app.SCGEATOOLUIFigure);
            app.HelpMenu.Text = '&Help';
            app.Toolbar = uitoolbar(app.SCGEATOOLUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Select genes to show expression'};
            app.PushTool.Icon = 'HDF_point.gif';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Icon = 'HDF_object02.gif';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Icon = 'HDF_object01.gif';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Icon = fullfile(pathToMLAPP, 'dattut12.gif');
            app.PushTool5 = uipushtool(app.Toolbar);
            app.PushTool5.Icon = fullfile(pathToMLAPP, 'icon-mat-filter-7-20.gif');
            app.PushTool6 = uipushtool(app.Toolbar);
            app.PushTool6.Icon = fullfile(pathToMLAPP, 'waiting.png');
            app.UIAxes = uiaxes(app.SCGEATOOLUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.SCGEATOOLUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scgeatoolui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SCGEATOOLUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SCGEATOOLUIFigure)
        end
    end
end