classdef uifig_splash < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WelcomeUIFigure  matlab.ui.Figure
        scGEAppLabel     matlab.ui.control.Label
        SinglecellGeneExpressionAnalysisLabel  matlab.ui.control.Label
        v122019Label     matlab.ui.control.Label
        JamesCaiLabel    matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            a=imread(fullfile('resources','waiting.png'));
            app.UIAxes.XLabel.String='';
            app.UIAxes.YLabel.String='';
            app.UIAxes.Title.String='';
            % app.UIAxes.Visible=false;
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            I=imshow(a, 'parent', app.UIAxes,...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);   
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WelcomeUIFigure = uifigure('Visible', 'off');
            app.WelcomeUIFigure.Name = 'Welcome';
            app.scGEAppLabel = uilabel(app.WelcomeUIFigure);
            app.scGEAppLabel.Text = 'scGEApp';
            app.SinglecellGeneExpressionAnalysisLabel = uilabel(app.WelcomeUIFigure);
            app.SinglecellGeneExpressionAnalysisLabel.Text = 'Single-cell Gene Expression Analysis';
            app.v122019Label = uilabel(app.WelcomeUIFigure);
            app.v122019Label.Text = 'v1.2 (2019)';
            app.JamesCaiLabel = uilabel(app.WelcomeUIFigure);
            app.JamesCaiLabel.Text = {'Please wait while the program is loaded.'; 'This may take several minutes....'};
            app.UIAxes = uiaxes(app.WelcomeUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.WelcomeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uifig_splash

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WelcomeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WelcomeUIFigure)
        end
    end
end