classdef uifig_embeddingview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        geneDropDownLabel  matlab.ui.control.Label
        geneDropDown       matlab.ui.control.DropDown
        UIAxes             matlab.ui.control.UIAxes
        CfLabel            matlab.ui.control.Label
        GoButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        X % Description
        genelistx
        score
    end
    
  
    methods (Access = public)
        
        function update_scatter(app)
            if ~isempty(app.X) && ~isempty(app.genelistx) && ~isempty(app.score)
                idx=app.geneDropDown.Value==app.genelistx;
                % g=grp2idx(log(1+app.X(idx,:)));
                g=grp2idx(app.X(idx,:));
                % c=hsv(max(g));
                % c=parula(max(g));
                c=jet(max(g));
                % c=colorgradient(max(g));
                app.UIAxes.Colormap=c;
                hold(app.UIAxes,'on');
                for k=1:length(app.score(:,1))
                    plot(app.UIAxes,app.score(k,1),app.score(k,2),'.','color',...
                        c(g(k),:),'markersize',10);
                end
                colorbar(app.UIAxes);
                hold(app.UIAxes,'off');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Position=[10 10 app.UIFigure.Position(3)-30 app.UIFigure.Position(4)-70];
            box(app.UIAxes,'on');
        end

        % Value changed function: geneDropDown
        function geneDropDownValueChanged(app, event)
            value = app.geneDropDown.Value;
            % update_scatter(app);
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            update_scatter(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.geneDropDownLabel = uilabel(app.UIFigure);
            app.geneDropDownLabel.Text = 'gene';
            app.geneDropDown = uidropdown(app.UIFigure);
            app.geneDropDown.ValueChangedFcn = createCallbackFcn(app, @geneDropDownValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'tSNE (Perplexity = 30)')
            xlabel(app.UIAxes, 'tSNE1')
            ylabel(app.UIAxes, 'tSNE2')
            app.CfLabel = uilabel(app.UIFigure);
            app.CfLabel.Text = 'Color data points (cells) based on expression values of ';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uifig_embeddingview

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end